classdef IC_KPIs_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ShownewMetadataKPIsButton       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ConnectLoadWriteTab             matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        LegendLabel                     matlab.ui.control.Label
        MainfeaturesLabel               matlab.ui.control.Label
        WIPfeaturesLabel                matlab.ui.control.Label
        CloseconnectedDBsButton         matlab.ui.control.Button
        WritetoDBPanel                  matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        WritemanuallymodifiedLoLaKPIsmetadataexcelfileButton  matlab.ui.control.Button
        WritemetedataKPIstonewIDsButton  matlab.ui.control.Button
        WriteKPIstoIDButton             matlab.ui.control.Button
        IDrowtoaddKPIstoEditField       matlab.ui.control.NumericEditField
        IDrowtoaddKPIstoEditFieldLabel  matlab.ui.control.Label
        ImportKPIsKPIsMetadatafromexcelPanel  matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ShownewKPIstableButton          matlab.ui.control.Button
        ImportMetadataKPIsfromexceltableButton  matlab.ui.control.Button
        ImportKPIsfromexceltableButton  matlab.ui.control.Button
        ImportKPIsfromLoLaoutputPanel   matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        OpenexcelButton                 matlab.ui.control.Button
        MustbemodifiedmanuallyrowbyrowLabel  matlab.ui.control.Label
        WriteGUIMetadataToLoLaOutputButton  matlab.ui.control.Button
        InfereTemplateCheckBox          matlab.ui.control.CheckBox
        InferefromFilenameCheckBox      matlab.ui.control.CheckBox
        ImportKPIsfromLoLaOutputButton  matlab.ui.control.Button
        WriteMetadatatoDBPanel          matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        IDtomodifyIDtoloadEditField     matlab.ui.control.NumericEditField
        IDtomodifyIDtoloadEditFieldLabel  matlab.ui.control.Label
        NewIDavailableEditField         matlab.ui.control.NumericEditField
        NewIDavailableEditFieldLabel    matlab.ui.control.Label
        OverwriteMetadatatoIDloadedButton  matlab.ui.control.Button
        WriteMetadatatonewIDButton      matlab.ui.control.Button
        ImportMetadatasavetemplatetoexcelPanel  matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        IDtoloadEditField               matlab.ui.control.NumericEditField
        IDtoloadEditFieldLabel          matlab.ui.control.Label
        LoadMetadatainGUIfromIDRowofMetadataDBButton  matlab.ui.control.Button
        ExporttemplatetoexcelButton     matlab.ui.control.Button
        ImportMetadatafromexceltableButton  matlab.ui.control.Button
        LoadMetadatainGUIfromtemplateButton  matlab.ui.control.Button
        ShowMetatadaKPIsDBButton        matlab.ui.control.Button
        ShowKPIsButton                  matlab.ui.control.Button
        ShowMetadataButton              matlab.ui.control.Button
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        DBPathTextArea                  matlab.ui.control.TextArea
        DBPathTextAreaLabel             matlab.ui.control.Label
        ConnecttoDBButton               matlab.ui.control.Button
        GeneralMetadataTab              matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        TireFrontThreadDepEditField     matlab.ui.control.EditField
        TireFrontThreadDepEditFieldLabel  matlab.ui.control.Label
        TireFrontPressureEditField      matlab.ui.control.EditField
        TireFrontPressureEditFieldLabel  matlab.ui.control.Label
        ResultsCategoryEditField        matlab.ui.control.EditField
        ResultsCategoryEditFieldLabel   matlab.ui.control.Label
        IDNameEditField                 matlab.ui.control.EditField
        IDNameEditFieldLabel            matlab.ui.control.Label
        RepetitionIDEditField           matlab.ui.control.NumericEditField
        RepetitionIDEditFieldLabel      matlab.ui.control.Label
        ManeuvreNameEditField           matlab.ui.control.EditField
        ManeuvreNameEditFieldLabel      matlab.ui.control.Label
        TireRearTypeEditField           matlab.ui.control.EditField
        TireRearTypeEditFieldLabel      matlab.ui.control.Label
        TireFrontTypeEditField          matlab.ui.control.EditField
        TireFrontTypeEditFieldLabel     matlab.ui.control.Label
        TireRearButton                  matlab.ui.control.Button
        TireFrontButton                 matlab.ui.control.Button
        ConditionButton                 matlab.ui.control.Button
        ConditionEditField              matlab.ui.control.EditField
        ConditiondrywetEditFieldLabel   matlab.ui.control.Label
        RoadSurfaceButton               matlab.ui.control.Button
        RoadSurfaceEditField            matlab.ui.control.EditField
        RoadSurfaceEditFieldLabel       matlab.ui.control.Label
        ManeuvreButton                  matlab.ui.control.Button
        ModelButton                     matlab.ui.control.Button
        BrandButton                     matlab.ui.control.Button
        TireFrontNameEditField          matlab.ui.control.EditField
        TireFrontNameEditFieldLabel     matlab.ui.control.Label
        SubClusterButton                matlab.ui.control.Button
        SubClusterEditField             matlab.ui.control.EditField
        SubClusterEditFieldLabel        matlab.ui.control.Label
        ClusterEditField                matlab.ui.control.EditField
        ClusterEditFieldLabel           matlab.ui.control.Label
        ClusterButton                   matlab.ui.control.Button
        TireRearNameEditField           matlab.ui.control.EditField
        TireRearNameEditFieldLabel      matlab.ui.control.Label
        TCSDropDown                     matlab.ui.control.DropDown
        TCSDropDownLabel                matlab.ui.control.Label
        ESCDropDown                     matlab.ui.control.DropDown
        ESCDropDownLabel                matlab.ui.control.Label
        ABSDropDown                     matlab.ui.control.DropDown
        ABSDropDownLabel                matlab.ui.control.Label
        DrivingModeEditField            matlab.ui.control.EditField
        DrivingModeEditFieldLabel       matlab.ui.control.Label
        VehiclesettingsPanel            matlab.ui.container.Panel
        RoadmucoeffEditField            matlab.ui.control.NumericEditField
        RoadmucoeffEditFieldLabel       matlab.ui.control.Label
        TrackEditField_2                matlab.ui.control.EditField
        TrackEditField_2Label           matlab.ui.control.Label
        EndSpeedEditField               matlab.ui.control.NumericEditField
        EndSpeedEditFieldLabel          matlab.ui.control.Label
        StartSpeedEditField             matlab.ui.control.NumericEditField
        StartSpeedEditFieldLabel        matlab.ui.control.Label
        ReferenceNameEditField          matlab.ui.control.EditField
        ReferenceNameEditFieldLabel     matlab.ui.control.Label
        VINEditField                    matlab.ui.control.EditField
        VINEditFieldLabel               matlab.ui.control.Label
        VehiclespecsEditField           matlab.ui.control.EditField
        VehiclespecsEditFieldLabel      matlab.ui.control.Label
        ModelEditField                  matlab.ui.control.EditField
        ModelEditFieldLabel             matlab.ui.control.Label
        BrandEditField                  matlab.ui.control.EditField
        BrandEditFieldLabel             matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        IDEditField                     matlab.ui.control.NumericEditField
        IDEditFieldLabel                matlab.ui.control.Label
        VehiclePanel                    matlab.ui.container.Panel
        MassDOVWPEditField              matlab.ui.control.EditField
        MassDOVWPEditFieldLabel         matlab.ui.control.Label
        TiresPanel                      matlab.ui.container.Panel
        TireRearThreadDepEditField      matlab.ui.control.EditField
        TireRearThreadDepEditFieldLabel  matlab.ui.control.Label
        TireRearPressureEditField       matlab.ui.control.EditField
        TireRearPressureLabel           matlab.ui.control.Label
        ManeuvreEnvironmentPanel        matlab.ui.container.Panel
        DriverEditField                 matlab.ui.control.EditField
        DriverEditFieldLabel            matlab.ui.control.Label
        IDReferencesPanel_2             matlab.ui.container.Panel
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        RefFolderlinkEditField          matlab.ui.control.EditField
        RefFolderlinkEditFieldLabel     matlab.ui.control.Label
        ICMetadataTab                   matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        BrakeSystemPanel                matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        BrakeRearBLPhaseButton          matlab.ui.control.Button
        BrakeRearBLPhaseEditField       matlab.ui.control.EditField
        BrakeRearBLPhaseEditFieldLabel  matlab.ui.control.Label
        BrakeFrontBLPhaseButton         matlab.ui.control.Button
        BrakeFrontBLPhaseEditField      matlab.ui.control.EditField
        BrakeFrontBLPhaseEditFieldLabel  matlab.ui.control.Label
        BrakeRearCALIDEditField         matlab.ui.control.NumericEditField
        CALIDEditField_2Label           matlab.ui.control.Label
        BrakeFrontCALIDEditField        matlab.ui.control.NumericEditField
        CALIDEditFieldLabel             matlab.ui.control.Label
        RearcalibrationdateDatePicker   matlab.ui.control.DatePicker
        RearcalibrationdateDatePickerLabel  matlab.ui.control.Label
        BLRearEditField                 matlab.ui.control.EditField
        BLRearEditFieldLabel            matlab.ui.control.Label
        FrontcalibrationdateDatePicker  matlab.ui.control.DatePicker
        FrontcalibrationdateDatePickerLabel  matlab.ui.control.Label
        BLFrontEditField                matlab.ui.control.EditField
        BLFrontEditFieldLabel           matlab.ui.control.Label
        BrakeRearButton                 matlab.ui.control.Button
        BrakeRearTypeEditField          matlab.ui.control.EditField
        BrakeRearTypeEditFieldLabel     matlab.ui.control.Label
        BrakeRearSystemEditField        matlab.ui.control.EditField
        BrakeRearSystemEditFieldLabel   matlab.ui.control.Label
        BrakeFrontButton                matlab.ui.control.Button
        BrakeFrontTypeEditField         matlab.ui.control.EditField
        BrakeFrontTypeEditFieldLabel    matlab.ui.control.Label
        BrakeFrontSystemEditField       matlab.ui.control.EditField
        BrakeFrontSystemEditFieldLabel  matlab.ui.control.Label
        SmartTireBLCalDateDatePicker    matlab.ui.control.DatePicker
        SmartTireBLCalDateDatePickerLabel  matlab.ui.control.Label
        CoOperationPanel                matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        CoOperationBLEditField          matlab.ui.control.EditField
        CoOperationBLEditFieldLabel     matlab.ui.control.Label
        CoOperationBLCalDateDatePicker  matlab.ui.control.DatePicker
        CoOperationBLCalDateDatePickerLabel  matlab.ui.control.Label
        CoOpCALIDEditField              matlab.ui.control.NumericEditField
        CALIDEditField_6Label           matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        BrakeNode                       matlab.ui.container.TreeNode
        SuspNode                        matlab.ui.container.TreeNode
        TireNode                        matlab.ui.container.TreeNode
        SteerNode                       matlab.ui.container.TreeNode
        CoOperationtypeLabel            matlab.ui.control.Label
        SmartTirePanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        SmartTireBLEditField            matlab.ui.control.EditField
        SmartTireBLEditFieldLabel       matlab.ui.control.Label
        SmartTireTypeEditField          matlab.ui.control.EditField
        SmartTireTypeEditFieldLabel     matlab.ui.control.Label
        SmartTireSWTireIDEditField      matlab.ui.control.EditField
        SmartTireSWTireIDEditFieldLabel  matlab.ui.control.Label
        SmartTireSWMassEditField        matlab.ui.control.EditField
        SmartTireSWMassEditFieldLabel   matlab.ui.control.Label
        SmartTireSWWearEditField        matlab.ui.control.EditField
        SmartTireSWWearEditFieldLabel   matlab.ui.control.Label
        SmartTireSWRoadEditField        matlab.ui.control.EditField
        SmartTireSWRoadEditFieldLabel   matlab.ui.control.Label
        SmartTireSWVerEditField         matlab.ui.control.EditField
        SmartTireSWVerEditFieldLabel    matlab.ui.control.Label
        SmTCALIDEditField               matlab.ui.control.NumericEditField
        CALIDEditField_4Label           matlab.ui.control.Label
        SmartTireSWRoadButton           matlab.ui.control.Button
        SmartTireSWTireIDButton         matlab.ui.control.Button
        SmartTireSWWearButton           matlab.ui.control.Button
        SmartTireSWMassButton           matlab.ui.control.Button
        SteeringPanel                   matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        SteeringBLCalDateDatePicker     matlab.ui.control.DatePicker
        SteeringBLCalDateDatePickerLabel  matlab.ui.control.Label
        SteeringModeEditField           matlab.ui.control.EditField
        SteeringModeEditFieldLabel      matlab.ui.control.Label
        SteeringBLEditField             matlab.ui.control.EditField
        SteeringBLEditFieldLabel        matlab.ui.control.Label
        SteeringButton                  matlab.ui.control.Button
        SteeringTypeEditField           matlab.ui.control.EditField
        SteeringTypeEditFieldLabel      matlab.ui.control.Label
        SteerCALIDEditField             matlab.ui.control.NumericEditField
        CALIDEditField_5Label           matlab.ui.control.Label
        SuspensionsPanel                matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        SuspCALIDEditField              matlab.ui.control.NumericEditField
        CALIDEditFieldLabel_2           matlab.ui.control.Label
        SuspButton                      matlab.ui.control.Button
        SuspTypeEditField               matlab.ui.control.EditField
        SuspTypeEditFieldLabel          matlab.ui.control.Label
        SuspBLCalibDateDatePicker       matlab.ui.control.DatePicker
        SuspBLCalibDateDatePickerLabel  matlab.ui.control.Label
        SuspBLEditField                 matlab.ui.control.EditField
        SuspBLEditFieldLabel            matlab.ui.control.Label
        SuspModeEditField               matlab.ui.control.EditField
        SuspModeEditFieldLabel          matlab.ui.control.Label
        SuspSystemEditField             matlab.ui.control.EditField
        SuspSystemEditFieldLabel        matlab.ui.control.Label
        KPIsTab                         matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ResetKPIstableButton            matlab.ui.control.Button
        AddKPIValueButton               matlab.ui.control.Button
        ShowaddedimportedKPIstableButton  matlab.ui.control.Button
        GenerateexceltabletobefilledwithKPIsSelectedBelowButton  matlab.ui.control.Button
        GenerateexceltabletobefilledwithKPIsAllButton  matlab.ui.control.Button
        RefreshlistfromconnectedDBButton  matlab.ui.control.Button
        InsertKPIvalueEditField         matlab.ui.control.NumericEditField
        InsertKPIvalueEditFieldLabel    matlab.ui.control.Label
        SelectKPItobeAddedDropDown      matlab.ui.control.DropDown
        SelectKPItobeAddedDropDownLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end


    properties (Access = private)

        % Mapping between General Metadata UI components and DB columns
        GeneralMetadataMap

        % Mapping between IC Metadata UI components and DB columns
        ICMetadataMap


        % --- DB connection state ---
        DB_Connected logical = false;   % 0/1 flag indicating if DB is connected
        DB_FilePath  char    = '';      % Full path to the selected Excel


        % --- UI table to show metadata sheet ---
        MetadataUITable matlab.ui.control.Table


        % --- Temporary table of KPIs to be added ---
        % Each column = one KPI to add
        % row = values for each KPI
        KPIsToAddTable table = table();

        MetadataToAddTable table = table();

        LoLa_excel_path
        LoLa_excel_path_DB

    end

    methods (Access = private)

        function initializeDbMappings(app)
            %---------------------------------------------------------------
            % Build mapping for "General Metadata" tab
            % Each row: {UIHandle, 'DB_COLUMN_NAME'}
            % Fill the second column with your actual DB column names
            %---------------------------------------------------------------
            app.GeneralMetadataMap = {
                app.IDEditField,                   'ID';  % e.g. 'ID'
                app.IDNameEditField,               'IDName';  % e.g. 'ID_NAME'
                app.DateDatePicker,                'Date';  % e.g. 'TEST_DATE'
                app.ClusterEditField,               'Cluster';  % e.g. 'CLUSTER'
                app.SubClusterEditField,            'Sub_Cluster';  % e.g. 'SUB_CLUSTER'
                app.RefFolderlinkEditField,        'RefFolder';  % e.g. 'REF_FOLDER_LINK'
                app.ResultsCategoryEditField,       'Results_Category';
                app.BrandEditField,                'Brand';  % e.g. 'VEHICLE_BRAND'
                app.ModelEditField,                'Model';  % e.g. 'VEHICLE_MODEL'
                app.VehiclespecsEditField,         'Specs';  % e.g. 'VEHICLE_SPECS'
                app.VINEditField,                  'VIN';  % e.g. 'VIN_CODE'
                app.ReferenceNameEditField,        'RefName';  % e.g. 'REFERENCE_NAME'
                app.ManeuvreNameEditField,          'ManeuvreName';  % e.g. 'MANEUVER_NAME'
                app.StartSpeedEditField,           'StartSpeed';  % e.g. 'START_SPEED_KMH'
                app.EndSpeedEditField,             'EndSpeed';  % e.g. 'END_SPEED_KMH'
                app.RepetitionIDEditField,          'Repetition_ID';
                app.TrackEditField_2,              'Track';  % e.g. 'TRACK_NAME'
                app.RoadSurfaceEditField,           'Surface';  % e.g. 'ROAD_SURFACE'
                app.ConditionEditField,    'Condition';  % e.g. 'ROAD_CONDITION'
                app.RoadmucoeffEditField,          'Road_mu_coeff';  % e.g. 'ROAD_MU_COEFF'
                app.DrivingModeEditField,          'Driving_Mode';  % e.g. 'SYSTEM_DRIVING_MODE'
                app.ABSDropDown,                   'ABS';  % e.g. 'ABS_STATE'
                app.ESCDropDown,                   'ESC';  % e.g. 'ESP_STATE'
                app.TCSDropDown,                    'TCS';  % e.g. 'TC_STATE'
                app.TireFrontNameEditField,        'T_Front_Name';  % e.g. 'TIRE_FRONT_NAME'
                app.TireRearNameEditField,         'T_Rear_Name';  % e.g. 'TIRE_REAR_NAME'
                app.TireFrontTypeEditField,         'T_Front_type';  % e.g. 'TIRE_FRONT_TYPE'
                app.TireRearTypeEditField,          'T_Rear_type';  % e.g. 'TIRE_REAR_TYPE'
                app.FileNameEditField,              'FileName';
                app.MassDOVWPEditField,             'MassInfo';
                app.DriverEditField,                'Driver';
                };

            %---------------------------------------------------------------
            % Build mapping for "IC Metadata" tab
            % Each row: {UIHandle, 'DB_COLUMN_NAME'}
            % Fill the second column with your actual DB column names
            %---------------------------------------------------------------
            app.ICMetadataMap = {
                app.BrakeFrontSystemEditField,         'B_F_System';  % e.g. 'BRAKE_FRONT_SYSTEM'
                app.BLFrontEditField,                  'B_F_BL';  % e.g. 'BRAKE_FRONT_BL'
                app.FrontcalibrationdateDatePicker,'B_F_Cal_Date';  % e.g. 'BRAKE_FRONT_EXTRA_CAL_DATE'
                app.BrakeFrontCALIDEditField, 'B_F_Cal_ID';
                app.BrakeFrontTypeEditField,            'B_F_Type';  % e.g. 'BRAKE_FRONT_TYPE'
                app.BrakeRearSystemEditField,          'B_R_System';  % e.g. 'BRAKE_REAR_SYSTEM'
                app.BLRearEditField,                   'B_R_BL';  % e.g. 'BRAKE_REAR_BL'
                app.BrakeRearTypeEditField,             'B_R_Type';  % e.g. 'BRAKE_REAR_TYPE'
                app.RearcalibrationdateDatePicker,'B_R_Cal_Date';  % e.g. 'BRAKE_REAR_EXTRA_CAL_DATE'
                app.BrakeRearCALIDEditField, 'B_R_Cal_ID';
                app.SuspSystemEditField,               'Su_System';  % e.g. 'SUSP_SYSTEM'
                app.SuspTypeEditField,                  'Su_Type';  % e.g. 'SUSP_TYPE'
                app.SuspModeEditField,                 'Su_Mode';  % e.g. 'SUSP_MODE'
                app.SuspBLCalibDateDatePicker,   'Su_BL_Cal_Date';  % e.g. 'SUSP_BL_CAL_DATE'
                app.SuspCALIDEditField, 'Su_Cal_ID';
                app.SuspBLEditField,                   'Su_BL';
                app.SmartTireTypeEditField,             'SmT_Type';  % e.g. 'SMART_TIRE_TYPE'
                app.SmartTireBLCalDateDatePicker,      'SmT_BL_Cal_Date';  % e.g. 'SMART_TIRE_BL_CAL_DATE'
                app.SmartTireBLEditField,              'SmT_BL';
                app.SmTCALIDEditField, 'SmT_Cal_ID';
                app.SteeringTypeEditField,              'St_Type';  % e.g. 'STEERING_TYPE'
                app.SteeringBLCalDateDatePicker,       'St_Cal_Date';  % e.g. 'STEERING_BL_CAL_DATE'
                app.SteeringBLEditField,               'St_BL';
                app.SteerCALIDEditField, 'St_Cal_ID';
                app.SteeringModeEditField,             'St_Mode';
                app.Tree,                              'CoOpType';  % e.g. 'COOP_COMPONENTS_SELECTED'
                app.CoOperationBLCalDateDatePicker,    'CoOpBL_Cal_Date';  % e.g. 'COOP_BL_CAL_DATE'
                app.CoOperationBLEditField,            'CoOpBL';
                app.CoOpCALIDEditField, 'CoOp_Cal_ID';
                app.BrakeFrontBLPhaseEditField, 'B_F_BL_Phase';
                app.BrakeRearBLPhaseEditField, 'B_R_BL_Phase';
                app.TireFrontPressureEditField, 'T_Front_Pressure';
                app.TireRearPressureEditField, 'T_Rear_Pressure';
                app.TireFrontThreadDepEditField, 'T_Front_Thread_Depth';
                app.TireRearThreadDepEditField, 'T_Rear_Thread_Depth';
                app.SmartTireSWVerEditField, 'SmT_SW_Version';
                app.SmartTireSWTireIDEditField, 'SmT_SW_TireID'; 
                app.SmartTireSWRoadEditField, 'SmT_SW_Road';
                app.SmartTireSWWearEditField, 'SmT_SW_Wear';
                app.SmartTireSWMassEditField, 'SmT_SW_Mass';
                };
        end


        function rowStruct = buildRowFromMapping(app, mappingCell)
            %-----------------------------------------------------------
            % Build a struct: DB_COLUMN_NAME -> UI current value
            %
            % INPUT:
            %   mappingCell: cell array N x 2
            %       { UIHandle, 'DB_COLUMN_NAME' }
            %
            % OUTPUT:
            %   rowStruct: struct with fields named as DB column names
            %-----------------------------------------------------------

            rowStruct = struct();

            numRows = size(mappingCell, 1);

            for iRow = 1:numRows
                uiHandle   = mappingCell{iRow, 1};
                columnName = mappingCell{iRow, 2};

                % Skip mapping rows without a column name
                if isempty(columnName)
                    continue;
                end

                % Read value depending on component type
                if isa(uiHandle, 'matlab.ui.control.EditField') || ...
                        isa(uiHandle, 'matlab.ui.control.NumericEditField') || ...
                        isa(uiHandle, 'matlab.ui.control.DropDown') || ...
                        isa(uiHandle, 'matlab.ui.control.DatePicker')

                    value = uiHandle.Value;

                elseif isa(uiHandle, 'matlab.ui.container.CheckBoxTree')
                    % For the Tree we store the list of checked node texts
                    checkedNodes = uiHandle.CheckedNodes;
                    nodeTexts = strings(numel(checkedNodes), 1);
                    for idx = 1:numel(checkedNodes)
                        nodeTexts(idx) = string(checkedNodes(idx).Text);
                    end
                    % You can store as array of strings or as a single
                    % concatenated string
                    value = strjoin(nodeTexts, ';');

                else
                    % For any other component type we skip or handle separately
                    continue;
                end

                % Assign value to struct field using dynamic fieldname
                rowStruct.(columnName) = value;
            end
        end


        function applyMetadataFilter(app, metadataFigure, filterColumnDropDown, filterTextField)
            %--------------------------------------------------------------
            % Apply a simple "contains" text-based filter on the selected
            % column of the original Metadata table.
            %
            % Called by the "Apply Filter" button.
            %--------------------------------------------------------------

            % Retrieve stored data from figure
            figureData = metadataFigure.UserData;
            originalTable = figureData.OriginalTable;
            metadataUITable = figureData.UITable;

            % Get filtering parameters from UI controls
            selectedColumnName = filterColumnDropDown.Value;
            filterText = strtrim(filterTextField.Value);

            % If no filter text, restore original table
            if isempty(filterText)
                metadataUITable.Data = originalTable;
                return;
            end

            % Extract column data from the table
            columnData = originalTable.(selectedColumnName);

            % Convert column data to string array for generic "contains"
            columnAsString = string(columnData);

            % Build logical mask based on substring containment
            rowMask = contains(columnAsString, filterText, 'IgnoreCase', true);

            % Apply mask to original table
            filteredTable = originalTable(rowMask, :);

            % Update UITable data
            metadataUITable.Data = filteredTable;
        end


        function clearMetadataFilter(app, metadataFigure)
            %--------------------------------------------------------------
            % Clear any active filter and restore the original Metadata
            % table in the UITable.
            %
            % Called by the "Clear Filter" button.
            %--------------------------------------------------------------

            % Retrieve stored data from figure
            figureData = metadataFigure.UserData;
            originalTable    = figureData.OriginalTable;
            metadataUITable  = figureData.UITable;

            % Restore original data
            metadataUITable.Data = originalTable;
        end

        function applyKPIsFilter(app, kpiFigure, filterColumnDropDown, filterTextField)
            %--------------------------------------------------------------
            % Apply a "contains" text filter over one selected column
            %--------------------------------------------------------------

            figureData = kpiFigure.UserData;
            originalTable = figureData.OriginalTable;
            kpiUITable    = figureData.UITable;

            selectedColumn = filterColumnDropDown.Value;
            filterText     = strtrim(filterTextField.Value);

            if isempty(filterText)
                kpiUITable.Data = originalTable;
                return;
            end

            % Convert column to string for contains() operation
            colData = string(originalTable.(selectedColumn));

            mask = contains(colData, filterText, 'IgnoreCase', true);

            kpiUITable.Data = originalTable(mask, :);
        end

        function clearKPIsFilter(app, kpiFigure)
            %--------------------------------------------------------------
            % Restore full KPIs table
            %--------------------------------------------------------------
            figureData = kpiFigure.UserData;
            originalTable = figureData.OriginalTable;
            kpiUITable    = figureData.UITable;

            kpiUITable.Data = originalTable;
        end

        function applyMetadataKPIsFilter(app, combinedFigure, tableKey, filterColumnDropDown, filterTextField)
            %--------------------------------------------------------------
            % Generic "contains" filter for Metadata or KPIs table.
            %
            % INPUTS:
            %   combinedFigure        : uifigure that stores both tables in UserData
            %   tableKey              : 'Metadata' or 'KPIs'
            %   filterColumnDropDown  : dropdown UI with column names
            %   filterTextField       : edit field with text pattern
            %--------------------------------------------------------------

            % Retrieve stored data from combined figure
            combinedData = combinedFigure.UserData;

            % Extract sub-struct (Metadata or KPIs)
            if ~isfield(combinedData, tableKey)
                % Defensive check: in principle should not happen
                return;
            end
            tableDataStruct  = combinedData.(tableKey);
            originalTable    = tableDataStruct.OriginalTable;
            targetUITable    = tableDataStruct.UITable;

            % Get selected column and filter text
            selectedColumnName = filterColumnDropDown.Value;
            filterText         = strtrim(filterTextField.Value);

            % If filter text is empty -> restore original data
            if isempty(filterText)
                targetUITable.Data = originalTable;
                return;
            end

            % Get the column data
            columnData = originalTable.(selectedColumnName);

            % Convert to string array for robust contains() filtering
            columnAsString = string(columnData);

            % Build logical mask: TRUE when cell contains the filter text
            rowMask = contains(columnAsString, filterText, 'IgnoreCase', true);

            % Apply mask to the original table
            filteredTable = originalTable(rowMask, :);

            % Update UITable
            targetUITable.Data = filteredTable;
        end

        function clearMetadataKPIsFilter(app, combinedFigure, tableKey)
            %--------------------------------------------------------------
            % Restore full table data (Metadata or KPIs) in the combined viewer.
            %
            % INPUTS:
            %   combinedFigure : uifigure that stores both tables in UserData
            %   tableKey       : 'Metadata' or 'KPIs'
            %--------------------------------------------------------------

            combinedData = combinedFigure.UserData;

            if ~isfield(combinedData, tableKey)
                return;
            end

            tableDataStruct = combinedData.(tableKey);
            originalTable   = tableDataStruct.OriginalTable;
            targetUITable   = tableDataStruct.UITable;

            % Restore original data
            targetUITable.Data = originalTable;
        end


        function applyRowToGUI(app, rowTable, mappingCell)
            %------------------------------------------------------------------
            % Apply values from a single-row table (rowTable) to GUI components
            % according to the mapping:
            %
            %   mappingCell: N x 2 cell array
            %     { UIHandle, 'DB_COLUMN_NAME' }
            %
            % This is conceptually the inverse of buildRowFromMapping:
            %   - buildRowFromMapping: GUI -> struct (DB row)
            %   - applyRowToGUI      : DB row (table) -> GUI
            %------------------------------------------------------------------

            % Safety check: we expect one-row table
            if height(rowTable) ~= 1
                error('applyRowToGUI:InvalidRowTable', ...
                    'Row table must contain exactly one row.');
            end

            numMappings = size(mappingCell, 1);
            dbColumnNames = rowTable.Properties.VariableNames;

            for iMap = 1:numMappings
                if iMap == 4
                    disp('stop here to debug')
                end
                uiHandle   = mappingCell{iMap, 1};
                columnName = mappingCell{iMap, 2};

                % Skip empty mapping entries
                if isempty(columnName)
                    continue;
                end

                % Skip if the column does not exist in the DB table
                if ~ismember(columnName, dbColumnNames)
                    continue;
                end

                % Extract scalar value from rowTable for this column
                % rowTable{row, colName} returns the raw MATLAB value
                cellValue = rowTable{1, columnName};

                % Handle by UI component type
                if isa(uiHandle, 'matlab.ui.control.NumericEditField')
                    % Numeric field expects double
                    if isnumeric(cellValue)
                        uiHandle.Value = cellValue;
                    else
                        % Try to convert from string/categorical/etc. to double
                        numericValue = str2double(string(cellValue));
                        if ~isnan(numericValue)
                            uiHandle.Value = numericValue;
                        else
                            % If conversion fails, leave as default
                            % (optional: you can raise a warning)
                        end
                    end

                elseif isa(uiHandle, 'matlab.ui.control.EditField')
                    % Text edit field
                    uiHandle.Value = char(string(cellValue));

                elseif isa(uiHandle, 'matlab.ui.control.DropDown')
                    % DropDown expects a string that must be in Items
                    valueStr = char(string(cellValue));
                    if any(strcmp(uiHandle.Items, valueStr))
                        uiHandle.Value = valueStr;
                    else
                        % Optionally, add the value to Items if not present
                        uiHandle.Items(end+1) = {valueStr};
                        uiHandle.Value = valueStr;
                    end

                elseif isa(uiHandle, 'matlab.ui.control.DatePicker')
                    % DatePicker expects a datetime value
                    if isa(cellValue, 'datetime')
                        uiHandle.Value = cellValue;
                    else
                        % Try to convert from string/number to datetime
                        try
                            % Very generic conversion; you can tune format if needed
                            uiHandle.Value = datetime(cellValue);
                        catch
                            % If conversion fails, keep previous value
                        end
                    end

                elseif isa(uiHandle, 'matlab.ui.container.CheckBoxTree')
                    % For the CheckBoxTree (CoOperation), we assume DB stores
                    % a single string with ';' separated node names, e.g.:
                    %   'Brake;Susp;Tire'
                    try
                        selectionStr = string(cellValue);
                        if strlength(selectionStr) == 0
                            uiHandle.CheckedNodes = matlab.ui.container.TreeNode.empty;
                            continue;
                        end

                        tokens = strtrim(strsplit(selectionStr, ';'));

                        % Collect all nodes of the tree and check those in tokens
                        allNodes = uiHandle.Children;  % assuming a flat tree
                        checkedNodes = matlab.ui.container.TreeNode.empty;

                        for iNode = 1:numel(allNodes)
                            node = allNodes(iNode);
                            if any(strcmp(tokens, string(node.Text)))
                                checkedNodes(end+1) = node; %#ok<AGROW>
                            end
                        end

                        uiHandle.CheckedNodes = checkedNodes;
                    catch
                        % If anything goes wrong, silently ignore
                    end

                else
                    % Other component types not handled here
                    continue;
                end
            end
        end

        function refreshKPIsDropDown(app)
            %------------------------------------------------------------------
            % Refresh the KPI selection dropdown based on the content of the
            % "KPIs" sheet in the connected Excel DB.
            %
            % The dropdown to be updated is:
            %   app.SelectKPItobeAddedDropDown
            %
            % Strategy:
            %   1) Check DB connection (DB_Connected + DB_FilePath).
            %   2) Read "KPIs" sheet as a table.
            %   3) Take variable names as potential KPI names.
            %   4) Optionally exclude some non-KPI columns (ID, metadata).
            %   5) Update dropdown Items and preserve selection if possible.
            %------------------------------------------------------------------

            % 1) Check DB connection
            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    'DB is not connected. Please connect to DB first.', ...
                    'DB not connected');
                return;
            end

            % 2) Read KPIs sheet
            try
                % Use 'modify' to get valid MATLAB identifiers as variable names
                kpiTable = readtable(app.DB_FilePath, ...
                    'Sheet', 'KPIs', ...
                    'ReadVariableNames', true, ...
                    'VariableNamingRule', 'modify');
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "KPIs":\n%s', readError.message), ...
                    'Read error');
                return;
            end

            if isempty(kpiTable)
                uialert(app.UIFigure, ...
                    'The "KPIs" sheet is empty. No KPIs to load in dropdown.', ...
                    'Empty KPIs sheet');
                return;
            end

            % 3) Extract variable names as candidate KPI names
            allKpiCandidateNames = kpiTable.Properties.VariableNames;

            % 4) Optionally exclude non-KPI columns
            %    Adjust this list depending on how your KPIs sheet is structured.
            %    Typical exclusions: ID, metadata fields, etc.
            excludedColumns = { ...
                'ID', ...
                'IDName', ...
                'Date', ...
                'Cluster', ...
                'Sub_Cluster', ...
                'RefFolder', ...
                'Brand', ...
                'Model', ...
                'Specs' ...
                % Add here any other column that is NOT a KPI
                };

            isExcluded = ismember(allKpiCandidateNames, excludedColumns);
            kpiNamesForDropDown = allKpiCandidateNames(~isExcluded);

            if isempty(kpiNamesForDropDown)
                uialert(app.UIFigure, ...
                    ['No KPI columns found after exclusion.' newline ...
                    'Check the KPIs sheet structure and the excludedColumns list.'], ...
                    'No KPIs detected');
                return;
            end

            % 5) Update dropdown items
            previousValue = app.SelectKPItobeAddedDropDown.Value;

            app.SelectKPItobeAddedDropDown.Items = kpiNamesForDropDown;

            % Preserve previous selection if still valid, otherwise select first
            if ismember(previousValue, kpiNamesForDropDown)
                app.SelectKPItobeAddedDropDown.Value = previousValue;
            else
                app.SelectKPItobeAddedDropDown.Value = kpiNamesForDropDown{1};
            end
        end

        function appendStructAsExcelTableRow(app, rowStruct, sheetName, tableName)
            %------------------------------------------------------------------
            % Append a single struct as a new row to an Excel ListObject
            % (table) on a given sheet.
            %
            % INPUTS:
            %   rowStruct : scalar struct
            %               - fields are VALID MATLAB IDENTIFIERS
            %               - they correspond to Excel table headers after
            %                 applying matlab.lang.makeValidName()
            %   sheetName : Excel sheet name where the ListObject resides
            %   tableName : Name of the ListObject (Excel table)
            %
            % BEHAVIOR:
            %   - Uses COM/ActiveX: Excel must be installed (Windows only).
            %   - Reads column headers from the ListObject.
            %   - For each header, builds the corresponding field name via
            %       validFieldName = matlab.lang.makeValidName(headerText)
            %     and pulls the value from rowStruct.(validFieldName).
            %   - Adds a ListRow and writes the entire row in one shot.
            %------------------------------------------------------------------

            %-------------------------
            % 1) Start Excel via COM
            %-------------------------
            excelApp = actxserver('Excel.Application');  % Requires Windows + Excel
            excelApp.Visible = false;                    % Set true for debugging

            try
                % Open workbook
                workbook  = excelApp.Workbooks.Open(app.DB_FilePath);

                % Get worksheet
                try
                    worksheet = workbook.Worksheets.Item(sheetName);
                catch
                    error('appendStructAsExcelTableRow:SheetNotFound', ...
                        'Sheet "%s" not found in workbook.', sheetName);
                end

                % Get ListObject (Excel table)
                listObjects = worksheet.ListObjects;
                if listObjects.Count < 1
                    error('appendStructAsExcelTableRow:NoTables', ...
                        'No Excel tables (ListObjects) found on sheet "%s".', sheetName);
                end

                % If you know the table name, select it explicitly
                listObject = [];
                for idx = 1:listObjects.Count
                    candidate = listObjects.Item(idx);
                    if strcmp(candidate.Name, tableName)
                        listObject = candidate;
                        break;
                    end
                end

                if isempty(listObject)
                    error('appendStructAsExcelTableRow:TableNotFound', ...
                        'Table "%s" not found on sheet "%s".', tableName, sheetName);
                end

                %--------------------------------------------------------------
                % 2) Build row values according to Excel table column order
                %--------------------------------------------------------------
                numColumns = listObject.ListColumns.Count;
                rowCell = cell(1, numColumns);   % cell row for COM assignment

                for colIdx = 1:numColumns
                    % Header text as seen in Excel
                    headerText = listObject.ListColumns.Item(colIdx).Name;

                    % Convert header to valid MATLAB field name
                    % This mimics 'VariableNamingRule','modify'
                    validFieldName = matlab.lang.makeValidName(headerText);

                    if isfield(rowStruct, validFieldName)
                        rawValue = rowStruct.(validFieldName);
                    else
                        rawValue = [];  % or '' if you prefer
                    end

                    %------------------------------------------------------------
                    % Normalize missing values BEFORE COM conversion
                    %------------------------------------------------------------

                    % Case 1: missing datetime -> very old sentinel date
                    if isa(rawValue, 'datetime') && ismissing(rawValue)
                        rawValue = datetime(1800,1,1);   % sentinel date (safe for Excel)

                        % Case 2: missing string -> empty char
                    elseif isstring(rawValue) && ismissing(rawValue)
                        rawValue = '';

                        % Case 3: missing cell (string/datetime inside)
                    elseif iscell(rawValue) && isscalar(rawValue)
                        if ismissing(rawValue{1})
                            rawValue = '';
                        end
                    end

                    %------------------------------------------------------------
                    % Convert rawValue to something Excel COM understands
                    %------------------------------------------------------------
                    if isa(rawValue, 'datetime')
                        % Convert datetime to Excel serial date number
                        rawValue = datenum(rawValue); %#ok<DATNM>

                    elseif isstring(rawValue)
                        rawValue = char(rawValue);   % COM prefers char

                    elseif islogical(rawValue)
                        rawValue = double(rawValue); % 0/1 in Excel
                    end

                    rowCell{colIdx} = rawValue;
                end

                %--------------------------------------------------------------
                % 3) Add new ListRow and assign values
                %--------------------------------------------------------------
                newListRow = listObject.ListRows.Add;      % append row at bottom
                excelRange = newListRow.Range;             % Range for the new row

                % Assign entire row in one shot (1 x numColumns cell)
                excelRange.Value = rowCell;

                %--------------------------------------------------------------
                % 4) Save and close workbook
                %--------------------------------------------------------------
                workbook.Save();
                workbook.Close(false);   % close without prompt
            catch ME
                % Make sure to close Excel instance on error
                try
                    workbook.Close(false);
                catch
                end
                excelApp.Quit();
                delete(excelApp);
                rethrow(ME);
            end

            %-------------------------
            % 5) Quit Excel cleanly
            %-------------------------
            excelApp.Quit();
            delete(excelApp);
        end

        function writeKPIsToExcelTableForID(app, idToWrite)
            %------------------------------------------------------------------
            % Write KPIs from app.KPIsToAddTable into the Excel ListObject
            % (table) "TableKPI" on sheet "KPIs" for a given ID.
            %
            % Steps:
            %   1) Open Excel via COM (actxserver).
            %   2) Get worksheet "KPIs" and ListObject "TableKPI".
            %   3) Find ID column index and row index for idToWrite
            %      (create a new row if missing and set ID).
            %   4) For each KPI column in app.KPIsToAddTable:
            %        - If column exists in the Excel table:
            %             write KPI value in (ID row, KPI column).
            %        - If column does not exist:
            %             ask user (Create column / Skip KPI / Abort).
            %   5) Save workbook only if user did not abort.
            %
            % NOTES:
            %   - Requires Excel on Windows (actxserver).
            %   - Assumes that:
            %       app.KPIsToAddTable.Properties.VariableNames
            %     are valid MATLAB identifiers that match Excel headers
            %     processed with matlab.lang.makeValidName.
            %------------------------------------------------------------------

            %-----------------------------
            % 0) Shortcuts to GUI data
            %-----------------------------
            kpisToAddTable = app.KPIsToAddTable;
            guiKpiNames    = kpisToAddTable.Properties.VariableNames;

            excelSheetName = 'KPIs';      % Sheet where KPI table lives
            excelTableName = 'TableKPI';  % Name of the Excel ListObject (ADAPT if different)

            userAborted = false;

            % Start Excel via COM
            excelApp = actxserver('Excel.Application');  % Requires Excel on Windows
            excelApp.Visible = false;                    % Set true for debugging

            workbook = [];
            try
                %-----------------------------
                % 1) Open workbook
                %-----------------------------
                workbook = excelApp.Workbooks.Open(app.DB_FilePath);

                %-----------------------------
                % 2) Get worksheet
                %-----------------------------
                try
                    worksheet = workbook.Worksheets.Item(excelSheetName);
                catch
                    error('writeKPIsToExcelTableForID:SheetNotFound', ...
                        'Sheet "%s" not found in workbook.', excelSheetName);
                end

                %-----------------------------
                % 3) Get ListObject (TableKPI)
                %-----------------------------
                listObjects = worksheet.ListObjects;
                if listObjects.Count < 1
                    error('writeKPIsToExcelTableForID:NoTables', ...
                        'No Excel tables found on sheet "%s".', excelSheetName);
                end

                listObject = [];
                for idx = 1:listObjects.Count
                    candidate = listObjects.Item(idx);
                    if strcmp(candidate.Name, excelTableName)
                        listObject = candidate;
                        break;
                    end
                end

                if isempty(listObject)
                    error('writeKPIsToExcelTableForID:TableNotFound', ...
                        'Table "%s" not found on sheet "%s".', ...
                        excelTableName, excelSheetName);
                end

                %---------------------------------------------------------
                % 4) Find ID column index in Excel table (ListColumns)
                %---------------------------------------------------------
                numColumns    = listObject.ListColumns.Count;
                idColumnIndex = [];

                for colIdx = 1:numColumns
                    headerText  = listObject.ListColumns.Item(colIdx).Name;  % Excel header text
                    validHeader = matlab.lang.makeValidName(headerText);     % MATLAB-safe
                    if strcmp(validHeader, 'ID')
                        idColumnIndex = colIdx;
                        break;
                    end
                end

                if isempty(idColumnIndex)
                    error('writeKPIsToExcelTableForID:IDColumnMissing', ...
                        'ID column not found in Excel table "%s".', excelTableName);
                end

                %---------------------------------------------------------
                % 5) Find or create rowIndexForID using ID column range
                %---------------------------------------------------------
                dataBodyRange = listObject.DataBodyRange;  % May be empty
                rowIndexForID = [];                        % 1-based within DataBodyRange

                if ~isempty(dataBodyRange)
                    % Data body range for the ID column only (single-column range)
                    idDataRange = listObject.ListColumns.Item(idColumnIndex).DataBodyRange;
                    idValuesRaw = idDataRange.Value;  % Could be cell, numeric, etc.

                    % Convert to numeric column vector robustly
                    idNumeric = str2double(string(idValuesRaw(:)));

                    % Find first row where ID equals idToWrite
                    matchMask = (idNumeric == idToWrite);
                    matchIdx  = find(matchMask, 1, 'first');

                    if ~isempty(matchIdx)
                        rowIndexForID = matchIdx;
                    end
                end

                % If the ID row does not exist, add a new ListRow and set the ID
                if isempty(rowIndexForID)
                    % Add new row to the ListObject (extends the table)
                    listObject.ListRows.Add;

                    % Refresh ranges after adding the row
                    dataBodyRange = listObject.DataBodyRange;
                    idDataRange   = listObject.ListColumns.Item(idColumnIndex).DataBodyRange;

                    % New row index is the last data row
                    rowIndexForID = idDataRange.Rows.Count;

                    % Set ID cell in the new row (single-column range -> column index is 1)
                    idCell = idDataRange.Item(rowIndexForID, 1);
                    idCell.Value = idToWrite;
                end

                %---------------------------------------------------------
                % 6) For each KPI in KPIsToAddTable, write value or ask user
                %---------------------------------------------------------
                for k = 1:numel(guiKpiNames)
                    guiKpiName = guiKpiNames{k};

                    % Never treat 'ID' as KPI
                    if strcmp(guiKpiName, 'ID')
                        continue;
                    end

                    % Retrieve KPI value (single row from table)
                    kpiValue = kpisToAddTable{1, guiKpiName};

                    %-----------------------------------------------------
                    % 6a) Find KPI column index in Excel ListObject
                    %      by comparing makeValidName(header) == guiKpiName
                    %-----------------------------------------------------
                    kpiColumnIndex = [];  % empty = not found

                    for colIdx = 1:numColumns
                        headerText  = listObject.ListColumns.Item(colIdx).Name;
                        validHeader = matlab.lang.makeValidName(headerText);
                        if strcmp(validHeader, guiKpiName)
                            kpiColumnIndex = colIdx;
                            break;
                        end
                    end

                    %-----------------------------------------------------
                    % 6b) If KPI column is not found -> ask user
                    %-----------------------------------------------------
                    if isempty(kpiColumnIndex)
                        questionMessage = sprintf( ...
                            'KPI "%s" does not exist in Excel table "%s".\nWhat do you want to do?', ...
                            guiKpiName, excelTableName);

                        userChoice = uiconfirm(app.UIFigure, ...
                            questionMessage, ...
                            'Missing KPI column', ...
                            'Options', {'Create column','Skip KPI','Abort'}, ...
                            'DefaultOption', 1, ...
                            'CancelOption', 3);

                        if strcmp(userChoice, 'Create column')
                            % Add a new column at the end of the ListObject
                            newListColumn      = listObject.ListColumns.Add;
                            newListColumn.Name = guiKpiName;   % header text

                            % Update column count and KPI column index
                            numColumns     = listObject.ListColumns.Count;
                            kpiColumnIndex = newListColumn.Index;

                        elseif strcmp(userChoice, 'Skip KPI')
                            % Skip this KPI
                            continue;

                        elseif strcmp(userChoice, 'Abort')
                            userAborted = true;
                            break;
                        end
                    end

                    %-----------------------------------------------------
                    % 6c) Safety: do NOT write into ID column by mistake
                    %-----------------------------------------------------
                    if ~isempty(kpiColumnIndex) && kpiColumnIndex == idColumnIndex
                        % Something went wrong in mapping -> force dedicated KPI column
                        newListColumn      = listObject.ListColumns.Add;
                        newListColumn.Name = guiKpiName;
                        numColumns         = listObject.ListColumns.Count;
                        kpiColumnIndex     = newListColumn.Index;
                    end

                    if userAborted
                        break;
                    end

                    %-----------------------------------------------------
                    % 6d) Normalize KPI value for Excel COM
                    %-----------------------------------------------------
                    excelValue = kpiValue;

                    if isa(excelValue, 'datetime')
                        excelValue = datenum(excelValue);            % Excel serial date
                    elseif isstring(excelValue)
                        excelValue = char(excelValue);               % COM prefers char
                    elseif iscell(excelValue) && numel(excelValue) == 1
                        excelValue = excelValue{1};                  % unpack 1x1 cell
                        if isa(excelValue, 'datetime')
                            excelValue = datenum(excelValue);
                        elseif isstring(excelValue)
                            excelValue = char(excelValue);
                        end
                    elseif islogical(excelValue)
                        excelValue = double(excelValue);             % 0/1 in Excel
                    end

                    %-----------------------------------------------------
                    % 6e) Write KPI value using the column DataBodyRange
                    %     (single-column range -> index by row, col = 1)
                    %-----------------------------------------------------
                    kpiDataRange = listObject.ListColumns.Item(kpiColumnIndex).DataBodyRange;

                    % Refresh after possible structural changes
                    kpiDataRange = listObject.ListColumns.Item(kpiColumnIndex).DataBodyRange;

                    targetCell = kpiDataRange.Item(rowIndexForID, 1);
                    targetCell.Value = excelValue;

                    % DEBUG (optional):
                    % fprintf('Writing KPI "%s" for ID %g at row %d, colIndex %d\n', ...
                    %         guiKpiName, idToWrite, rowIndexForID, kpiColumnIndex);
                end

                %---------------------------------------------------------
                % 7) Save or discard changes depending on userAborted
                %---------------------------------------------------------
                if userAborted
                    workbook.Close(false);   % close without saving
                    error('WriteKPIs:UserAborted', ...
                        'User aborted KPI writing. Changes discarded.');
                else
                    workbook.Save();
                    workbook.Close(false);
                end

            catch ME
                % Ensure workbook is closed on error, if it was opened
                try
                    if ~isempty(workbook)
                        workbook.Close(false);
                    end
                catch
                end

                % Quit Excel and clean up COM object
                excelApp.Quit();
                delete(excelApp);

                % Re-throw error to caller
                rethrow(ME);
            end

            % Quit Excel and clean up COM object on normal exit
            excelApp.Quit();
            delete(excelApp);
        end


        function confirmOverwriteMetadataButtonPushed(app, src, event, compareFig)
            %----------------------------------------------------------------------
            % Callback for "Confirm overwrite" button in the comparison figure.
            % Reads ID and NEW row from compareFig.UserData and overwrites the
            % corresponding row in the Excel Metadata table.
            %----------------------------------------------------------------------

            if ~isvalid(compareFig)
                return;
            end

            compareData = compareFig.UserData;

            idToOverwrite = compareData.IdToOverwrite;
            newRowTable   = compareData.NewRowTable;  % 1-row table with correct variables

            try
                app.overwriteMetadataRowInExcel(idToOverwrite, newRowTable);
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Error while overwriting Metadata row:\n%s', ME.message), ...
                    'Overwrite error');
                % Close comparison figure anyway
                try
                    close(compareFig);
                catch
                end
                return;
            end

            % Optional: notify user of success
            uialert(app.UIFigure, ...
                sprintf('Metadata for ID = %g has been overwritten.', idToOverwrite), ...
                'Overwrite complete', 'Icon','success');

            % Close comparison figure
            try
                close(compareFig);
            catch
            end
        end


        function cancelOverwriteMetadataButtonPushed(app, src, event, compareFig)
            %----------------------------------------------------------------------
            % Callback for "Cancel" button in the comparison figure.
            % Simply closes the comparison window without writing anything.
            %----------------------------------------------------------------------

            if isvalid(compareFig)
                close(compareFig);
            end
        end


        function overwriteMetadataRowInExcel(app, idToOverwrite, newRowTable)
            %----------------------------------------------------------------------
            % Overwrite a single row in the Excel "Metadata" ListObject (table)
            % "TableMet" on sheet "Metadata" for the given ID.
            %
            % INPUTS:
            %   idToOverwrite : numeric ID identifying the row to overwrite
            %   newRowTable   : 1-row table with updated values
            %                    - Variable names are valid MATLAB identifiers
            %                    - They correspond to Excel header names after
            %                      applying matlab.lang.makeValidName().
            %
            % BEHAVIOR:
            %   - Finds the row where ID == idToOverwrite in the Excel table.
            %   - For each Excel column whose valid header name exists in
            %     newRowTable.Properties.VariableNames, writes the value
            %     from newRowTable into that cell.
            %
            % NOTES:
            %   - Requires Excel on Windows (actxserver).
            %   - Table name and sheet name are hard-coded as:
            %       sheet "Metadata", table "TableMet".
            %----------------------------------------------------------------------

            excelSheetName = 'Metadata';
            excelTableName = 'TableMet';  % adapt if your table name differs

            % Ensure newRowTable has exactly one row
            if height(newRowTable) ~= 1
                error('overwriteMetadataRowInExcel:InvalidRow', ...
                    'newRowTable must contain exactly one row.');
            end

            newVarNames = newRowTable.Properties.VariableNames;

            excelApp = actxserver('Excel.Application');
            excelApp.Visible = false;

            workbook = [];
            try
                % Open workbook
                workbook = excelApp.Workbooks.Open(app.DB_FilePath);

                % Get worksheet
                try
                    worksheet = workbook.Worksheets.Item(excelSheetName);
                catch
                    error('overwriteMetadataRowInExcel:SheetNotFound', ...
                        'Sheet "%s" not found in workbook.', excelSheetName);
                end

                % Get ListObject (Excel table)
                listObjects = worksheet.ListObjects;
                if listObjects.Count < 1
                    error('overwriteMetadataRowInExcel:NoTables', ...
                        'No Excel tables found on sheet "%s".', excelSheetName);
                end

                listObject = [];
                for idx = 1:listObjects.Count
                    candidate = listObjects.Item(idx);
                    if strcmp(candidate.Name, excelTableName)
                        listObject = candidate;
                        break;
                    end
                end

                if isempty(listObject)
                    error('overwriteMetadataRowInExcel:TableNotFound', ...
                        'Table "%s" not found on sheet "%s".', ...
                        excelTableName, excelSheetName);
                end

                % Find ID column index
                numColumns    = listObject.ListColumns.Count;
                idColumnIndex = [];

                for colIdx = 1:numColumns
                    headerText  = listObject.ListColumns.Item(colIdx).Name;
                    validHeader = matlab.lang.makeValidName(headerText);
                    if strcmp(validHeader, 'ID')
                        idColumnIndex = colIdx;
                        break;
                    end
                end

                if isempty(idColumnIndex)
                    error('overwriteMetadataRowInExcel:IDColumnMissing', ...
                        'ID column not found in Excel table "%s".', excelTableName);
                end

                % Find rowIndexForID using ID column DataBodyRange
                dataBodyRange = listObject.DataBodyRange;
                if isempty(dataBodyRange)
                    error('overwriteMetadataRowInExcel:EmptyTable', ...
                        'Metadata table is empty. Cannot overwrite any row.');
                end

                idDataRange = listObject.ListColumns.Item(idColumnIndex).DataBodyRange;
                idValuesRaw = idDataRange.Value;   % cell/numeric/variant
                idNumeric   = str2double(string(idValuesRaw(:)));

                matchMask   = (idNumeric == idToOverwrite);
                matchIdx    = find(matchMask, 1, 'first');

                if isempty(matchIdx)
                    error('overwriteMetadataRowInExcel:IDNotFound', ...
                        'Row with ID = %g not found in table "%s".', ...
                        idToOverwrite, excelTableName);
                end

                rowIndexForID = matchIdx;

                %--------------------------------------------------
                % Overwrite row cells column by column
                %--------------------------------------------------
                for colIdx = 1:numColumns
                    % Excel header and corresponding valid MATLAB name
                    headerText  = listObject.ListColumns.Item(colIdx).Name;
                    validHeader = matlab.lang.makeValidName(headerText);

                    % Skip if the header is not present in newRowTable
                    if ~ismember(validHeader, newVarNames)
                        continue;
                    end

                    % Extract value for this column from newRowTable
                    cellValue = newRowTable{1, validHeader};

                    % Normalize to something Excel understands
                    excelValue = cellValue;
                    if isa(excelValue, 'datetime')
                        excelValue = datenum(excelValue);
                    elseif isstring(excelValue)
                        excelValue = char(excelValue);
                    elseif iscell(excelValue) && numel(excelValue) == 1
                        excelValue = excelValue{1};
                        if isa(excelValue, 'datetime')
                            excelValue = datenum(excelValue);
                        elseif isstring(excelValue)
                            excelValue = char(excelValue);
                        end
                    elseif islogical(excelValue)
                        excelValue = double(excelValue);
                    end

                    % DataBodyRange for this column only (single-column range)
                    columnDataRange = listObject.ListColumns.Item(colIdx).DataBodyRange;
                    columnDataRange = listObject.ListColumns.Item(colIdx).DataBodyRange;

                    targetCell = columnDataRange.Item(rowIndexForID, 1);
                    targetCell.Value = excelValue;
                end

                % Save and close workbook
                workbook.Save();
                workbook.Close(false);

            catch ME
                % Ensure workbook is closed on error
                try
                    if ~isempty(workbook)
                        workbook.Close(false);
                    end
                catch
                end

                excelApp.Quit();
                delete(excelApp);

                rethrow(ME);
            end

            excelApp.Quit();
            delete(excelApp);
        end

        function openSupportSelectionDialog(app, tableName, valueColumnName, targetTextComponent, dialogTitle)

            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, 'DB not connected.', 'Error');
                return;
            end

            excelFile = app.DB_FilePath;

            % Read table from its dedicated sheet (A1 header, variable height)
            try
                dataTable = readtable(excelFile, ...
                    "Sheet", tableName, ...
                    "ReadVariableNames", true, ...
                    "VariableNamingRule", "modify");
            catch err
                uialert(app.UIFigure, sprintf("Error reading sheet '%s':\n%s", tableName, err.message), "Read Error");
                return;
            end

            if isempty(dataTable)
                uialert(app.UIFigure, sprintf("Sheet '%s' contains no data.", tableName), "Empty Table");
                return;
            end

            if nargin < 5 || isempty(dialogTitle)
                dialogTitle = sprintf("Select value from %s", tableName);
            end

            fig = uifigure("Name", dialogTitle, "Position", [300 300 750 430]);

            uit = uitable(fig, ...
                "Data", dataTable, ...
                "Position", [20 80 710 320], ...
                "ColumnName", dataTable.Properties.VariableNames, ...
                "ColumnWidth", "auto", ...
                "RowName", {});

            % Store context
            fig.UserData = struct( ...
                "DataTable", dataTable, ...
                "SelectedRow", [], ...
                "ValueColumnName", valueColumnName, ...
                "TargetTextComponent", targetTextComponent, ...
                "TableName", tableName ...
                );

            % Selection callback
            uit.CellSelectionCallback = @(src,evt) app.onGenericTableSelection(src, evt, fig);

            % Buttons
            uibutton(fig, "push", "Text", "OK", ...
                "Position", [200 20 100 35], ...
                "ButtonPushedFcn", @(src,evt) app.confirmGenericSelectionButtonPushed(src,evt,fig));

            uibutton(fig, "push", "Text", "Cancel", ...
                "Position", [320 20 100 35], ...
                "ButtonPushedFcn", @(src,evt) app.cancelGenericSelectionButtonPushed(src,evt,fig));

            % NEW: Add row
            uibutton(fig, "push", "Text", "Add Row", ...
                "Position", [440 20 100 35], ...
                "ButtonPushedFcn", @(src,evt) app.addRowToExcelTable(src,evt,fig));

        end

        function onGenericTableSelection(app, src, evt, selectionFig)
            %----------------------------------------------------------------------
            % Generic CellSelectionCallback for the selection UITable.
            % Stores the selected row index in selectionFig.UserData.SelectedRow.
            %
            % evt.Indices : N×2 array, [row, col] for each selected cell.
            % We use only the row from the first selected cell.
            %----------------------------------------------------------------------

            if isempty(evt.Indices)
                figData = selectionFig.UserData;
                figData.SelectedRow = [];
                selectionFig.UserData = figData;
                return;
            end

            selectedRowIndex = evt.Indices(1, 1);

            figData = selectionFig.UserData;
            figData.SelectedRow = selectedRowIndex;
            selectionFig.UserData = figData;
        end

        function confirmGenericSelectionButtonPushed(app, src, evt, selectionFig)
            %----------------------------------------------------------------------
            % Generic "OK" button callback:
            %   - Reads SelectedRow, ValueColumnName, DataTable, TargetTextComponent
            %     from selectionFig.UserData.
            %   - Writes the selected value into TargetTextComponent.Value.
            %----------------------------------------------------------------------

            if ~isvalid(selectionFig)
                return;
            end

            figData = selectionFig.UserData;

            dataTable          = figData.DataTable;
            selectedRow        = figData.SelectedRow;
            valueColumnName    = figData.ValueColumnName;
            targetTextComponent = figData.TargetTextComponent;

            % Check selection
            if isempty(selectedRow) || selectedRow < 1 || selectedRow > height(dataTable)
                uialert(selectionFig, ...
                    'No row selected. Please select a row before pressing OK.', ...
                    'No selection');
                return;
            end

            % Read value from requested column in selected row
            valueRaw = dataTable.(valueColumnName)(selectedRow);

            % Convert to string for TextArea/EditField
            valueStr = char(string(valueRaw));

            % Write into the target text component (must have .Value property)
            targetTextComponent.Value = valueStr;

            % Close selection figure
            try
                close(selectionFig);
            catch
            end
        end

        function addRowToExcelTable(app, src, evt, selectionFig)
            %---------------------------------------------------------------
            % Open dialog to let the user type a new row for the table.
            % First column is numeric ID and auto-filled (not editable).
            %---------------------------------------------------------------

            figData   = selectionFig.UserData;
            tableName = figData.TableName;
            guiTable  = figData.DataTable;

            columnNames = guiTable.Properties.VariableNames;
            numCols     = numel(columnNames);

            % Compute next ID from first column
            existingIDs = guiTable.(columnNames{1});
            nextID = max(existingIDs) + 1;

            % Window size scaled to number of rows
            dialogHeight = 120 + 45 * numCols;
            dlg = uifigure( ...
                "Name", sprintf("Add row to %s", tableName), ...
                "Position", [400 400 420 dialogHeight]);

            inputFields = gobjects(numCols,1);

            for i = 1:numCols
                y = dialogHeight - 60 - (i-1)*45;

                % label
                uilabel(dlg, ...
                    "Text", columnNames{i}, ...
                    "Position", [20 y 150 30], ...
                    "HorizontalAlignment","left");

                % text fields
                if i == 1
                    % ID field: auto-filled, non-editable
                    inputFields(i) = uieditfield(dlg, "numeric", ...
                        "Value", nextID, ...
                        "Editable", "off", ...
                        "Position",[180 y+5 200 25]);
                else
                    inputFields(i) = uieditfield(dlg, "text", ...
                        "Position", [180 y+5 200 25]);
                end
            end

            % confirm button
            uibutton(dlg, "Text","Confirm", ...
                "Position",[80 20 100 35], ...
                "ButtonPushedFcn", @(btn,evt) app.confirmAddRow(dlg, selectionFig, inputFields));

            % cancel button
            uibutton(dlg, "Text","Cancel", ...
                "Position",[220 20 100 35], ...
                "ButtonPushedFcn", @(btn,evt) close(dlg));
        end


        function confirmAddRow(app, addRowDlg, selectionFig, inputFields)
            %------------------------------------------------------------------
            % Adds one row to Excel ListObject:
            %   - First column = auto-generated ID (pre-filled, non editable)
            %   - Remaining columns = from UI fields
            %   - No writetable: only COM ListRows.Add
            %------------------------------------------------------------------

            figData   = selectionFig.UserData;
            tableName = figData.TableName;
            guiTable  = figData.DataTable;

            columnNames = guiTable.Properties.VariableNames;
            numColsGui  = numel(columnNames);

            % 1. Collect values
            rowValues = cell(1, numColsGui);
            for c = 1:numColsGui
                rowValues{c} = inputFields(c).Value;
            end

            % 2. Ask confirmation
            userChoice = uiconfirm(addRowDlg, ...
                sprintf("Add new row to table '%s'?", tableName), ...
                "Confirm insertion", ...
                "Options",["Yes","No"], ...
                "DefaultOption","Yes");

            if userChoice == "No"
                close(addRowDlg);
                return;
            end

            %------------------------------------------------------------
            % 3. Append to Excel ListObject via COM
            %------------------------------------------------------------
            excelApp = [];
            excelWorkbook = [];

            try
                excelApp = actxserver('Excel.Application');
                excelApp.Visible = false;

                excelWorkbook = excelApp.Workbooks.Open(app.DB_FilePath);
                excelWorksheet = excelWorkbook.Worksheets.Item(tableName);

                % Get the ListObject:
                if excelWorksheet.ListObjects.Count == 1
                    listObj = excelWorksheet.ListObjects.Item(1);
                else
                    listObj = excelWorksheet.ListObjects.Item(tableName);
                end

                numExcelCols = listObj.ListColumns.Count;
                numToWrite = min(numColsGui, numExcelCols);

                % Add new ListRow
                newRowObj   = listObj.ListRows.Add;
                newRowRange = newRowObj.Range;     % full width of the new row

                % Build rowData cell array of correct width
                rowData = cell(1, numExcelCols);
                for c = 1:numToWrite
                    rowData{c} = rowValues{c};
                end
                for c = numToWrite+1:numExcelCols
                    rowData{c} = [];
                end

                % Write whole row at once
                newRowRange.Value = rowData;

                excelWorkbook.Save();
                excelWorkbook.Close(false);
                excelApp.Quit();
                delete(excelApp);

            catch err
                if ~isempty(excelWorkbook)
                    try excelWorkbook.Close(false); end
                end
                if ~isempty(excelApp)
                    try excelApp.Quit(); end
                    delete(excelApp);
                end

                uialert(addRowDlg, ...
                    sprintf("Error writing new row:\n%s", err.message), ...
                    "Excel COM error");
                return;
            end

            %------------------------------------------------------------
            % 4. Reload updated table into uitable
            %------------------------------------------------------------
            close(addRowDlg);

            try
                updatedTable = readtable(app.DB_FilePath, ...
                    "Sheet", tableName, ...
                    "ReadVariableNames", true, ...
                    "VariableNamingRule", "modify");
            catch err
                uialert(selectionFig, ...
                    sprintf("Row added, but cannot reload table:\n%s", err.message), ...
                    "Reload error");
                return;
            end

            figData.DataTable = updatedTable;
            selectionFig.UserData = figData;

            uiTableHandle = findobj(selectionFig, "Type","uitable");
            uiTableHandle.Data = updatedTable;
        end

        function columnLetters = excelColumnName(app, columnIndex)
            %--------------------------------------------------------------
            % Converts a 1-based column index to Excel column letters.
            % Examples:
            %   1  -> 'A'
            %   2  -> 'B'
            %   26 -> 'Z'
            %   27 -> 'AA'
            %   28 -> 'AB'
            %--------------------------------------------------------------
            if columnIndex < 1
                error('columnIndex must be a positive integer.');
            end

            letters = "";
            while columnIndex > 0
                remainder    = mod(columnIndex - 1, 26);
                currentChar  = char(65 + remainder);   % 65 = 'A'
                letters      = string(currentChar) + letters;
                columnIndex  = floor((columnIndex - 1) / 26);
            end

            columnLetters = char(letters);
        end

        function onTemplateSelection(app, src, evt, selectionFig)

            if isempty(evt.Indices)
                data = selectionFig.UserData;
                data.SelectedRow = [];
                selectionFig.UserData = data;
                return;
            end

            selected = evt.Indices(1,1);

            data = selectionFig.UserData;
            data.SelectedRow = selected;
            selectionFig.UserData = data;

        end


        function writeStructToGUI(app, rowStruct, mapping)
            % writeStructToGUI  - Safely writes struct values into GUI components
            %
            % mapping = { guiHandle , 'ColumnName' ; ... }
            %
            % This function inspects the GUI component class to decide how to convert
            % and assign the value (text, numeric, date, dropdown, textarea, etc.)

            numMap = size(mapping,1);

            for i = 1:numMap
                guiHandle  = mapping{i,1};
                columnName = mapping{i,2};

                % Skip if value is not available in struct
                if ~isfield(rowStruct, columnName)
                    continue;
                end

                rawVal = rowStruct.(columnName);

                % Skip if GUI component has no Value property
                if ~isprop(guiHandle, "Value")
                    continue;
                end

                %--------------------------------------------------------------
                % DETECT GUI COMPONENT TYPE
                %--------------------------------------------------------------
                guiClass = class(guiHandle);
                disp(columnName)

                %--------------------------------------------------------------
                % TYPE DISPATCH
                %--------------------------------------------------------------

                % -------- UIDATEPICKER --------------------------------------
                if contains(guiClass, "datepicker", "IgnoreCase",true)
                    try
                        if isa(rawVal, "datetime")
                            if isnat(rawVal)
                                guiHandle.Value = NaT;          % cella vuota → campo vuoto
                            else
                                guiHandle.Value = rawVal;
                            end
                        elseif ischar(rawVal) || isstring(rawVal)
                            trimmed = strtrim(string(rawVal));
                            if trimmed == "" || trimmed == "NaT"
                                guiHandle.Value = NaT;          % stringa vuota → campo vuoto
                            else
                                guiHandle.Value = datetime(trimmed);
                            end
                        elseif isnumeric(rawVal)
                            if isnan(rawVal)
                                guiHandle.Value = NaT;          % NaN → campo vuoto
                            else
                                guiHandle.Value = datetime(rawVal, ...
                                    "ConvertFrom", "excel");    % numero seriale Excel → data
                            end
                        else
                            guiHandle.Value = NaT;              % qualsiasi altro caso → vuoto
                        end
                    catch
                        guiHandle.Value = NaT;                  % ← era datetime("today"), causa del bug
                    end
                    % -------- NUMERIC EDIT FIELD --------------------------------
                elseif contains(guiClass, "numericeditfield", "IgnoreCase", true)
                    isEmpty = (isnumeric(rawVal) && (isnan(rawVal) || isempty(rawVal))) || ...
                        ((ischar(rawVal) || isstring(rawVal)) && strtrim(string(rawVal)) == "");

                    if isEmpty
                        % Controlla se il campo supporta AllowEmpty
                        if isprop(guiHandle, "AllowEmpty") && guiHandle.AllowEmpty == "on"
                            guiHandle.Value = [];   % campo vuoto
                        else
                            guiHandle.Value = 0;    % fallback
                        end
                    else
                        try
                            guiHandle.Value = double(rawVal);
                        catch
                            guiHandle.Value = 0;
                        end
                    end

                    % -------- TEXT EDIT FIELD -----------------------------------
                elseif contains(guiClass, "editfield","IgnoreCase",true) && ~strcmpi(guiClass,"numeric")
                    % Plain text field
                    guiHandle.Value = string(rawVal);

                    % -------- DROPDOWN ------------------------------------------
                elseif contains(guiClass, "dropdown", "IgnoreCase",true)
                    % Dropdown expects string, but must be a valid item
                    valStr = string(rawVal);

                    if any(strcmp(guiHandle.Items, valStr))
                        guiHandle.Value = valStr;
                    else
                        % If value not present, leave unchanged or choose default
                        % Here we leave unchanged.
                    end

                    % -------- TEXTAREA ------------------------------------------
                elseif contains(guiClass, "textarea", "IgnoreCase", true)
                    guiHandle.Value = string(rawVal);

                    % -------- ANY COMPONENT WITH Value PROPERTY -----------------
                else
                    % Last-resort: assign as string
                    guiHandle.Value = string(rawVal);
                end

            end
        end


        function loadTemplateIntoGUI(app, src, evt, selectionFig)
            data = selectionFig.UserData;

            if isempty(data.SelectedRow)
                uialert(selectionFig, "Select one row first.", "No selection");
                return;
            end

            T = data.TemplateTable;
            rowIdx = data.SelectedRow;

            % Extract selected row as struct
            rowStruct = table2struct(T(rowIdx,:));

            % ---- APPLY GENERAL MAPPING ----
            app.writeStructToGUI(rowStruct, app.GeneralMetadataMap);

            % ---- APPLY IC MAPPING ----
            app.writeStructToGUI(rowStruct, app.ICMetadataMap);

            % Close dialog
            close(selectionFig);
        end

        function exportSelectedTemplateRow(app, src, evt, selectionFig)
            %--------------------------------------------------------------
            % Exports selected row of Metadata_Templates into an Excel file.
            % The Excel file contains:
            %   - first row: column names (as in DB)
            %   - second row: the selected template values
            %--------------------------------------------------------------

            data = selectionFig.UserData;

            if isempty(data.SelectedRow)
                uialert(selectionFig,"Select a row before exporting.","No selection");
                return;
            end

            templateTable = data.TemplateTable;
            rowIdx = data.SelectedRow;

            % Extract selected row
            oneRow = templateTable(rowIdx, :);

            % Ask user where to save
            [fileName, folder] = uiputfile( ...
                {'*.xlsx','Excel file (*.xlsx)'}, ...
                'Save template row as...');

            if isequal(fileName,0)
                return;     % user cancelled
            end

            fullPath = fullfile(folder, fileName);

            % Write one-row table to Excel
            try
                writetable(oneRow, fullPath, ...
                    "WriteMode","overwrite", ...
                    "WriteVariableNames",true);
            catch ME
                uialert(selectionFig, ...
                    sprintf("Failed to export row:\n%s", ME.message), ...
                    "Write error");
                return;
            end

            % Close dialog
            try
                close(selectionFig);
            end

            uialert(app.UIFigure, ...
                sprintf("Template row exported:\n%s", fullPath), ...
                "Export complete", 'Icon','success');
        end

        function updateID(app)

            try
                % Read Metadata sheet, preserving exact header names
                metadataTable = readtable(app.DB_FilePath, ...
                    'Sheet', 'Metadata', ...
                    'ReadVariableNames', true, ...
                    'VariableNamingRule', 'preserve');  % R2020a+

                % Name of ID column in the DB
                idColumnName = 'ID';

                if ~ismember(idColumnName, metadataTable.Properties.VariableNames)
                    % If the ID column does not exist, we cannot update ID available
                    warning('ConnecttoDBButtonPushed:IDColumnMissing', ...
                        'Column "%s" not found in Metadata sheet. ID available not updated.', ...
                        idColumnName);
                else
                    idColumnData = metadataTable.(idColumnName);

                    % Keep only numeric values (in case of NaN / non numeric)
                    if isnumeric(idColumnData)
                        validIdMask = ~isnan(idColumnData);
                        validIds    = idColumnData(validIdMask);

                        if isempty(validIds)
                            % No valid IDs yet -> start from 1
                            nextAvailableId = 1;
                        else
                            % Next ID is max + 1
                            nextAvailableId = max(validIds) + 1;
                        end
                    else
                        % If ID column is not numeric, try to convert to double
                        numericIds = str2double(string(idColumnData));
                        validIdMask = ~isnan(numericIds);
                        validIds    = numericIds(validIdMask);

                        if isempty(validIds)
                            nextAvailableId = 1;
                        else
                            nextAvailableId = max(validIds) + 1;
                        end
                    end

                    %--------------------------------------------------
                    % Assign the nextAvailableId to your GUI field
                    %--------------------------------------------------
                    % Adjust this line depending on where you want
                    % to show the "ID available".
                    % Example: use IDEditField as "next ID" field:
                    app.IDEditField.Value = nextAvailableId;

                    % update ID available
                    app.NewIDavailableEditField.Value = nextAvailableId;
                    % turn off ID editing
                    app.NewIDavailableEditField.Enable = 'Off';
                end

            catch ME
                % If anything goes wrong while reading Metadata or IDs,
                % do not block the DB connection, just warn.
                warning('ID:UpdateIDFailed', ...
                    'Failed to update ID available: %s', ME.message);
            end
        end

        function metadataColumnNames = getAllMetadataColumnNames(app)
            %----------------------------------------------------------------------
            % Returns a unique list of all metadata column names used in the DB,
            % collected from:
            %   - app.GeneralMetadataMap
            %   - app.ICMetadataMap
            %
            % Each map is a cell array:
            %   { guiHandle , 'ColumnName'; ... }
            %----------------------------------------------------------------------

            generalMap = app.GeneralMetadataMap;
            icMap      = app.ICMetadataMap;

            generalCols = generalMap(:,2);   % 2nd column = DB column name
            icCols      = icMap(:,2);

            metadataColumnNames = unique([generalCols; icCols], "stable");
        end

        function [allOK, issuesTable] = validateImportedMetadata(app)
            %--------------------------------------------------------------
            % Validate every row of app.MetadataToAddTable against GUI types.
            %--------------------------------------------------------------

            % Initialize as 0x5 cell to avoid dimension mismatch
            issues = cell(0, 5);

            if isempty(app.MetadataToAddTable)
                issuesTable = table( ...
                    'Size',[0 5], ...
                    'VariableTypes', {'double','string','string','string','string'}, ...
                    'VariableNames', {'RowIndex','IDName','ColumnName','RawValue','ErrorMessage'});
                allOK = true;
                return;
            end

            metadataTable = app.MetadataToAddTable;
            numRows = height(metadataTable);

            idNameField = 'IDName';  % adattalo se si chiama diversamente

            for rowIdx = 1:numRows
                rowStruct = table2struct(metadataTable(rowIdx,:));

                if isfield(rowStruct, idNameField)
                    idNameValue = string(rowStruct.(idNameField));
                else
                    idNameValue = "";
                end

                % GENERAL mapping
                issues = app.validateRowAgainstMapping( ...
                    rowStruct, app.GeneralMetadataMap, rowIdx, idNameValue, issues);

                % IC mapping
                issues = app.validateRowAgainstMapping( ...
                    rowStruct, app.ICMetadataMap, rowIdx, idNameValue, issues);
            end

            if isempty(issues)
                issuesTable = table( ...
                    'Size',[0 5], ...
                    'VariableTypes', {'double','string','string','string','string'}, ...
                    'VariableNames', {'RowIndex','IDName','ColumnName','RawValue','ErrorMessage'});
                allOK = true;
            else
                issuesTable = cell2table(issues, ...
                    'VariableNames', {'RowIndex','IDName','ColumnName','RawValue','ErrorMessage'});
                allOK = false;
            end
        end

        function issues = validateRowAgainstMapping(app, rowStruct, mapping, rowIdx, idNameValue, issues)
            %--------------------------------------------------------------
            % For each mapping { guiHandle, 'ColumnName'; ... }:
            %   - If ColumnName exists in rowStruct:
            %       * tries to assign raw value to guiHandle using
            %         assignValueToGUIComponent (type-sensitive)
            %       * on error, logs an issue
            %--------------------------------------------------------------

            numMap = size(mapping, 1);

            for i = 1:numMap
                guiHandle  = mapping{i,1};
                columnName = mapping{i,2};

                if ~isfield(rowStruct, columnName)
                    continue;
                end

                rawVal = rowStruct.(columnName);

                % Salta validazione per valori missing/empty - allowEmpty implicito
                rawStr = strtrim(string(rawVal));
                missingTokens = ["", "NA", "NaN", "N/A", "Missing", "NaT", "-", "none"];
                isNumMissing = isnumeric(rawVal) && isscalar(rawVal) && isnan(rawVal);
                isStrMissing = any(strcmpi(rawStr, missingTokens));
                if isNumMissing || isStrMissing
                    % Assegna comunque il valore missing al componente (per pulizia GUI)
                    try
                        app.assignValueToGUIComponent(guiHandle, rawVal);
                    catch
                        % Se fallisce su missing, ignora silenziosamente
                    end
                    continue;  % non loggare come errore
                end

                % Skip components without Value property
                if ~isprop(guiHandle, "Value")
                    continue;
                end

                try
                    app.assignValueToGUIComponent(guiHandle, rawVal);
                catch ME
                    issues(end+1, :) = { ...
                        rowIdx, ...
                        idNameValue, ...
                        columnName, ...
                        string(rawVal), ...
                        string(ME.message) };
                end
            end
        end

        function assignValueToGUIComponent(app, guiHandle, rawVal)

            %--------------------------------------------------------------
            % assignValueToGUIComponent
            %
            % Safely assigns rawVal to guiHandle.Value, converting according
            % to GUI component type and treating missing markers as "empty".
            %
            % Missing markers handled (case-insensitive):
            %   - string: "", "NA", "NaN", "Missing", "N/A"
            %   - numeric: NaN
            %
            % For missing values:
            %   - NumericEditField  -> NaN
            %   - DatePicker        -> NaT
            %   - DropDown          -> leave as-is
            %   - TextArea/EditField-> ""
            %
            % Any conversion error throws, so caller can log issues.
            %--------------------------------------------------------------

            guiClass = class(guiHandle);

            %==========================================================
            % 0) NORMALIZE rawVal AND DETECT MISSING
            %==========================================================

            % Normalize to string for checks (non-destructive)
            rawStr = string(rawVal);

            % Missing patterns for strings
            missingTokens = ["", "NA", "NaN", "N/A", "Missing", "NaT"];

            isStringMissing = ( ...
                isstring(rawVal) || ischar(rawVal) || iscellstr(rawVal) ) && ...
                any(strcmpi(strtrim(rawStr), missingTokens));

            % Missing numeric (NaN)
            isNumericMissing = isnumeric(rawVal) && isscalar(rawVal) && isnan(rawVal);

            isMissingValue = isStringMissing || isNumericMissing;

            if isMissingValue
                %----------------------------------------------
                % Handle missing: clear / default component
                %----------------------------------------------
                if isa(guiHandle, 'matlab.ui.control.NumericEditField') || ...
                        contains(guiClass, 'NumericEditField', 'IgnoreCase', true)
                    % numeric -> NaN
                    guiHandle.Value = NaN;
                    return;

                elseif isa(guiHandle, 'matlab.ui.control.DatePicker') || ...
                        contains(guiClass, 'DatePicker', 'IgnoreCase', true)
                    % date -> NaT (empty datetime)
                    guiHandle.Value = NaT;
                    return;

                elseif isa(guiHandle, 'matlab.ui.control.DropDown')
                    % Se il campo permette empty (primo item vuoto), seleziona quello
                    % altrimenti lascia invariato
                    if ~isempty(guiHandle.Items) && strtrim(guiHandle.Items{1}) == ""
                        guiHandle.Value = guiHandle.Items{1};
                    end
                    return;

                elseif isa(guiHandle, 'matlab.ui.control.TextArea') || ...
                        isa(guiHandle, 'matlab.ui.control.EditField')
                    % text-like -> empty string
                    guiHandle.Value = "";
                    return;

                elseif isprop(guiHandle, "Value")
                    % generic with Value property -> try empty string
                    try
                        guiHandle.Value = "";
                    catch
                        % if this fails, just leave unmodified
                    end
                    return;
                else
                    % no Value property: nothing to do
                    return;
                end
            end

            %==========================================================
            % 1) DATE PICKER
            %==========================================================
            if isa(guiHandle, 'matlab.ui.control.DatePicker') || ...
                    contains(guiClass, 'DatePicker', 'IgnoreCase', true)

                if isa(rawVal, "datetime")
                    guiHandle.Value = rawVal;
                    return;
                end

                if isnumeric(rawVal)
                    % If for some reason comes as Excel serial
                    try
                        guiHandle.Value = datetime(rawVal, 'ConvertFrom','excel');
                    catch
                        error('Invalid numeric date value for DatePicker: %s', string(rawVal));
                    end
                    return;
                end

                % Expect string in dd/MM/yyyy
                try
                    guiHandle.Value = datetime(rawStr, 'InputFormat','dd/MM/yyyy');
                catch
                    error('Invalid date "%s" for DatePicker. Expected dd/MM/yyyy.', rawStr);
                end
                return;
            end

            %==========================================================
            % 2) NUMERIC EDIT FIELD
            %==========================================================
            if isa(guiHandle,'matlab.ui.control.NumericEditField') || ...
                    contains(guiClass,'NumericEditField','IgnoreCase',true)

                if isnumeric(rawVal)
                    numVal = double(rawVal);
                else
                    numVal = str2double(rawStr);
                end

                if isnan(numVal)
                    error('Value "%s" is not numeric for NumericEditField.', rawStr);
                end

                guiHandle.Value = numVal;
                return;
            end

            %==========================================================
            % 3) DROPDOWN
            %==========================================================
            if isa(guiHandle,'matlab.ui.control.DropDown') || ...
                    contains(guiClass,'DropDown','IgnoreCase',true)

                valStr = rawStr;

                if any(strcmp(guiHandle.Items, valStr))
                    guiHandle.Value = valStr;
                else
                    error('Dropdown: value "%s" not in available items.', valStr);
                end
                return;
            end

            %==========================================================
            % 4) TEXTAREA
            %==========================================================
            if isa(guiHandle,'matlab.ui.control.TextArea')
                guiHandle.Value = rawStr;
                return;
            end

            %==========================================================
            % 5) TEXT EDIT FIELD
            %==========================================================
            if isa(guiHandle,'matlab.ui.control.EditField')
                guiHandle.Value = rawStr;
                return;
            end

            %==========================================================
            % 6) FALLBACK: ANY COMPONENT WITH Value PROPERTY
            %==========================================================
            if isprop(guiHandle,"Value")
                try
                    guiHandle.Value = rawStr;
                catch
                    error('Cannot assign value "%s" to component of class %s.', ...
                        rawStr, guiClass);
                end
            else
                error('Component of class %s has no Value property.', guiClass);
            end
        end


        function showMetadataIssuesDialog(app, issuesTable)
            %--------------------------------------------------------------
            % Shows a uitable listing metadata incompatibilities:
            %   RowIndex, IDName, ColumnName, RawValue, ErrorMessage
            %--------------------------------------------------------------

            if isempty(issuesTable) || height(issuesTable) == 0
                return;
            end

            fig = uifigure( ...
                "Name","Metadata validation issues", ...
                "Position",[200 200 900 400]);

            uitable(fig, ...
                "Data", issuesTable, ...
                "Position",[20 20 860 360], ...
                "ColumnWidth","auto");
        end

        function [allOK, kpiResolutionAll] = showKPIResolutionDialog(app)
            %--------------------------------------------------------------
            % Checks KPI names in app.KPIsToAddTable against KPI names in DB.
            %
            % For KPI already present in DB:
            %   -> automatically "keep" (no UI)
            %
            % For KPI not present in DB:
            %   -> opens a dialog with one row per unknown KPI:
            %        [ImportedKPI] [discard] [add] [mapToExisting] [dropdown existing KPIs]
            %
            % User must choose exactly one option per unknown KPI:
            %   - discard       : ignore this KPI column completely
            %   - add           : create a new KPI column with this name
            %   - mapToExisting : map imported KPI to an existing KPI name (dropdown)
            %
            % Returns:
            %   allOK           : false if user cancels or validation fails
            %   kpiResolutionAll: struct array with fields:
            %       ImportedName
            %       Action        ('keep' / 'discard' / 'add' / 'selectExisting')
            %       TargetName    (KPI name in DB, or empty for discard)
            %--------------------------------------------------------------

            kpiResolutionAll = struct('ImportedName',{}, 'Action',{}, 'TargetName',{});

            % No KPIs imported -> nothing to resolve
            if isempty(app.KPIsToAddTable)
                allOK = true;
                return;
            end

            importedKpiNames = app.KPIsToAddTable.Properties.VariableNames;

            % Escludi colonne template a priori
            templateColsToExclude = {'ID_Template', 'IDName_Template'};
            importedKpiNames = setdiff(importedKpiNames, templateColsToExclude, 'stable');
            % --- Read KPI DB sheet to know existing KPI columns ----------
            try
                kpiDbTable = readtable(app.DB_FilePath, ...
                    "Sheet","KPIs", ...
                    "ReadVariableNames",true, ...
                    "VariableNamingRule","preserve");
            catch
                % If cannot read KPIs sheet, assume no KPI in DB
                kpiDbTable = table();
            end

            dbVarNames       = kpiDbTable.Properties.VariableNames;
            metadataColNames = app.getAllMetadataColumnNames();

            % KPI column names in DB = columns not in metadata
            existingKpiDbNames = setdiff(dbVarNames, metadataColNames, "stable");

            % Unknown KPIs = imported but not in existing DB KPIs
            unknownKpiNames = setdiff(importedKpiNames, existingKpiDbNames, "stable");
            % Scarta a priori colonne template: non sono KPI
            templateColsToExclude = {'ID_Template', 'IDName_Template'};
            unknownKpiNames = setdiff(unknownKpiNames, templateColsToExclude, "stable");

            % If no unknown KPIs: everything is "keep"
            if isempty(unknownKpiNames)
                allOK = true;
                for i = 1:numel(importedKpiNames)
                    kpiResolutionAll(i).ImportedName = importedKpiNames{i};
                    kpiResolutionAll(i).Action       = "keep";
                    kpiResolutionAll(i).TargetName   = importedKpiNames{i};
                end
                return;
            end

            %----------------------------------------------------------
            %  Build dialog UI for UNKNOWN KPIs
            %----------------------------------------------------------
            numUnknown = numel(unknownKpiNames);

            dlgWidth  = 800;
            dlgHeight = 120 + 40 * numUnknown;

            dlg = uifigure( ...
                "Name","Resolve unknown KPIs", ...
                "Position",[200 200 dlgWidth dlgHeight]);

            % Grid: rows = numUnknown + row for buttons, columns = 5:
            %   1: KPI name label
            %   2: discard checkbox
            %   3: add checkbox
            %   4: mapToExisting checkbox
            %   5: dropdown
            grid = uigridlayout(dlg, [numUnknown+1, 5]);
            grid.RowHeight   = [repmat({30}, 1, numUnknown), {40}];
            grid.ColumnWidth = {200, 80, 80, 130, '1x'};

            % Structure for storing handles per row
            rowUI(numUnknown) = struct( ...
                'ImportedName', "", ...
                'DiscardChk', [], ...
                'AddChk', [], ...
                'MapChk', [], ...
                'MapDrop', []);

            % Dropdown items for existing KPI names (first item empty = no selection)
            mapItems = ["", existingKpiDbNames];

            for i = 1:numUnknown
                importedName = unknownKpiNames{i};

                % Column 1: KPI name label
                uilabel(grid, ...
                    "Text", importedName, ...
                    "HorizontalAlignment","left");

                % Column 2: discard checkbox
                discardChk = uicheckbox(grid, ...
                    "Text","discard");

                % Column 3: add checkbox
                addChk = uicheckbox(grid, ...
                    "Text","add");

                % Column 4: mapToExisting checkbox
                mapChk = uicheckbox(grid, ...
                    "Text","mapToExisting");

                % Column 5: dropdown existing KPIs (initially disabled)
                mapDrop = uidropdown(grid, ...
                    "Items", mapItems, ...
                    "Value", mapItems(1), ...
                    "Enable","off");

                % Save in struct
                rowUI(i).ImportedName = importedName;
                rowUI(i).DiscardChk   = discardChk;
                rowUI(i).AddChk       = addChk;
                rowUI(i).MapChk       = mapChk;
                rowUI(i).MapDrop      = mapDrop;

                discardChk.ValueChangedFcn = @(src,evt) ...
                    app.onKPIChoiceChanged(i, "discard", rowUI);
                addChk.ValueChangedFcn = @(src,evt) ...
                    app.onKPIChoiceChanged(i, "add", rowUI);
                mapChk.ValueChangedFcn = @(src,evt) ...
                    app.onKPIChoiceChanged(i, "map", rowUI);
            end

            % Salva rowUI in dlg.UserData PRIMA delle callback
            dlg.UserData.RowUI            = rowUI;
            dlg.UserData.KPIResolutionAll = [];

            % Crea callback passando dlg (handle by reference)
            for i = 1:numUnknown
                iCaptured = i;
                dlg.UserData.RowUI(iCaptured).DiscardChk.ValueChangedFcn = @(src,evt) ...
                    app.onKPIChoiceChanged(iCaptured, "discard", dlg);
                dlg.UserData.RowUI(iCaptured).AddChk.ValueChangedFcn = @(src,evt) ...
                    app.onKPIChoiceChanged(iCaptured, "add", dlg);
                dlg.UserData.RowUI(iCaptured).MapChk.ValueChangedFcn = @(src,evt) ...
                    app.onKPIChoiceChanged(iCaptured, "map", dlg);
            end

            % Last row: buttons
            confirmBtn = uibutton(grid, "Text","Confirm", ...
                "ButtonPushedFcn", @(src,evt) ...
                app.confirmKPIResolution(dlg, importedKpiNames, existingKpiDbNames));


            confirmBtn.Layout.Row    = numUnknown+1;
            confirmBtn.Layout.Column = 2;

            cancelBtn = uibutton(grid, "Text","Cancel", ...
                "ButtonPushedFcn", @(src,evt) close(dlg));
            cancelBtn.Layout.Row    = numUnknown+1;
            cancelBtn.Layout.Column = 4;

            % Wait for dialog result
            uiwait(dlg);

            if ~isvalid(dlg)
                allOK = false;
                kpiResolutionAll = struct('ImportedName',{},'Action',{},'TargetName',{});
                return;
            end

            dlgData = dlg.UserData;
            if isempty(dlgData) || isempty(dlgData.KPIResolutionAll)
                allOK = false;
                kpiResolutionAll = struct('ImportedName',{},'Action',{},'TargetName',{});
            else
                allOK = true;
                kpiResolutionAll = dlgData.KPIResolutionAll;
            end

            try
                close(dlg);
            catch
            end
        end


        function onKPIChoiceChanged(app, rowIndex, choiceType, dlg)
            rowUI = dlg.UserData.RowUI;
            uiRow = rowUI(rowIndex);

            switch choiceType
                case "discard"
                    if uiRow.DiscardChk.Value
                        uiRow.AddChk.Value   = false;
                        uiRow.MapChk.Value   = false;
                        uiRow.MapDrop.Enable = "off";
                        uiRow.MapDrop.Value  = uiRow.MapDrop.Items(1);
                    end
                case "add"
                    if uiRow.AddChk.Value
                        uiRow.DiscardChk.Value = false;
                        uiRow.MapChk.Value     = false;
                        uiRow.MapDrop.Enable   = "off";
                        uiRow.MapDrop.Value    = uiRow.MapDrop.Items(1);
                    end
                case "map"
                    if uiRow.MapChk.Value
                        uiRow.DiscardChk.Value = false;
                        uiRow.AddChk.Value     = false;
                        uiRow.MapDrop.Enable   = "on";
                    else
                        uiRow.MapDrop.Enable = "off";
                        uiRow.MapDrop.Value  = uiRow.MapDrop.Items(1);
                    end
            end
        end


        function confirmKPIResolution(app, dlg, importedAllNames, existingKpiDbNames)

            rowUI      = dlg.UserData.RowUI;   % <-- legge da dlg, non da parametro
            numUnknown = numel(rowUI);

            import containers.Map
            resMap = Map('KeyType','char','ValueType','any');

            for i = 1:numUnknown
                uiRow = rowUI(i);

                importedName = string(uiRow.ImportedName);
                discardVal   = uiRow.DiscardChk.Value;
                addVal       = uiRow.AddChk.Value;
                mapVal       = uiRow.MapChk.Value;
                dropVal      = string(uiRow.MapDrop.Value);

                numChosen = discardVal + addVal + mapVal;
                if numChosen ~= 1
                    uialert(dlg, ...
                        sprintf("For KPI '%s' select exactly one option.", importedName), ...
                        "Invalid selection");
                    return;
                end

                resStruct = struct('ImportedName', importedName, 'Action', "", 'TargetName', "");

                if mapVal
                    if dropVal == "" || dropVal == " "
                        uialert(dlg, ...
                            sprintf("For KPI '%s' no target selected.", importedName), ...
                            "Missing mapping");
                        return;
                    end
                    resStruct.Action     = "selectExisting";
                    resStruct.TargetName = dropVal;
                elseif addVal
                    resStruct.Action     = "add";
                    resStruct.TargetName = importedName;
                elseif discardVal
                    resStruct.Action     = "discard";
                    resStruct.TargetName = "";
                end

                resMap(char(importedName)) = resStruct;
            end

            importedAllNames = string(importedAllNames);
            kpiResolutionAll(numel(importedAllNames)) = struct( ...
                'ImportedName',"", 'Action',"", 'TargetName',"");

            for i = 1:numel(importedAllNames)
                name = importedAllNames(i);
                key  = char(name);
                if resMap.isKey(key)
                    kpiResolutionAll(i) = resMap(key);
                else
                    kpiResolutionAll(i).ImportedName = name;
                    kpiResolutionAll(i).Action       = "keep";
                    kpiResolutionAll(i).TargetName   = name;
                end
            end

            dlg.UserData.KPIResolutionAll = kpiResolutionAll;
            uiresume(dlg);
        end

        function applyImportedMetadataAndKPIsToDB(app, kpiResolutionAll)

            metadataSheetName = "Metadata";
            metadataTableName = "TableMet";
            kpiSheetName      = "KPIs";
            kpiTableName      = "TableKPI";
            idColumnName      = "ID";
            idNameColumnName  = "IDName";
            dateColumnName    = "Date";

            metadataTable = app.MetadataToAddTable;
            kpiTable      = app.KPIsToAddTable;

            if isempty(metadataTable)
                return;
            end

            %--------------------------------------------------------------
            % CONTROLLO 1: stesso numero di righe
            %--------------------------------------------------------------
            if ~isempty(kpiTable) && height(kpiTable) ~= height(metadataTable)
                uialert(app.UIFigure, ...
                    sprintf("Numero di righe non coincide:\nMetadata: %d righe\nKPIs: %d righe\nVerifica il file importato.", ...
                    height(metadataTable), height(kpiTable)), ...
                    "Errore consistenza tabelle");
                error("applyImportedMetadataAndKPIsToDB:RowMismatch", ...
                    "Metadata and KPI tables have different number of rows.");
            end

            %--------------------------------------------------------------
            % CONTROLLO 2: stessi ID nelle stesse posizioni (se ID esiste in entrambe)
            %--------------------------------------------------------------
            if ~isempty(kpiTable) && ...
                    ismember(idColumnName, metadataTable.Properties.VariableNames) && ...
                    ismember(idColumnName, kpiTable.Properties.VariableNames)

                metaIDs = string(metadataTable.(idColumnName));
                kpiIDs  = string(kpiTable.(idColumnName));

                if ~isequal(metaIDs, kpiIDs)
                    mismatchIdx = find(~strcmp(metaIDs, kpiIDs));
                    uialert(app.UIFigure, ...
                        sprintf("ID non corrispondenti tra Metadata e KPIs alle righe: %s\nVerifica il file importato.", ...
                        mat2str(mismatchIdx')), ...
                        "Errore ID non corrispondenti");
                    error("applyImportedMetadataAndKPIsToDB:IDMismatch", ...
                        "Metadata and KPI tables have mismatched IDs.");
                end
            end

            %--------------------------------------------------------------
            % 1) Determina il prossimo ID disponibile nel DB
            %--------------------------------------------------------------
            try
                metadataDB = readtable(app.DB_FilePath, ...
                    "Sheet", metadataSheetName, ...
                    "ReadVariableNames", true, ...
                    "VariableNamingRule", "preserve");
            catch
                metadataDB = table();
            end

            if ~isempty(metadataDB) && ismember(idColumnName, metadataDB.Properties.VariableNames)
                currentIDs = metadataDB.(idColumnName);
                if isnumeric(currentIDs)
                    nextID = max(currentIDs) + 1;
                else
                    numericIDs = str2double(string(currentIDs));
                    nextID = max(numericIDs(~isnan(numericIDs))) + 1;
                end
            else
                nextID = 1;
            end

            numRowsToAdd = height(metadataTable);

            %--------------------------------------------------------------
            % 2) Assicura colonne KPI necessarie
            %--------------------------------------------------------------
            requiredMetaCols = {idColumnName, idNameColumnName, dateColumnName};
            app.ensureKpiColumnsForActions(kpiSheetName, kpiTableName, ...
                kpiResolutionAll, requiredMetaCols);

            %--------------------------------------------------------------
            % 3) Loop su ogni riga: verifica corrispondenza prima di scrivere
            %--------------------------------------------------------------
            for r = 1:numRowsToAdd

                %--- Costruisci struct metadata riga r ---
                metaRowStruct = table2struct(metadataTable(r,:));
                metaRowStruct.(idColumnName) = nextID;

                %--- Costruisci struct KPI riga r ---
                kpiRowStruct = struct();
                kpiRowStruct.(idColumnName) = nextID;

                if isfield(metaRowStruct, idNameColumnName)
                    kpiRowStruct.(idNameColumnName) = metaRowStruct.(idNameColumnName);
                else
                    kpiRowStruct.(idNameColumnName) = "";
                end

                if isfield(metaRowStruct, dateColumnName)
                    dateVal = metaRowStruct.(dateColumnName);
                    if isa(dateVal, "datetime")
                        kpiRowStruct.(dateColumnName) = string(dateVal, "dd/MM/yyyy");
                    else
                        kpiRowStruct.(dateColumnName) = string(dateVal);
                    end
                else
                    kpiRowStruct.(dateColumnName) = "";
                end

                %--- Valori KPI per riga r ---
                if ~isempty(kpiTable)

                    % CONTROLLO 3: verifica che la riga r di KPI corrisponda
                    % alla riga r di metadata (per IDName se disponibile)
                    if ismember(idNameColumnName, metadataTable.Properties.VariableNames) && ...
                            ismember(idNameColumnName, kpiTable.Properties.VariableNames)

                        metaIDName = string(metadataTable.(idNameColumnName)(r));
                        kpiIDName  = string(kpiTable.(idNameColumnName)(r));

                        if ~strcmp(metaIDName, kpiIDName)
                            uialert(app.UIFigure, ...
                                sprintf("Riga %d: IDName non corrispondente.\nMetadata: '%s'\nKPIs: '%s'\nScrittura interrotta.", ...
                                r, metaIDName, kpiIDName), ...
                                "Errore corrispondenza riga");
                            error("applyImportedMetadataAndKPIsToDB:RowIDNameMismatch", ...
                                "Row %d: IDName mismatch between Metadata and KPIs.", r);
                        end
                    end

                    for k = 1:numel(kpiResolutionAll)
                        importedName = string(kpiResolutionAll(k).ImportedName);
                        action       = char(kpiResolutionAll(k).Action);
                        targetName   = string(kpiResolutionAll(k).TargetName);

                        if ~ismember(importedName, kpiTable.Properties.VariableNames)
                            continue;
                        end

                        if strcmp(action, "discard")
                            continue;
                        end

                        targetName = strtrim(targetName);
                        if strlength(targetName) == 0
                            continue;
                        end

                        kpiVal = kpiTable.(importedName)(r);

                        rawKpiValue = kpiTable.(importedName)(r);

                        %----------------------------------------------------------
                        % KPI SANITIZATION: force numeric, replace text/NA with NaN
                        %----------------------------------------------------------
                        kpiNumericValue = sanitizeKpiValue(rawKpiValue);

                        kpiRowStruct.(targetName) = kpiNumericValue;

                    end
                end

                %--- Scrivi metadata ---
                app.appendStructAsExcelTableRow( ...
                    metaRowStruct, metadataSheetName, metadataTableName);

                %--- Scrivi KPI (stesso ID) ---
                app.appendStructAsExcelTableRow( ...
                    kpiRowStruct, kpiSheetName, kpiTableName);

                nextID = nextID + 1;
            end
        end


        function ensureKpiColumnsForActions(app, kpiSheetName, kpiTableName, ...
                kpiResolutionAll, requiredMetaCols)
            %--------------------------------------------------------------
            % Ensures KPI ListObject (sheet kpiSheetName, table kpiTableName)
            % has:
            %   - all required metadata columns in requiredMetaCols
            %   - all KPI columns needed by kpiResolutionAll with Action
            %     in {"keep","add","selectExisting"}.
            %
            % Rules:
            %   Action = "discard"
            %       -> completely ignored (no column check/creation).
            %
            %   Action = "add"
            %       -> if TargetName not in ListColumns, add new ListColumn
            %          at the end and name it TargetName.
            %
            %   Action = "keep" or "selectExisting"
            %       -> TargetName must already exist as column name.
            %--------------------------------------------------------------

            % Filter out discarded KPIs (robust with strcmp)
            allActions = arrayfun(@(x) char(x.Action), kpiResolutionAll, ...
                'UniformOutput', false);
            activeMask = ~strcmp(allActions, 'discard');
            kpiResolutionActive = kpiResolutionAll(activeMask);

            excelApp      = [];
            excelWorkbook = [];

            try
                excelApp = actxserver('Excel.Application');
                excelApp.Visible = false;

                excelWorkbook = excelApp.Workbooks.Open(app.DB_FilePath);

                kpiWorksheet = excelWorkbook.Worksheets.Item(kpiSheetName);

                if kpiWorksheet.ListObjects.Count == 0
                    error('No ListObject found on sheet "%s".', kpiSheetName);
                end

                try
                    kpiListObj = kpiWorksheet.ListObjects.Item(kpiTableName);
                catch
                    kpiListObj = kpiWorksheet.ListObjects.Item(1);
                end

                % Build current column name set
                numCols = kpiListObj.ListColumns.Count;
                existingNames = strings(1, numCols);
                for c = 1:numCols
                    colObj = kpiListObj.ListColumns.Item(c);
                    existingNames(c) = string(colObj.Name);
                end

                % 1) Ensure required metadata columns exist (ID, IDName, Date)
                for i = 1:numel(requiredMetaCols)
                    reqName = string(requiredMetaCols{i});
                    if ~any(existingNames == reqName)
                        error('KPI table "%s" missing required metadata column "%s".', ...
                            kpiTableName, reqName);
                    end
                end

                % 2) For active KPI resolutions: ensure/add columns
                for i = 1:numel(kpiResolutionActive)
                    action     = char(kpiResolutionActive(i).Action);
                    targetName = string(kpiResolutionActive(i).TargetName);
                    targetName = strtrim(targetName);

                    if isempty(targetName)
                        error('KPI resolution "%s" requires non-empty TargetName.', action);
                    end

                    if strcmp(action, "add")
                        % Create new column if missing
                        if ~any(existingNames == targetName)
                            newCol = kpiListObj.ListColumns.Add();   % appended at right
                            newCol.Name = char(targetName);

                            % Update existingNames
                            numCols = kpiListObj.ListColumns.Count;
                            existingNames(end+1) = targetName;
                        end

                    elseif strcmp(action, "keep") || strcmp(action, "selectExisting")
                        % Column must already exist
                        if ~any(existingNames == targetName)
                            error('Target KPI column "%s" not found in KPI table.', targetName);
                        end
                    end
                end

                % Save and close
                excelWorkbook.Save();
                excelWorkbook.Close(false);

                excelApp.Quit();
                delete(excelApp);

            catch ME
                % Cleanup on error
                if ~isempty(excelWorkbook)
                    try
                        excelWorkbook.Close(false);
                    end
                end

                if ~isempty(excelApp)
                    try
                        excelApp.Quit();
                    end
                    delete(excelApp);
                end

                rethrow(ME);
            end
        end


        function [noDuplicates, duplicatesSummaryTable] = checkMetadataDuplicatesAgainstDB(app)
            %--------------------------------------------------------------------------
            % checkMetadataDuplicatesAgainstDB
            %
            % Detects if any row in app.MetadataToAddTable is already present
            % in the DB "Metadata" sheet. Two rows are considered identical if
            % ALL columns except "ID" have the same value, after:
            %   - converting any type to string
            %   - trimming leading/trailing spaces
            %   - normalizing common missing tokens (NA, N/A, Missing, NaN, "")
            %
            % OUTPUT:
            %   noDuplicates (logical)
            %       true  -> no duplicates found
            %       false -> duplicatesSummaryTable contains at least one row
            %
            %   duplicatesSummaryTable (table) with columns:
            %       NewRowIndex : index in MetadataToAddTable (1-based)
            %       DBRowIndex  : matching row index in DB Metadata (1-based)
            %       IDNameNew   : IDName from imported row (if exists)
            %       IDNameDB    : IDName from DB row (if exists)
            %--------------------------------------------------------------------------

            %------------------------------------------------------
            % Default outputs
            %------------------------------------------------------
            noDuplicates = true;
            duplicatesSummaryTable = table( ...
                'Size', [0 4], ...
                'VariableTypes', {'double','double','string','string'}, ...
                'VariableNames', {'NewRowIndex','DBRowIndex','IDNameNew','IDNameDB'});

            importedTable = app.MetadataToAddTable;
            if isempty(importedTable)
                return;
            end

            %------------------------------------------------------
            % Read DB metadata
            %------------------------------------------------------
            try
                dbTable = readtable(app.DB_FilePath, ...
                    "Sheet","Metadata", ...
                    "ReadVariableNames",true, ...
                    "VariableNamingRule","preserve");
            catch
                % Cannot read DB => treat as empty, no duplicates
                return;
            end

            if isempty(dbTable)
                return;
            end

            %------------------------------------------------------
            % Columns to compare: all common ones except ID
            %------------------------------------------------------
            idColumn = "ID";

            importedVars = importedTable.Properties.VariableNames;
            dbVars       = dbTable.Properties.VariableNames;

            commonCols = intersect(importedVars, dbVars, "stable");
            commonCols = setdiff(commonCols, idColumn, "stable");

            if isempty(commonCols)
                % Nothing to compare
                return;
            end

            %------------------------------------------------------
            % Helper: convert ANY column to normalized string
            %------------------------------------------------------
            function colStr = normalizeColumn(col)
                if iscell(col)
                    colStr = string(col);
                elseif isdatetime(col)
                    % Forza sempre dd/MM/yyyy per confronto consistente
                    colStr = string(col, "dd/MM/yyyy");
                elseif isnumeric(col)
                    % Numeri: rimuovi decimali inutili (es. "1.0" -> "1")
                    colStr = string(col);
                    % Gestisce Excel serial dates letti come numeri
                    mask = ~isnan(col) & col > 40000 & col < 60000;
                    if any(mask)
                        dt = datetime(col(mask), 'ConvertFrom', 'excel');
                        colStr(mask) = string(dt, "dd/MM/yyyy");
                    end
                elseif isstring(col) || ischar(col)
                    colStr = string(col);
                    % Prova a normalizzare stringhe che sembrano date
                    try
                        dt = datetime(colStr, 'InputFormat', 'dd/MM/yyyy');
                        colStr = string(dt, "dd/MM/yyyy");
                    catch
                        % Non è una data, lascia as-is
                    end
                elseif iscategorical(col)
                    colStr = string(col);
                else
                    colStr = string(col);
                end

                colStr = strtrim(colStr);
                missingTokens = ["", "NA", "NaN", "N/A", "Missing", "NaT", "-", "none", "None"];
                colStr(ismember(lower(colStr), lower(missingTokens))) = "";
            end

            %------------------------------------------------------
            % Build row keys for DB and imported tables
            %------------------------------------------------------
            numDB  = height(dbTable);
            numNew = height(importedTable);
            numCols = numel(commonCols);

            % DB keys
            dbMat = strings(numDB, numCols);
            for c = 1:numCols
                col = normalizeColumn(dbTable.(commonCols{c}));
                col(ismissing(col)) = "";   % <-- FIX: sostituisce <missing> con ""
                dbMat(:, c) = col;
            end
            dbKey = join(dbMat, "|", 2);

            % Imported keys
            newMat = strings(numNew, numCols);
            for c = 1:numCols
                col = normalizeColumn(importedTable.(commonCols{c}));
                col(ismissing(col)) = "";   % <-- FIX: sostituisce <missing> con ""
                newMat(:, c) = col;
            end
            newKey = join(newMat, "|", 2);

            %------------------------------------------------------
            % Compare: newKey vs dbKey
            %------------------------------------------------------
            [isDupMask, dbMatchIdx] = ismember(newKey, dbKey);

            if ~any(isDupMask)
                % No duplicates in DB
                return;
            end

            noDuplicates = false;

            dupNewIdx = find(isDupMask);
            dupDbIdx  = dbMatchIdx(isDupMask);

            %------------------------------------------------------
            % Build summary table
            %------------------------------------------------------

            idNameField   = "IDName";
            hasIDNameNew  = ismember(idNameField, importedVars);
            hasIDNameDB   = ismember(idNameField, dbVars);

            % Create table with DBIDnumber
            duplicatesSummaryTable = table( ...
                'Size',[numel(dupNewIdx) 4], ...
                'VariableTypes', {'double','double','string','string'}, ...
                'VariableNames', {'NewRowIndex','DBIDnumber','IDNameNew','IDNameDB'} );

            for k = 1:numel(dupNewIdx)

                newIdx = dupNewIdx(k);
                dbIdx  = dupDbIdx(k);

                % A) New row index
                duplicatesSummaryTable.NewRowIndex(k) = newIdx;

                % B) TRUE DB ID (not row index)
                if ismember("ID", dbVars)
                    duplicatesSummaryTable.DBIDnumber(k) = dbTable.ID(dbIdx);
                else
                    duplicatesSummaryTable.DBIDnumber(k) = NaN;
                end

                % C) IDName from imported row
                if hasIDNameNew
                    duplicatesSummaryTable.IDNameNew(k) = normalizeColumn( importedTable.(idNameField)(newIdx) );
                else
                    duplicatesSummaryTable.IDNameNew(k) = "";
                end

                % D) IDName from DB row
                if hasIDNameDB
                    duplicatesSummaryTable.IDNameDB(k) = normalizeColumn( dbTable.(idNameField)(dbIdx) );
                else
                    duplicatesSummaryTable.IDNameDB(k) = "";
                end
            end

        end


        function showMetadataDuplicatesDialog(app, duplicatesSummaryTable)
            %--------------------------------------------------------------
            % showMetadataDuplicatesDialog
            %
            % Shows a uitable with metadata rows that already exist in DB.
            %
            % duplicatesSummaryTable has columns:
            %   NewRowIndex, DBRowIndex, IDNameNew, IDNameDB
            %--------------------------------------------------------------

            if isempty(duplicatesSummaryTable) || height(duplicatesSummaryTable) == 0
                return;
            end

            dlg = uifigure( ...
                "Name","Duplicate Metadata Rows Detected", ...
                "Position",[250 250 700 300]);

            uitable(dlg, ...
                "Data", duplicatesSummaryTable, ...
                "Position",[20 20 660 260], ...
                "ColumnWidth","auto");

            uialert(dlg, ...
                "Some imported metadata rows are identical to existing DB rows. Review and adjust the import file before writing to DB.", ...
                "Duplicate metadata");
        end


        function [hasDuplicates, duplicateIndices] = checkInternalDuplicates(app)
            tbl = app.MetadataToAddTable;
            if height(tbl) <= 1
                hasDuplicates = false;
                duplicateIndices = [];
                return;
            end

            % Escludi colonna ID se presente
            cols = setdiff(tbl.Properties.VariableNames, "ID", "stable");
            subTbl = tbl(:, cols);

            % Converti tutto a stringa e crea chiave
            keys = strings(height(subTbl), 1);
            for r = 1:height(subTbl)
                keys(r) = strjoin(string(table2cell(subTbl(r,:))), "|");
            end

            [~, uniqueIdx] = unique(keys, "stable");
            duplicateIndices = setdiff(1:height(tbl), uniqueIdx);
            hasDuplicates = ~isempty(duplicateIndices);
        end


        function [isValid, errorMsg] = validateDBConsistency(app)
            %--------------------------------------------------------------
            % Verifica che le tabelle Metadata e KPIs nel DB siano consistenti:
            %   1) stesso numero di righe
            %   2) stessi ID nelle stesse posizioni
            %--------------------------------------------------------------

            isValid  = false;
            errorMsg = "";

            metadataSheetName = "Metadata";
            kpiSheetName      = "KPIs";
            idColumnName      = "ID";

            %--- Leggi Metadata dal DB ---
            try
                metadataDB = readtable(app.DB_FilePath, ...
                    "Sheet", metadataSheetName, ...
                    "ReadVariableNames", true, ...
                    "VariableNamingRule", "preserve");
            catch ME
                errorMsg = sprintf("Impossibile leggere il foglio Metadata:\n%s", ME.message);
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                return;
            end

            %--- Leggi KPIs dal DB ---
            try
                kpiDB = readtable(app.DB_FilePath, ...
                    "Sheet", kpiSheetName, ...
                    "ReadVariableNames", true, ...
                    "VariableNamingRule", "preserve");
            catch ME
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                errorMsg = sprintf("Impossibile leggere il foglio KPIs:\n%s", ME.message);
                return;
            end

            %--- Se entrambe vuote: DB vuoto, tutto ok ---
            if isempty(metadataDB) && isempty(kpiDB)
                isValid = true;
                return;
            end

            %--- Controllo 1: stesso numero di righe ---
            if height(metadataDB) ~= height(kpiDB)
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                errorMsg = sprintf( ...
                    "Il DB non è consistente:\nMetadata ha %d righe, KPIs ha %d righe.\nCorreggi il DB prima di procedere.", ...
                    height(metadataDB), height(kpiDB));
                return;
            end

            %--- Controllo 2: colonna ID presente in entrambe ---
            hasMeta_ID = ismember(idColumnName, metadataDB.Properties.VariableNames);
            hasKPI_ID  = ismember(idColumnName, kpiDB.Properties.VariableNames);

            if ~hasMeta_ID
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                errorMsg = sprintf("Colonna '%s' non trovata nel foglio Metadata.", idColumnName);
                return;
            end
            if ~hasKPI_ID
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                errorMsg = sprintf("Colonna '%s' non trovata nel foglio KPIs.", idColumnName);
                return;
            end

            %--- Controllo 3: ID corrispondenti posizione per posizione ---
            metaIDs = string(metadataDB.(idColumnName));
            kpiIDs  = string(kpiDB.(idColumnName));

            mismatchMask = ~strcmp(metaIDs, kpiIDs);
            if any(mismatchMask)
                mismatchRows = find(mismatchMask);
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                errorMsg = sprintf( ...
                    "Il DB non è consistente:\nID non corrispondenti tra Metadata e KPIs alle righe DB: %s\nCorreggi il DB prima di procedere.", ...
                    mat2str(mismatchRows'));
                return;
            end

            %--- Controllo 4: nessun ID duplicato in Metadata ---
            [uniqueMetaIDs, ~, ic] = unique(metaIDs, "stable");
            if numel(uniqueMetaIDs) ~= numel(metaIDs)
                counts = accumarray(ic, 1);
                dupIDs = uniqueMetaIDs(counts > 1);
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                errorMsg = sprintf( ...
                    "Il DB non è consistente:\nID duplicati trovati in Metadata: %s\nCorreggi il DB prima di procedere.", ...
                    strjoin(dupIDs, ", "));
                return;
            end

            %--- Controllo 5: nessun ID duplicato in KPIs ---
            [uniqueKpiIDs, ~, ic] = unique(kpiIDs, "stable");
            if numel(uniqueKpiIDs) ~= numel(kpiIDs)
                counts = accumarray(ic, 1);
                dupIDs = uniqueKpiIDs(counts > 1);
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                errorMsg = sprintf( ...
                    "Il DB non è consistente:\nID duplicati trovati in KPIs: %s\nCorreggi il DB prima di procedere.", ...
                    strjoin(dupIDs, ", "));
                return;
            end

            isValid  = true;
            errorMsg = "";
        end

        function closeAllExcelButtonPushed(app)
            try
                % Prova a prendere istanza Excel COM già aperta da MATLAB
                excelApp = actxGetRunningServer('Excel.Application');

                % Chiudi tutti i workbook aperti senza salvare
                workbooks = excelApp.Workbooks;
                for i = workbooks.Count : -1 : 1
                    workbooks.Item(i).Close(false); % false = non salvare
                end

                % Chiudi Excel stesso
                excelApp.Quit();
                excelApp.delete();

                uialert(app.UIFigure, 'Tutti i file Excel sono stati chiusi.', 'OK', 'Icon', 'success');

            catch ME
                % Nessun Excel aperto da MATLAB, oppure già chiuso
                uialert(app.UIFigure, 'Nessun file Excel aperto da MATLAB trovato.', 'Info', 'Icon', 'info');
            end
        end


            function importMetadataKPIsFromFile(app, fullPath)
                %IMPORTMETADATAKPISFROMFILE  Read metadata + KPIs from an Excel file at a
                %   known path and populate app.MetadataToAddTable and app.KPIsToAddTable.
                %
                %   Inputs:
                %     app      – handle to the App Designer app object
                %     fullPath – absolute path to the .xlsx file
                %
                %   Outputs (written to app):
                %     app.MetadataToAddTable  – table of metadata columns
                %     app.KPIsToAddTable      – table of KPI columns
                %
                %   A preview figure is also opened at the end.

                %----------------------------------------------------------
                % 1) Read file
                %----------------------------------------------------------
                try
                    inputTable = readtable(fullPath, ...
                        "ReadVariableNames", true, ...
                        "TextType", "string", ...
                        "PreserveVariableNames", true);
                catch ME
                    uialert(app.UIFigure, ...
                        sprintf("Error reading Excel file:\n%s", ME.message), ...
                        "Read error");
                    return;
                end

                if isempty(inputTable)
                    uialert(app.UIFigure, "The selected file is empty.", "Empty file");
                    return;
                end

                %----------------------------------------------------------
                % 2) Post-process each column
                %    – datetime          → string "dd/MM/yyyy"
                %    – string/numeric-like → double
                %    – string (other)    → keep as string
                %    – char/cell/categorical → convert to string
                %----------------------------------------------------------
                processedTable = inputTable;
                varNames = processedTable.Properties.VariableNames;

                for iCol = 1:numel(varNames)
                    colName = varNames{iCol};
                    colData = processedTable.(colName);

                    if isa(colData, "datetime")
                        processedTable.(colName) = string(colData, "dd/MM/yyyy");
                        continue;
                    end

                    if isnumeric(colData)
                        continue;
                    end

                    if isa(colData, "string")
                        nonEmptyMask   = (colData ~= "");
                        nonEmptyValues = colData(nonEmptyMask);

                        if isempty(nonEmptyValues)
                            continue;
                        end

                        matchDigits       = regexp(cellstr(nonEmptyValues), '^[0-9]+$', 'once');
                        isAllNumericLike  = all(~cellfun(@isempty, matchDigits));

                        if isAllNumericLike
                            numericCol = NaN(height(processedTable), 1);
                            numericCol(nonEmptyMask) = double(str2double(nonEmptyValues));
                            processedTable.(colName) = numericCol;
                        end

                        continue;
                    end

                    if iscell(colData) || ischar(colData) || iscategorical(colData)
                        processedTable.(colName) = string(colData);
                        continue;
                    end

                    processedTable.(colName) = string(colData);
                end

                %----------------------------------------------------------
                % 3) Split METADATA vs KPI columns
                %----------------------------------------------------------
                metadataColumnNames = app.getAllMetadataColumnNames();
                allVarNames = processedTable.Properties.VariableNames;
                isMetadata  = ismember(allVarNames, metadataColumnNames);

                app.MetadataToAddTable = processedTable(:,  isMetadata);
                app.KPIsToAddTable     = processedTable(:, ~isMetadata);

                %----------------------------------------------------------
                % 4) Show preview
                %----------------------------------------------------------
                combinedTable = [app.MetadataToAddTable, app.KPIsToAddTable];

                previewFig = uifigure( ...
                    "Name",     "Preview Imported Metadata + KPIs", ...
                    "Position", [300 200 1000 500]);

                uitable(previewFig, ...
                    "Data",       combinedTable, ...
                    "Position",   [20 20 960 460], ...
                    "ColumnName", combinedTable.Properties.VariableNames, ...
                    "ColumnWidth", "auto");

                uialert(app.UIFigure, ...
                    sprintf("Imported %d rows.\nMetadata columns: %d\nKPI columns: %d", ...
                    height(processedTable), ...
                    width(app.MetadataToAddTable), ...
                    width(app.KPIsToAddTable)), ...
                    "Import complete", ...
                    "Icon", "success");

            end
        
 function outputFilePath = writeMetadata_ALL_DB_FromGUI(app, LoLa_excel_path)
                % writeMetadata_ALL_DB_FromGUI
                % Build metadata struct from GUI mappings (General + IC) and write it into a
                % COPY of the consolidated LoLa Excel file (ALL_DB), row-by-row.
                %
                % FINAL COLUMN ORDER REQUIREMENT:
                %   1) Repetition_ID
                %   2) FileName
                %   3) StartSpeed
                %   4) GUI METADATA fields (General + IC, in GUI-build order)
                %   5) All remaining original columns (in original order)
                %
                % CONSTRAINTS:
                %   - DO NOT overwrite 'Repetition_ID' (keep values from Excel if present)
                %   - DO NOT overwrite 'FileName'      (keep values from Excel if present)
                %   - DO NOT overwrite 'StartSpeed' where existing cell is numeric
                %   - DO NOT write to DB; operate only on a COPY of LoLa excel file
                %   - When InferMetadataFromFilename is active, Date/DrivingMode/ManeuvreName/Driver
                %     are inferred per-row from FileName and NOT overwritten by GUI template values
                %
                % OUTPUT:
                %   outputFilePath : char, full path of generated copy: Metadata_ALL_DB.<ext>

                %-------------------------
                % 0) Resolve input path
                %-------------------------
                if nargin < 2 || isempty(LoLa_excel_path)
                    if isprop(app, 'LoLa_excel_path')
                        LoLa_excel_path = app.LoLa_excel_path;
                    else
                        error('LoLa_excel_path not provided and app.LoLa_excel_path not available.');
                    end
                end

                if isempty(LoLa_excel_path) || ~isfile(LoLa_excel_path)
                    error('LoLa_excel_path is empty or does not exist: %s', string(LoLa_excel_path));
                end

                %-------------------------------------------------------------------------
                % 0b) Check if "Infer metadata from filename" feature is enabled in GUI
                %     Expects app.InferMetadataFromFilenameCheckBox (Value = true/false)
                %     Fields inferred from filename (pattern X_X_Date_X_DrivMode_Maneuvre_Driver_X.mf4):
                %       position 3 -> Date
                %       position 5 -> DrivingMode
                %       position 6 -> ManeuvreName
                %       position 7 -> Driver
                %-------------------------------------------------------------------------
                inferFromFilename = false;
                if isprop(app, 'InferefromFilenameCheckBox') && ...
                        ~isempty(app.InferefromFilenameCheckBox)
                    inferFromFilename = app.InferefromFilenameCheckBox.Value;
                end

                % Fields that will be inferred from filename (when feature is ON).
                % These must NOT be overwritten by GUI template values when inferFromFilename=true.
                INFERRED_FIELDS = {'Date', 'Driving_Mode', 'ManeuvreName', 'Driver'};

                %---------------------------------------------------------
                % 1) Build combined metadata struct from GUI mappings
                %    (same logic as WriteMetadatatonewIDButtonPushed)
                %    + preserve field order: General fields first, then IC fields.
                %---------------------------------------------------------
                generalRowStruct = app.buildRowFromMapping(app.GeneralMetadataMap);
                icRowStruct      = app.buildRowFromMapping(app.ICMetadataMap);

                combinedMetadataStruct = generalRowStruct;

                icFields = fieldnames(icRowStruct);
                for k = 1:numel(icFields)
                    fieldName = icFields{k};
                    combinedMetadataStruct.(fieldName) = icRowStruct.(fieldName);
                end

                % Build explicit GUI metadata field order (stable)
                generalFields = fieldnames(generalRowStruct);
                guiMetaFieldOrder = generalFields;
                for k = 1:numel(icFields)
                    if ~any(strcmp(guiMetaFieldOrder, icFields{k}))
                        guiMetaFieldOrder{end+1} = icFields{k}; %#ok<AGROW>
                    end
                end

                %---------------------------------------------------------
                % 2) Prepare output file path and copy input file
                %---------------------------------------------------------
                [inputFolder, ~, inputExt] = fileparts(LoLa_excel_path);
                if isempty(inputExt)
                    inputExt = '.xlsx';
                end

                outputFilePath = fullfile(inputFolder, ['Metadata_ALL_DB' inputExt]);

                if isfile(outputFilePath)
                    delete(outputFilePath);
                end
                copyfile(LoLa_excel_path, outputFilePath);

                %---------------------------------------------------------
                % 3) Choose sheet (first sheet; fallback 'Sheet1')
                %---------------------------------------------------------
                dataSheetName = 'Sheet1';
                try
                    sheetList = sheetnames(outputFilePath);
                    if ~isempty(sheetList)
                        dataSheetName = sheetList(1);
                    end
                catch
                    % Older MATLAB: keep default
                end

                %---------------------------------------------------------
                % 4) Read consolidated table from the copied file
                %---------------------------------------------------------
                T = readtable(outputFilePath, ...
                    'Sheet', dataSheetName, ...
                    'ReadVariableNames', true, ...
                    'VariableNamingRule', 'modify');

                if isempty(T) || height(T) == 0
                    error('The consolidated Excel file contains no rows to update.');
                end

                nRows = height(T);

                %---------------------------------------------------------
                % 5) Ensure mandatory columns exist (do NOT overwrite existing)
                %    - Repetition_ID: create only if missing (empty)
                %    - FileName:      create only if missing (empty)
                %    - StartSpeed:    create if missing (fill from GUI if provided, else empty)
                %---------------------------------------------------------
                if ~ismember('Repetition_ID', T.Properties.VariableNames)
                    T.Repetition_ID = nan(nRows, 1);
                end

                if ~ismember('FileName', T.Properties.VariableNames)
                    T.FileName = repmat(string(""), nRows, 1);
                end

                if ~ismember('StartSpeed', T.Properties.VariableNames)
                    if isfield(combinedMetadataStruct, 'StartSpeed')
                        T.StartSpeed = expandScalarToColumn(combinedMetadataStruct.StartSpeed, nRows);
                    else
                        T.StartSpeed = nan(nRows, 1);
                    end
                end

                %---------------------------------------------------------
                % 6) Enforce non-overwrite rules: never write these directly
                %---------------------------------------------------------
                protectedColumns = {'Repetition_ID', 'FileName'};

                for i = 1:numel(protectedColumns)
                    if isfield(combinedMetadataStruct, protectedColumns{i})
                        combinedMetadataStruct = rmfield(combinedMetadataStruct, protectedColumns{i});
                    end
                end

                %---------------------------------------------------------
                % 6b) When inferFromFilename is ON, also remove inferred fields
                %     from the GUI struct so they are NOT written by the GUI loop.
                %     They will be filled per-row from FileName in step 8b.
                %---------------------------------------------------------
                if inferFromFilename
                    for i = 1:numel(INFERRED_FIELDS)
                        fn = INFERRED_FIELDS{i};
                        if isfield(combinedMetadataStruct, fn)
                            combinedMetadataStruct = rmfield(combinedMetadataStruct, fn);
                        end
                    end
                end

                %---------------------------------------------------------
                % 6c) Se InferredTemplate è attivo, carica il template
                %     corrispondente ai primi 2 token del FileName per ogni riga
                %     e aggiorna combinedMetadataStruct riga per riga.
                %     In questo caso il loop GUI (step 7) viene saltato e
                %     la scrittura avviene qui direttamente.
                %---------------------------------------------------------
                inferTemplate = false;
                if isprop(app, 'InfereTemplateCheckBox') && ...
                        ~isempty(app.InfereTemplateCheckBox) && inferFromFilename
                    inferTemplate = app.InfereTemplateCheckBox.Value;
                end

                if inferTemplate
                    % Leggi la tabella dei template una volta sola
                    try
                        templateTable = readtable(app.DB_FilePath, ...
                            'Sheet', 'Metadata_Templates', ...
                            'ReadVariableNames', true, ...
                            'VariableNamingRule', 'preserve');
                    catch ME
                        warning('writeMetadata_ALL_DB_FromGUI: cannot read Metadata_Templates: %s', char(ME.message));
                        templateTable = [];
                    end
                    fprintf('Template names in DB:\n');
                    for tt = 1:height(templateTable)
                        nm = templateTable.IDName_Template{tt};
                        fprintf('  [%s] len=%d\n', nm, numel(nm));
                    end
                    disp(templateTable.Properties.VariableNames);
                    templateNameCol = 'IDName_Template';
                    if ~ismember(templateNameCol, templateTable.Properties.VariableNames)
                        % fallback: cerca una colonna che contenga "Name" nel nome
                        allCols = templateTable.Properties.VariableNames;
                        nameColIdx = find(contains(allCols, 'Name', 'IgnoreCase', true), 1);
                        if ~isempty(nameColIdx)
                            templateNameCol = allCols{nameColIdx};
                        else
                            templateNameCol = allCols{1};
                        end
                    end
                    fprintf('templateNameCol resolved to: "%s"\n', templateNameCol);
                end


                %---------------------------------------------------------
                % 7) Write metadata into every row
                %    - Se inferTemplate: carica template per riga da DB,
                %      poi scrivi quei valori (i campi inferiti da filename
                %      vengono comunque scritti dopo in step 8b)
                %    - Altrimenti: scrivi valori GUI flat su tutte le righe
                %---------------------------------------------------------
                if inferTemplate && ~isempty(templateTable)
                    % Colonne del template disponibili
                    templateCols = templateTable.Properties.VariableNames;

                    for row = 1:nRows
                        % Recupera filename della riga
                        rawFN = T.FileName(row);
                        if iscell(rawFN); rawFN = rawFN{1}; end
                        fnChar = char(rawFN);

                        % Determina nome template dai primi 2 token
                        if ~isempty(fnChar)
                            [~, baseNameFN, ~] = fileparts(fnChar);
                            tokensFN = strsplit(baseNameFN, '_');
                            if numel(tokensFN) >= 2
                                templateName = strjoin(tokensFN(1:2), '_');
                                fprintf('Row %d | FileName: %s | templateName: "%s"\n', row, fnChar, templateName);
                            else
                                templateName = baseNameFN;
                            end
                        else
                            templateName = '';
                        end

                        % Cerca il template nella tabella (colonna 'Name' o prima colonna)
                        templateNameCol = 'IDName_Template';
                        if ~ismember(templateNameCol, templateCols)
                            templateNameCol = templateCols{1};  % fallback: prima colonna
                        end

                        % Confronto case-insensitive, gestisce cell/string/numeric
                        nameColData = templateTable.(templateNameCol);

                        templateNameTrimmed = strtrim(templateName)
                        fprintf('Using templateNameCol: "%s"\n', templateNameCol);
                        disp(templateTable.Properties.VariableNames);
                        fprintf('Comparing "%s" (len=%d) against:\n', templateNameTrimmed, numel(templateNameTrimmed));
                        for tt = 1:height(templateTable)
                            val = templateTable.(templateNameCol)(tt);
                            if iscell(val); val = val{1}; end
                            nm = strtrim(char(val));
                            fprintf('  [%s] len=%d match=%d\n', nm, numel(nm), strcmpi(nm, templateNameTrimmed));
                        end

                        if iscell(nameColData)
                            matchIdx = find(strcmpi(nameColData, templateName), 1);
                        elseif isstring(nameColData)
                            matchIdx = find(strcmpi(nameColData, templateName), 1);
                        elseif isnumeric(nameColData)
                            templateNameNum = str2double(templateName);
                            if ~isnan(templateNameNum)
                                matchIdx = find(nameColData == templateNameNum, 1);
                            else
                                matchIdx = [];
                            end
                        else
                            matchIdx = [];
                        end

                        fprintf('Row %d | templateName: "%s" | matchIdx: %d\n', row, templateName, matchIdx);

                        if isempty(matchIdx)
                            warning('Row %d: no template found for "%s", using GUI values.', row, templateName);
                            rowStruct = combinedMetadataStruct;  % fallback su GUI
                        else
                            % Costruisci struct dalla riga del template
                            rowStruct = struct();
                            for c = 1:numel(templateCols)
                                cn = templateCols{c};
                                val = templateTable.(cn)(matchIdx);
                                if iscell(val); val = val{1}; end
                                rowStruct.(cn) = val;
                            end
                        end

                        % Scrivi i campi del template (o GUI fallback) nella riga
                        allFields = fieldnames(rowStruct);
                        for f = 1:numel(allFields)
                            fn = allFields{f};

                            % Mai toccare colonne protette
                            if any(strcmpi(fn, protectedColumns)); continue; end

                            % I campi inferiti da filename vengono scritti dopo in 8b
                            if inferFromFilename && any(strcmpi(fn, INFERRED_FIELDS)); continue; end

                            % Salta colonne proprie del template non rilevanti per il DB output
                            if any(strcmpi(fn, {'ID_Template', 'IDName_Template'})); continue; end

                            val = rowStruct.(fn);
                            if iscell(val); val = val{1}; end

                            % Converti data da YYYYMMDD o datetime a DD/MM/YYYY
                            if strcmpi(fn, 'Date')
                                if isdatetime(val)
                                    val = datestr(val, 'dd/mm/yyyy');
                                elseif ischar(val) || isstring(val)
                                    rawDate = strtrim(char(val));
                                    if numel(rawDate) == 8 && all(isstrprop(rawDate, 'digit'))
                                        % Formato YYYYMMDD
                                        val = [rawDate(7:8) '/' rawDate(5:6) '/' rawDate(1:4)];
                                    end
                                    % Se già in altro formato (es. dd/mm/yyyy) lascia invariato
                                end
                            end

                            % Assicura che la colonna esista in T (crea se mancante)
                            if ~ismember(fn, T.Properties.VariableNames)
                                % Determina tipo appropriato per la nuova colonna

                                if isnumeric(val)
                                    T.(fn) = nan(nRows, 1);
                                else
                                    T.(fn) = repmat(string(""), nRows, 1);
                                end
                            end

                            % Assegna il valore alla riga corrente
                            col = T.(fn);

                            if isnumeric(val)
                                if isnumeric(col)
                                    if isscalar(val)
                                        col(row) = val;
                                    elseif isempty(val)
                                        col(row) = NaN;
                                    else
                                        col(row) = val(1);
                                    end
                                else
                                    % Colonna stringa/cell ma valore numerico
                                    if iscell(col)
                                        col{row} = char(string(val));
                                    else
                                        col(row) = string(val);
                                    end
                                end
                            elseif ischar(val) || isstring(val)
                                strVal = char(string(val));
                                if iscell(col)
                                    col{row} = strVal;
                                elseif isnumeric(col)
                                    % Colonna numerica ma valore stringa: prova conversione
                                    numVal = str2double(strVal);
                                    if ~isnan(numVal)
                                        col(row) = numVal;
                                    end
                                    % se non convertibile lascia NaN esistente
                                else
                                    col(row) = string(strVal);
                                end
                            end

                            T.(fn) = col;
                        end
                    end

                else
                    % Modalità normale: scrivi valori GUI flat su tutte le righe
                    for i = 1:numel(guiMetaFieldOrder)
                        columnName = guiMetaFieldOrder{i};

                        if any(strcmpi(columnName, protectedColumns)); continue; end
                        if inferFromFilename && any(strcmpi(columnName, INFERRED_FIELDS)); continue; end
                        if ~isfield(combinedMetadataStruct, columnName); continue; end

                        valueToWrite = combinedMetadataStruct.(columnName);

                        if ~ismember(columnName, T.Properties.VariableNames)
                            T.(columnName) = expandScalarToColumn(valueToWrite, nRows);
                            continue;
                        end

                        if strcmpi(columnName, 'StartSpeed')
                            existingColumn = T.(columnName);
                            numericMask    = isNumericValueColumn(existingColumn);
                            fillColumn     = expandScalarToColumn(valueToWrite, nRows);
                            T.(columnName) = assignByMask(existingColumn, fillColumn, ~numericMask);
                        else
                            T.(columnName) = expandScalarToColumn(valueToWrite, nRows);
                        end
                    end
                end
                %---------------------------------------------------------
                % 8b) Infer metadata from FileName column, row by row
                %     Pattern: X_X_Date_X_DrivingMode_ManeuvreName_Driver_X.mf4
                %     Positions (1-based after split on '_'):
                %       3 -> Date
                %       5 -> DrivingMode
                %       6 -> ManeuvreName
                %       7 -> Driver
                %
                %     Behaviour:
                %       - Ensure all 4 inferred columns exist in T
                %       - For each row: parse FileName; if parsing succeeds, write
                %         the inferred values; if parsing fails (wrong pattern),
                %         leave the existing cell value untouched
                %---------------------------------------------------------
                if inferFromFilename
                    % Ensure all inferred columns exist (create empty if not present)
                    % Use exact DB column names
                    inferredColDate        = 'Date';
                    inferredColDrivingMode = 'Driving_Mode';   % <-- underscore
                    inferredColManeuvre    = 'ManeuvreName';   % <-- verify this matches your DB
                    inferredColDriver      = 'Driver';

                    allInferredCols = {inferredColDate, inferredColDrivingMode, inferredColManeuvre, inferredColDriver};

                    for i = 1:numel(allInferredCols)
                        fn = allInferredCols{i};
                        if ~ismember(fn, T.Properties.VariableNames)
                            T.(fn) = repmat(string(""), nRows, 1);
                        end
                    end

                    for row = 1:nRows
                        rawFileName = T.FileName(row);
                        if iscell(rawFileName);  rawFileName = rawFileName{1}; end
                        fileName = char(rawFileName);

                        if isempty(fileName); continue; end

                        [~, baseName, ~] = fileparts(fileName);
                        parts = strsplit(baseName, '_');

                        if numel(parts) < 7
                            warning('Row %d: "%s" has fewer than 7 tokens, skipping inference.', row, baseName);
                            continue;
                        end

                        % Converti data da YYYYMMDD a DD/MM/YYYY
                        rawDate = char(parts{3});
                        if numel(rawDate) == 8 && all(isstrprop(rawDate, 'digit'))
                            formattedDate = [rawDate(7:8) '/' rawDate(5:6) '/' rawDate(1:4)];
                        else
                            formattedDate = rawDate;
                        end

                        inferredValues = {string(formattedDate), string(parts{5}), string(parts{6}), string(parts{7})};
                        inferredCols   = {inferredColDate, inferredColDrivingMode, inferredColManeuvre, inferredColDriver};

                        for c = 1:4
                            colName = inferredCols{c};
                            val     = inferredValues{c};
                            col     = T.(colName);

                            if iscell(col)
                                col{row}  = char(val);
                            else
                                col(row)  = val;
                            end

                            T.(colName) = col;
                        end
                    end
                end


                %---------------------------------------------------------
                % 9) Reorder columns:
                %    Repetition_ID, FileName, StartSpeed, GUI_METADATA...,
                %    ALL_OTHER_METADATA..., KPI_COLUMNS...
                %---------------------------------------------------------
                mandatoryOrder = {'Repetition_ID', 'FileName', 'StartSpeed'};

                % GUI metadata order, excluding mandatory columns
                guiMetaOrderClean = {};
                for i = 1:numel(guiMetaFieldOrder)
                    nm = guiMetaFieldOrder{i};
                    if any(strcmpi(nm, mandatoryOrder)); continue; end
                    if ismember(nm, T.Properties.VariableNames) && ~any(strcmp(guiMetaOrderClean, nm))
                        guiMetaOrderClean{end+1} = nm; %#ok<AGROW>
                    end
                end

                % Add inferred columns if not already present
                if inferFromFilename
                    for i = 1:numel(INFERRED_FIELDS)
                        fn = INFERRED_FIELDS{i};
                        if ismember(fn, T.Properties.VariableNames) && ...
                                ~any(strcmp(guiMetaOrderClean, fn)) && ...
                                ~any(strcmpi(fn, mandatoryOrder))
                            guiMetaOrderClean{end+1} = fn; %#ok<AGROW>
                        end
                    end
                end

                % Columns already placed
                alreadyPlaced = [mandatoryOrder, guiMetaOrderClean];

                % Remaining columns: split into metadata vs KPI
                % Metadata columns = all columns that appear in the template table
                % or in the known metadata column list from app
                currentCols = T.Properties.VariableNames;
                remainingCols = currentCols(~ismember(currentCols, alreadyPlaced));

                % Get known metadata column names from app (if available)
                if isprop(app, 'getAllMetadataColumnNames') || ismethod(app, 'getAllMetadataColumnNames')
                    try
                        knownMetaCols = app.getAllMetadataColumnNames();
                    catch
                        knownMetaCols = {};
                    end
                else
                    knownMetaCols = {};
                end

                % Also treat template columns as metadata
                if inferTemplate && ~isempty(templateTable)
                    templateMetaCols = templateTable.Properties.VariableNames;
                    templateMetaCols = setdiff(templateMetaCols, {'ID_Template','IDName_Template'}, 'stable');
                    knownMetaCols = union(knownMetaCols, templateMetaCols, 'stable');
                end

                % Split remaining into metadata (left) and KPI (right)
                remainingMetaCols = remainingCols(ismember(remainingCols, knownMetaCols));
                remainingKpiCols  = remainingCols(~ismember(remainingCols, knownMetaCols));

                finalOrder = [mandatoryOrder, guiMetaOrderClean, remainingMetaCols, remainingKpiCols];
                T = T(:, finalOrder);

                %---------------------------------------------------------
                % 10) Write updated table back into the COPIED Excel file
                %---------------------------------------------------------
                try
                    writetable(T, outputFilePath, ...
                        'Sheet', dataSheetName, ...
                        'WriteVariableNames', true, ...
                        'WriteMode', 'overwritesheet');
                catch
                    delete(outputFilePath);
                    writetable(T, outputFilePath, 'WriteVariableNames', true);
                end

                %=========================================================
                % Local helper functions
                %=========================================================
                function columnVector = expandScalarToColumn(inputValue, rowCount)
                    % Handle empty input of any type -> blank string column
                    if isempty(inputValue)
                        columnVector = repmat(string(""), rowCount, 1);
                        return;
                    end

                    if isnumeric(inputValue) || islogical(inputValue)
                        if ~isscalar(inputValue)
                            inputValue = inputValue(1);
                        end
                        columnVector = repmat(inputValue, rowCount, 1);
                        return;
                    end

                    if ischar(inputValue)
                        columnVector = repmat(string(inputValue), rowCount, 1);
                        return;
                    end

                    if isstring(inputValue)
                        if isscalar(inputValue)
                            columnVector = repmat(inputValue, rowCount, 1);
                        elseif numel(inputValue) == rowCount
                            columnVector = inputValue(:);
                        else
                            columnVector = repmat(inputValue(1), rowCount, 1);
                        end
                        return;
                    end

                    if iscell(inputValue)
                        if isscalar(inputValue)
                            columnVector = repmat(inputValue, rowCount, 1);
                        elseif numel(inputValue) == rowCount
                            columnVector = inputValue(:);
                        else
                            columnVector = repmat(inputValue(1), rowCount, 1);
                        end
                        return;
                    end

                    % Fallback
                    try
                        columnVector = repmat(string(inputValue), rowCount, 1);
                    catch
                        columnVector = repmat(string(""), rowCount, 1);
                    end
                end
                function outColumn = assignByMask(outColumn, inColumn, mask)
                    if iscell(outColumn) || iscell(inColumn)
                        if ~iscell(outColumn)
                            if isstring(outColumn); outColumn = cellstr(outColumn);
                            else; outColumn = num2cell(outColumn); end
                        end
                        if ~iscell(inColumn)
                            if isstring(inColumn); inColumn = cellstr(inColumn);
                            else; inColumn = num2cell(inColumn); end
                        end
                        outColumn(mask) = inColumn(mask);
                    else
                        outColumn(mask) = inColumn(mask);
                    end
                end

                function numericMask = isNumericValueColumn(columnData)
                    if isnumeric(columnData)
                        numericMask = ~isnan(columnData);
                        return;
                    end
                    if isstring(columnData)
                        numericMask = ~isnan(str2double(columnData));
                        return;
                    end
                    if iscell(columnData)
                        numericMask = false(size(columnData, 1), 1);
                        for r = 1:size(columnData, 1)
                            v = columnData{r};
                            if isnumeric(v) && isscalar(v) && ~isnan(v)
                                numericMask(r) = true;
                            elseif ischar(v) || (isstring(v) && isscalar(v))
                                numericMask(r) = ~isnan(str2double(string(v)));
                            else
                                numericMask(r) = false;
                            end
                        end
                        return;
                    end
                    numericMask = false(size(columnData, 1), 1);
                end


            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WriteMetadatatonewIDButton
        function WriteMetadatatonewIDButtonPushed(app, event)

            %------------------------------------------------------------------
            % Write GUI metadata into the Excel DB, adding EXACTLY one row
            % to the Excel TABLE (ListObject) on sheet "Metadata".
            %
            % Steps:
            %   1) Check DB connection.
            %   2) Read "Metadata" sheet as table and compute next ID:
            %        nextID = max(ID) + 1  (or 1 if empty)
            %   3) Build combined struct from GUI (GeneralMetadataMap + ICMetadataMap).
            %   4) Force combinedRowStruct.ID = nextID.
            %   5) Append new row to the Excel ListObject (table) via COM.
            %   6) Update GUI "ID available" field.
            %------------------------------------------------------------------

            %-------------------------
            % 1) Check DB connection
            %-------------------------
            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    'DB is not connected. Please connect to DB first.', ...
                    'DB not connected');
                return;
            end

            %--------------------------------------
            % 2) Read Metadata to compute next ID
            %--------------------------------------
            try
                % Use 'modify' so that variable names are valid MATLAB identifiers
                metadataTable = readtable(app.DB_FilePath, ...
                    'Sheet', 'Metadata', ...
                    'ReadVariableNames', true, ...
                    'VariableNamingRule', 'modify');
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "Metadata":\n%s', readError.message), ...
                    'Read error');
                return;
            end

            idColumnName = 'ID';  % Column name in the MATLAB table (must be valid)

            % Compute nextAvailableId
            if isempty(metadataTable) || ~ismember(idColumnName, metadataTable.Properties.VariableNames)
                nextAvailableId = 1;   % No rows or no ID -> start at 1
            else
                idColumnData = metadataTable.(idColumnName);

                if isnumeric(idColumnData)
                    validIdMask = ~isnan(idColumnData);
                    validIds    = idColumnData(validIdMask);

                    if isempty(validIds)
                        nextAvailableId = 1;
                    else
                        nextAvailableId = max(validIds) + 1;
                    end
                else
                    numericIds  = str2double(string(idColumnData));
                    validIdMask = ~isnan(numericIds);
                    validIds    = numericIds(validIdMask);

                    if isempty(validIds)
                        nextAvailableId = 1;
                    else
                        nextAvailableId = max(validIds) + 1;
                    end
                end
            end

            %---------------------------------------------
            % 3) Build combined struct from GUI mappings
            %---------------------------------------------
            generalRowStruct = app.buildRowFromMapping(app.GeneralMetadataMap);
            icRowStruct      = app.buildRowFromMapping(app.ICMetadataMap);

            % Manual merge: combinedRowStruct = general ∪ ic
            combinedRowStruct = generalRowStruct;
            icFields = fieldnames(icRowStruct);
            for k = 1:numel(icFields)
                fieldName = icFields{k};
                combinedRowStruct.(fieldName) = icRowStruct.(fieldName);
            end

            %---------------------------------------------
            % 4) Force ID field to nextAvailableId
            %---------------------------------------------
            combinedRowStruct.(idColumnName) = nextAvailableId;

            % Optional: update GUI field used as "New ID available"
            % If you use IDEditField for this:
            app.IDEditField.Value = nextAvailableId;
            % Otherwise, replace with your specific component:
            % app.NewIDAvailableEditField.Value = nextAvailableId;

            %---------------------------------------------
            % 5) Write struct as new row in Excel TABLE
            %---------------------------------------------
            try
                % Name of the sheet and table in Excel
                excelSheetName   = 'Metadata';       % sheet where the table lives
                excelTableName   = 'TableMet';  % <<< ADAPT THIS to your actual Excel table name

                app.appendStructAsExcelTableRow( ...
                    combinedRowStruct, ...
                    excelSheetName, ...
                    excelTableName);

            catch writeError
                uialert(app.UIFigure, ...
                    sprintf(['Error while writing new Metadata row into Excel table:\n' ...
                    '%s'], writeError.message), ...
                    'DB write error');
                return;
            end

            uialert(app.UIFigure, ...
                sprintf('Metadata written for ID = %d in Excel table.', nextAvailableId), ...
                'Write complete', 'Icon', 'success');

        end

        % Size changed function: ConnectLoadWriteTab
        function ConnectLoadWriteTabSizeChanged(app, event)
            position = app.ConnectLoadWriteTab.Position;

        end

        % Button pushed function: ConnecttoDBButton
        function ConnecttoDBButtonPushed(app, event)


            initializeDbMappings(app)

            %----------------------------------------------------------
            % Let user choose an Excel file that will act as the "DB"
            %----------------------------------------------------------
            [fileName, fileFolder] = uigetfile( ...
                {'*.xlsx;*.xls', 'Excel files (*.xlsx, *.xls)'}, ...
                'Select DB Excel file');

            % If user presses Cancel, uigetfile returns 0
            if isequal(fileName, 0)
                % Do nothing, keep previous DB state
                return;
            end

            % Build full path to the selected Excel file
            fullDbPath = fullfile(fileFolder, fileName);

            [~, shortPath] = system(['for %I in ("' fullDbPath '") do @echo %~sI']);
            shortPath = strtrim(shortPath);
            disp(shortPath); % verifica come appare
            fullDbPath =shortPath;

            %----------------------------------------------------------
            % Read sheet names from the Excel file
            %----------------------------------------------------------
            try

                if ~isfile(fullDbPath)
                    error('File non trovato o non sincronizzato: %s', fullDbPath);
                end

                % xlsfinfo returns information about Excel file
                % [~, sheetNames] is usually enough
                [~, sheetNames] = xlsfinfo(fullDbPath);

                % If xlsfinfo returns empty, something is wrong
                if isempty(sheetNames)
                    error('NoExcelSheetsFound:DB', ...
                        'No sheets were found in the selected Excel file.');
                end

            catch ME
                % Show error to user and reset DB state
                uialert(app.UIFigure, ...
                    sprintf('Cannot read Excel file:\n%s', ME.message), ...
                    'DB connection error');

                app.DB_Connected = false;
                app.DB_FilePath  = '';
                return;
            end

            %----------------------------------------------------------
            % Check that required sheets "Metadata" and "KPIs" exist
            %----------------------------------------------------------
            requiredSheets = {'Metadata', 'KPIs'};
            hasRequiredSheets = all(ismember(requiredSheets, sheetNames));

            if ~hasRequiredSheets
                % Find which required sheets are missing
                missingSheets = requiredSheets(~ismember(requiredSheets, sheetNames));

                % Build an informative error message for the user
                errorMessage = sprintf( ...
                    ['Selected file does NOT contain the required sheets:\n' ...
                    '%s\n\nAvailable sheets are:\n%s'], ...
                    strjoin(missingSheets, ', '), ...
                    strjoin(sheetNames, ', '));

                % Show error dialog
                uialert(app.UIFigure, errorMessage, 'DB schema error');

                % Reset DB state
                app.DB_Connected = false;
                app.DB_FilePath  = '';
                return;
            end

            %----------------------------------------------------------
            % If we are here, the Excel file is valid as "DB"
            %----------------------------------------------------------
            app.DB_Connected = true;          % flag = 1
            app.DB_FilePath  = fullDbPath;    % save Excel path

            [dbValid, dbErrorMsg] = app.validateDBConsistency();
            if ~dbValid
                app.DB_Connected = false;          % flag = 1
                app.DB_FilePath  = '';    % save Excel path
                % set status light to Red
                app.StatusLamp.Color = '1.00,0.00,0.00';
                app.DBPathTextArea.Value = app.DB_FilePath;
                uialert(app.UIFigure, dbErrorMsg, "DB non consistente");
                return;
            end

            % Optional feedback to the user
            uialert(app.UIFigure, ...
                sprintf('DB connected successfully:\n%s', fullDbPath), ...
                'DB connected', 'Icon', 'success');

            % set status light to Green
            app.StatusLamp.Color = '0.00,1.00,0.00';
            app.DBPathTextArea.Value = app.DB_FilePath;
            % update KPIs dropdown
            refreshKPIsDropDown(app);

            %----------------------------------------------------------
            % update ID available: take max(ID) from sheet "Metadata"
            % and set GUI field to maxID + 1
            %----------------------------------------------------------
            try
                % Read Metadata sheet, preserving exact header names
                metadataTable = readtable(app.DB_FilePath, ...
                    'Sheet', 'Metadata', ...
                    'ReadVariableNames', true, ...
                    'VariableNamingRule', 'preserve');  % R2020a+

                % Name of ID column in the DB
                idColumnName = 'ID';

                if ~ismember(idColumnName, metadataTable.Properties.VariableNames)
                    % If the ID column does not exist, we cannot update ID available
                    warning('ConnecttoDBButtonPushed:IDColumnMissing', ...
                        'Column "%s" not found in Metadata sheet. ID available not updated.', ...
                        idColumnName);
                else
                    idColumnData = metadataTable.(idColumnName);

                    % Keep only numeric values (in case of NaN / non numeric)
                    if isnumeric(idColumnData)
                        validIdMask = ~isnan(idColumnData);
                        validIds    = idColumnData(validIdMask);

                        if isempty(validIds)
                            % No valid IDs yet -> start from 1
                            nextAvailableId = 1;
                        else
                            % Next ID is max + 1
                            nextAvailableId = max(validIds) + 1;
                        end
                    else
                        % If ID column is not numeric, try to convert to double
                        numericIds = str2double(string(idColumnData));
                        validIdMask = ~isnan(numericIds);
                        validIds    = numericIds(validIdMask);

                        if isempty(validIds)
                            nextAvailableId = 1;
                        else
                            nextAvailableId = max(validIds) + 1;
                        end
                    end

                    %--------------------------------------------------
                    % Assign the nextAvailableId to your GUI field
                    %--------------------------------------------------
                    % Adjust this line depending on where you want
                    % to show the "ID available".
                    % Example: use IDEditField as "next ID" field:
                    app.IDEditField.Value = nextAvailableId;

                    % update ID available
                    app.NewIDavailableEditField.Value = nextAvailableId;
                    % turn off ID editing
                    app.NewIDavailableEditField.Enable = 'Off';
                end

            catch ME
                % If anything goes wrong while reading Metadata or IDs,
                % do not block the DB connection, just warn.
                warning('ConnecttoDBButtonPushed:UpdateIDFailed', ...
                    'Failed to update ID available: %s', ME.message);
            end


        end

        % Button pushed function: ShowMetadataButton
        function ShowMetadataButtonPushed(app, event)


            %--------------------------------------------------------------
            % Show the "Metadata" Excel sheet in a separate resizable UI
            % figure with a sortable UITable and simple text filtering.
            %--------------------------------------------------------------

            % 1) Check DB connection state
            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    'DB is not connected. Please connect to DB first.', ...
                    'DB not connected');
                return;
            end

            % 2) Read "Metadata" sheet into a table
            try
                metadataTable = readtable(app.DB_FilePath, 'Sheet', 'Metadata');
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "Metadata":\n%s', readError.message), ...
                    'Read error');
                return;
            end

            % 3) Handle empty sheet
            if isempty(metadataTable)
                uialert(app.UIFigure, ...
                    'The "Metadata" sheet is empty.', ...
                    'Empty sheet');
                return;
            end

            %--------------------------------------------------------------
            % 4) Create a separate UI figure to host the UITable
            %--------------------------------------------------------------
            metadataFigure = uifigure( ...
                'Name', 'Metadata Viewer', ...
                'Position', [100 100 900 500]);  % [left bottom width height]

            %--------------------------------------------------------------
            % 5) Create the UITable that displays the full metadata table
            %--------------------------------------------------------------
            metadataUITable = uitable(metadataFigure, ...
                'Data', metadataTable, ...
                'Position', [20 70 860 410]);  % leave space on top for filters

            % Set column names from table variable names
            metadataUITable.ColumnName = metadataTable.Properties.VariableNames;

            % Auto-size column widths
            metadataUITable.ColumnWidth = 'auto';

            % Enable sorting on all columns (if supported by your MATLAB)
            try
                metadataUITable.ColumnSortable = true;
            catch
                % Older MATLAB versions may not support ColumnSortable.
                % In that case just ignore this option.
            end

            %--------------------------------------------------------------
            % 6) Store original table and uitable handle in figure UserData
            %--------------------------------------------------------------
            metadataFigure.UserData = struct( ...
                'OriginalTable', metadataTable, ...
                'UITable',       metadataUITable);

            %--------------------------------------------------------------
            % 7) Filter UI: dropdown + text field + buttons
            %--------------------------------------------------------------

            % Dropdown to choose which column to filter
            filterColumnDropDown = uidropdown(metadataFigure, ...
                'Items', metadataTable.Properties.VariableNames, ...
                'Position', [20 20 150 22], ...
                'Value', metadataTable.Properties.VariableNames{1});

            % Text field for filter pattern
            filterTextField = uieditfield(metadataFigure, 'text', ...
                'Placeholder', 'Filter text (contains)', ...
                'Position', [180 20 200 22]);

            % "Apply Filter" button
            applyFilterButton = uibutton(metadataFigure, 'push', ...
                'Text', 'Apply Filter', ...
                'Position', [390 20 100 22], ...
                'ButtonPushedFcn', @(src, evt)app.applyMetadataFilter( ...
                metadataFigure, ...
                filterColumnDropDown, ...
                filterTextField));

            % "Clear Filter" button
            clearFilterButton = uibutton(metadataFigure, 'push', ...
                'Text', 'Clear Filter', ...
                'Position', [500 20 100 22], ...
                'ButtonPushedFcn', @(src, evt)app.clearMetadataFilter(metadataFigure))


        end

        % Button pushed function: ShowKPIsButton
        function ShowKPIsButtonPushed(app, event)
            %--------------------------------------------------------------
            % Show the "KPIs" Excel sheet in a separate resizable UI figure
            % with a sortable UITable and simple text filtering.
            %
            % The design is identical to ShowMetadataButtonPushed, but it
            % reads sheet "KPIs".
            %--------------------------------------------------------------

            % 1) Check DB connection
            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    'DB is not connected. Please connect to DB first.', ...
                    'DB not connected');
                return;
            end

            % 2) Read KPIs sheet from Excel DB
            try
                kpiTable = readtable(app.DB_FilePath, 'Sheet', 'KPIs');
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "KPIs":\n%s', readError.message), ...
                    'Read error');
                return;
            end

            % 3) Handle empty sheet
            if isempty(kpiTable)
                uialert(app.UIFigure, ...
                    'The "KPIs" sheet is empty.', ...
                    'Empty sheet');
                return;
            end

            %--------------------------------------------------------------
            % 4) Create separate, resizable UI figure
            %--------------------------------------------------------------
            kpiFigure = uifigure( ...
                'Name', 'KPIs Viewer', ...
                'Position', [150 120 950 520]);

            %--------------------------------------------------------------
            % 5) Create UITable to display KPIs
            %--------------------------------------------------------------
            kpiUITable = uitable(kpiFigure, ...
                'Data', kpiTable, ...
                'Position', [20 70 910 430]);

            kpiUITable.ColumnName  = kpiTable.Properties.VariableNames;
            kpiUITable.ColumnWidth = 'auto';

            % Enable sorting if supported
            try
                kpiUITable.ColumnSortable = true;
            catch
                % Ignore if MATLAB version does not support it
            end

            %--------------------------------------------------------------
            % 6) Store original table + UI handle in figure UserData
            %--------------------------------------------------------------
            kpiFigure.UserData = struct( ...
                'OriginalTable', kpiTable, ...
                'UITable',       kpiUITable);

            %--------------------------------------------------------------
            % 7) Filtering UI (dropdown + edit field + buttons)
            %--------------------------------------------------------------
            filterColumnDropDown = uidropdown(kpiFigure, ...
                'Items', kpiTable.Properties.VariableNames, ...
                'Position', [20 20 150 22], ...
                'Value', kpiTable.Properties.VariableNames{1});

            filterTextField = uieditfield(kpiFigure, 'text', ...
                'Placeholder', 'Filter text (contains)', ...
                'Position', [180 20 200 22]);

            % Apply Filter
            uibutton(kpiFigure, 'push', ...
                'Text', 'Apply Filter', ...
                'Position', [390 20 100 22], ...
                'ButtonPushedFcn', @(src,evt)app.applyKPIsFilter( ...
                kpiFigure, ...
                filterColumnDropDown, ...
                filterTextField));

            % Clear Filter
            uibutton(kpiFigure, 'push', ...
                'Text', 'Clear Filter', ...
                'Position', [500 20 100 22], ...
                'ButtonPushedFcn', @(src,evt)app.clearKPIsFilter(kpiFigure));
        end

        % Button pushed function: ShowMetatadaKPIsDBButton
        function ShowMetatadaKPIsDBButtonPushed(app, event)

            %--------------------------------------------------------------
            % Open a separate resizable figure with two tabs:
            %   - "Metadata": shows Excel sheet "Metadata"
            %   - "KPIs"    : shows Excel sheet "KPIs"
            %
            % Each tab contains:
            %   - A UITable with full data
            %   - A simple "contains" text filter (column + text)
            %
            % Requirements:
            %   - app.DB_Connected == true
            %   - app.DB_FilePath points to an Excel file
            %   - The Excel file has sheets "Metadata" and "KPIs"
            %--------------------------------------------------------------

            % 1) Check DB connection
            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    'DB is not connected. Please connect to DB first.', ...
                    'DB not connected');
                return;
            end

            % 2) Read both sheets: Metadata and KPIs
            try
                metadataTable = readtable(app.DB_FilePath, 'Sheet', 'Metadata');
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "Metadata":\n%s', readError.message), ...
                    'Read error');
                return;
            end

            try
                kpiTable = readtable(app.DB_FilePath, 'Sheet', 'KPIs');
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "KPIs":\n%s', readError.message), ...
                    'Read error');
                return;
            end

            % Handle empty sheets (independently)
            if isempty(metadataTable)
                uialert(app.UIFigure, ...
                    'The "Metadata" sheet is empty.', ...
                    'Empty Metadata sheet');
                % You can decide to return here or still open the viewer only with KPIs.
                % For now, we continue and show an empty table.
            end

            if isempty(kpiTable)
                uialert(app.UIFigure, ...
                    'The "KPIs" sheet is empty.', ...
                    'Empty KPIs sheet');
                % Same idea as above.
            end

            %--------------------------------------------------------------
            % 3) Create the main combined viewer figure
            %--------------------------------------------------------------
            combinedFigure = uifigure( ...
                'Name', 'Metadata & KPIs Viewer', ...
                'Position', [120 120 1000 550]);  % [left bottom width height]

            % Create a TabGroup to host the two tabs
            combinedTabGroup = uitabgroup(combinedFigure, ...
                'Position', [1 1 1000 550]);

            % Create "Metadata" tab
            metadataTab = uitab(combinedTabGroup, ...
                'Title', 'Metadata');

            % Create "KPIs" tab
            kpiTab = uitab(combinedTabGroup, ...
                'Title', 'KPIs');

            %--------------------------------------------------------------
            % 4) METADATA TAB UI
            %--------------------------------------------------------------
            % Create UITable in Metadata tab
            metadataUITable = uitable(metadataTab, ...
                'Data', metadataTable, ...
                'Position', [20 70 950 430]);   % leave space at bottom for filters

            metadataUITable.ColumnName  = metadataTable.Properties.VariableNames;
            metadataUITable.ColumnWidth = 'auto';

            % Enable sorting if supported
            try
                metadataUITable.ColumnSortable = true;
            catch
                % Ignore if MATLAB version does not support it
            end

            % Filter controls for Metadata
            metadataFilterColumnDropDown = uidropdown(metadataTab, ...
                'Items', metadataTable.Properties.VariableNames, ...
                'Position', [20 20 150 22], ...
                'Value', metadataTable.Properties.VariableNames{1});

            metadataFilterTextField = uieditfield(metadataTab, 'text', ...
                'Placeholder', 'Filter text (contains)', ...
                'Position', [180 20 200 22]);

            uibutton(metadataTab, 'push', ...
                'Text', 'Apply Filter', ...
                'Position', [390 20 100 22], ...
                'ButtonPushedFcn', @(src,evt)app.applyMetadataKPIsFilter( ...
                combinedFigure, ...
                'Metadata', ...
                metadataFilterColumnDropDown, ...
                metadataFilterTextField));

            uibutton(metadataTab, 'push', ...
                'Text', 'Clear Filter', ...
                'Position', [500 20 100 22], ...
                'ButtonPushedFcn', @(src,evt)app.clearMetadataKPIsFilter( ...
                combinedFigure, ...
                'Metadata'));

            %--------------------------------------------------------------
            % 5) KPIs TAB UI
            %--------------------------------------------------------------
            % Create UITable in KPIs tab
            kpiUITable = uitable(kpiTab, ...
                'Data', kpiTable, ...
                'Position', [20 70 950 430]);

            kpiUITable.ColumnName  = kpiTable.Properties.VariableNames;
            kpiUITable.ColumnWidth = 'auto';

            % Enable sorting if supported
            try
                kpiUITable.ColumnSortable = true;
            catch
                % Ignore if not supported
            end

            % Filter controls for KPIs
            kpiFilterColumnDropDown = uidropdown(kpiTab, ...
                'Items', kpiTable.Properties.VariableNames, ...
                'Position', [20 20 150 22], ...
                'Value', kpiTable.Properties.VariableNames{1});

            kpiFilterTextField = uieditfield(kpiTab, 'text', ...
                'Placeholder', 'Filter text (contains)', ...
                'Position', [180 20 200 22]);

            uibutton(kpiTab, 'push', ...
                'Text', 'Apply Filter', ...
                'Position', [390 20 100 22], ...
                'ButtonPushedFcn', @(src,evt)app.applyMetadataKPIsFilter( ...
                combinedFigure, ...
                'KPIs', ...
                kpiFilterColumnDropDown, ...
                kpiFilterTextField));

            uibutton(kpiTab, 'push', ...
                'Text', 'Clear Filter', ...
                'Position', [500 20 100 22], ...
                'ButtonPushedFcn', @(src,evt)app.clearMetadataKPIsFilter( ...
                combinedFigure, ...
                'KPIs'));

            %--------------------------------------------------------------
            % 6) Store both tables in combinedFigure.UserData
            %    to support filtering for each tab separately
            %--------------------------------------------------------------
            combinedFigure.UserData = struct( ...
                'Metadata', struct( ...
                'OriginalTable', metadataTable, ...
                'UITable',       metadataUITable), ...
                'KPIs', struct( ...
                'OriginalTable', kpiTable, ...
                'UITable',       kpiUITable));

        end

        % Button pushed function: 
        % LoadMetadatainGUIfromIDRowofMetadataDBButton
        function LoadMetadatainGUIfromIDRowofMetadataDBButtonPushed(app, event)

            % populate maps
            initializeDbMappings(app)

            %------------------------------------------------------------------
            % Load metadata from the Excel DB into the GUI, based on the ID
            % specified in app.IDtoloadEditField.
            %
            % Steps:
            %   1) Check DB_Connected and DB_FilePath
            %   2) Read 'Metadata' sheet as table
            %   3) Find row where column 'ID' == ID_to_load
            %   4) Apply that row to GUI using GeneralMetadataMap and ICMetadataMap
            %------------------------------------------------------------------

            % 1) Check DB connection state
            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    'DB is not connected. Please connect to DB first.', ...
                    'DB not connected');
                return;
            end

            % 2) Read the Metadata sheet from Excel
            try
                metadataTable = readtable(app.DB_FilePath, 'Sheet', 'Metadata', ...
                    'ReadVariableNames', true, ...
                    'VariableNamingRule', 'preserve');
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "Metadata":\n%s', readError.message), ...
                    'Read error');
                return;
            end

            if isempty(metadataTable)
                uialert(app.UIFigure, ...
                    'The "Metadata" sheet is empty. Nothing to load.', ...
                    'Empty sheet');
                return;
            end

            % 3) Read the ID to be loaded from GUI
            %    Assume you have a component: app.IDtoloadEditField
            idToLoadValue = app.IDtoloadEditField.Value;  % could be numeric or text

            % Name of the ID column in the DB (from your mapping: 'ID')
            idColumnName = 'ID';

            if ~ismember(idColumnName, metadataTable.Properties.VariableNames)
                uialert(app.UIFigure, ...
                    sprintf('Column "%s" not found in Metadata sheet.', idColumnName), ...
                    'Schema error');
                return;
            end

            idColumnData = metadataTable.(idColumnName);

            % Build mask depending on type of ID column
            if isnumeric(idColumnData) || islogical(idColumnData)
                % Convert GUI value to numeric if needed
                if ischar(idToLoadValue) || isstring(idToLoadValue)
                    idToLoadNumeric = str2double(string(idToLoadValue));
                else
                    idToLoadNumeric = idToLoadValue;
                end

                if isnan(idToLoadNumeric)
                    uialert(app.UIFigure, ...
                        'ID to load is not a valid numeric value.', ...
                        'Invalid ID');
                    return;
                end

                rowMask = (idColumnData == idToLoadNumeric);
            else
                % Treat ID as string
                rowMask = strcmp(string(idColumnData), string(idToLoadValue));
            end

            matchingIndices = find(rowMask);

            % 4) Handle number of matches
            if isempty(matchingIndices)
                uialert(app.UIFigure, ...
                    sprintf('No metadata found for ID = %s.', string(idToLoadValue)), ...
                    'ID not found');
                return;

            elseif numel(matchingIndices) > 1
                % You can change this policy: here we load the first and warn
                warningMessage = sprintf( ...
                    'Multiple rows found for ID = %s. Loading the first one (row %d).', ...
                    string(idToLoadValue), matchingIndices(1));
                uialert(app.UIFigure, warningMessage, 'Multiple matches');
            end

            rowIndexToLoad = matchingIndices(1);
            rowToLoad = metadataTable(rowIndexToLoad, :);  % 1xN table

            % 5) Apply this row to the GUI using the mappings
            try
                app.applyRowToGUI(rowToLoad, app.GeneralMetadataMap);
                app.applyRowToGUI(rowToLoad, app.ICMetadataMap);
            catch applyError
                uialert(app.UIFigure, ...
                    sprintf('Error while applying metadata to GUI:\n%s', applyError.message), ...
                    'Apply error');
                return;
            end

            % update ID
            updateID(app)
            uialert(app.UIFigure, ...
                sprintf('Metadata for ID = %s loaded into GUI.', string(idToLoadValue)), ...
                'Load complete', 'Icon', 'success');

        end

        % Value changed function: SelectKPItobeAddedDropDown
        function SelectKPItobeAddedDropDownValueChanged(app, event)
            value = app.SelectKPItobeAddedDropDown.Value;

        end

        % Button pushed function: RefreshlistfromconnectedDBButton
        function RefreshlistfromconnectedDBButtonPushed(app, event)
            refreshKPIsDropDown(app)
        end

        % Button pushed function: AddKPIValueButton
        function AddKPIValueButtonPushed(app, event)

            %------------------------------------------------------------------
            % Add a single KPI/value pair to the temporary KPIsToAddTable.
            %
            % INPUT:
            %   - KPI name is taken from:
            %         app.SelectKPItobeAddedDropDown.Value
            %   - KPI value is taken from:
            %         app.InsertKPIvalueEditField.Value
            %
            % BEHAVIOR:
            %   - If KPIsToAddTable is empty:
            %       create a 1-row table with one column (this KPI).
            %   - If KPIsToAddTable already exists:
            %       * If the KPI column exists: overwrite the value in row 1.
            %       * If the KPI column does NOT exist: add a new column
            %         with this KPI name and assign the value in row 1.
            %
            % RESULT:
            %   - app.KPIsToAddTable always has exactly 1 row.
            %   - Each KPI corresponds to one column.
            %   - Can be reused by other functions that will write KPIs to DB.
            %------------------------------------------------------------------

            % 1) Read KPI name from dropdown
            selectedKpiName = app.SelectKPItobeAddedDropDown.Value;

            if isempty(selectedKpiName)
                uialert(app.UIFigure, ...
                    'No KPI selected. Please select a KPI from the dropdown.', ...
                    'KPI not selected');
                return;
            end

            % 2) Read KPI value from the edit field
            kpiValue = app.InsertKPIvalueEditField.Value;

            % Note:
            %   - If InsertKPIvalueEditField is NumericEditField -> kpiValue is double.
            %   - If EditField (text) -> kpiValue is char/string.
            %   - We accept both; table can hold mixed types per column.

            % 3) Initialize table if empty
            if isempty(app.KPIsToAddTable) || height(app.KPIsToAddTable) == 0
                %--------------------------------------------------------------
                % Create a new 1-row table with one variable "selectedKpiName"
                %--------------------------------------------------------------
                % Build a table from a scalar value:
                tempStruct = struct();
                % Use the KPI name as field name
                tempStruct.(selectedKpiName) = kpiValue;

                app.KPIsToAddTable = struct2table(tempStruct, 'AsArray', true);

            else
                %--------------------------------------------------------------
                % Table already exists; ensure it has exactly one row
                %--------------------------------------------------------------
                if height(app.KPIsToAddTable) ~= 1
                    % For safety, force it to one row (could be adapted if ever needed)
                    error('KPIsToAddTable is expected to have exactly one row.');
                end

                % Check if this KPI column already exists
                existingVarNames = app.KPIsToAddTable.Properties.VariableNames;

                if ismember(selectedKpiName, existingVarNames)
                    %--------------------------------------
                    % Overwrite existing KPI value
                    %--------------------------------------
                    app.KPIsToAddTable{1, selectedKpiName} = {kpiValue};

                    % Note:
                    % - Using curly braces {} ensures proper assignment even if type is not double.
                    % - If column is numeric and kpiValue is numeric, using () with numeric assignment
                    %   also works:
                    %   app.KPIsToAddTable{1, selectedKpiName} = kpiValue;

                else
                    %--------------------------------------
                    % Add new KPI column with this value
                    %--------------------------------------
                    % Approach:
                    %   - Add a new variable to the table
                    %   - Fill its single row with kpiValue

                    % Create a cell array with 1 element for the new column
                    newColumnData = {kpiValue};

                    % Add as new variable
                    app.KPIsToAddTable.(selectedKpiName) = newColumnData;
                end
            end
            %
            % Optional: show the current KPIsToAddTable in Command Window for debug
            disp('Current KPIsToAddTable:');
            disp(app.KPIsToAddTable);

            % Optional: clear the input field after adding
            % If numeric:
            % app.InsertKPIvalueEditField.Value = 0;
            % If text:
            % app.InsertKPIvalueEditField.Value = '';

        end

        % Button pushed function: ShowaddedimportedKPIstableButton, 
        % ...and 1 other component
        function ShowaddedimportedKPIstableButtonPushed(app, event)
            % Display the current KPIsToAddTable in a separate, resizable UI figure.
            % The table is shown exactly as stored in app.KPIsToAddTable.
            %----------------------------------------------------------------------

            % 1. Check if there are KPIs to show
            if isempty(app.KPIsToAddTable) || width(app.KPIsToAddTable) == 0
                uialert(app.UIFigure, ...
                    'No KPIs have been added yet. Use "Add KPI Value" first.', ...
                    'No KPIs available');
                return;
            end

            % 2. Create independent UI figure
            kpiViewerFig = uifigure( ...
                'Name', 'KPIs to be Added', ...
                'Position', [200 200 600 300]);
            % The figure is resizable and closable by default

            % 3. Create UITable and populate with KPIsToAddTable
            kpiUITable = uitable(kpiViewerFig, ...
                'Data', app.KPIsToAddTable, ...
                'Position', [20 20 560 260]);

            % 4. Improve readability
            kpiUITable.ColumnName   = app.KPIsToAddTable.Properties.VariableNames;
            kpiUITable.ColumnWidth  = 'auto';

            % Enable interactive sorting if MATLAB version supports it
            try
                kpiUITable.ColumnSortable = true;
            catch
                % Older versions do not support sorting; silently ignore
            end

        end

        % Button pushed function: WriteKPIstoIDButton
        function WriteKPIstoIDButtonPushed(app, event)

            %------------------------------------------------------------------
            % Write KPIs from KPIsToAddTable into Excel KPI table "TableKPI"
            % for a specific ID taken from NewKPIsIDtowriteEditField.
            %
            % Logic:
            %   1) Check DB connection.
            %   2) Read Metadata sheet and verify that ID exists.
            %   3) Check that KPIsToAddTable is not empty.
            %   4) Use COM/ActiveX to:
            %       - Ensure that a row with this ID exists in Excel table "TableKPI".
            %       - For each KPI column in KPIsToAddTable:
            %           * If column exists in TableKPI:
            %               write value into correct cell.
            %           * Else:
            %               ask user via uiconfirm:
            %                 - Create new column
            %                 - Skip KPI
            %                 - Abort writing
            %
            % Requirements:
            %   - Excel installed (Windows) because of actxserver.
            %------------------------------------------------------------------

            %--------------------------
            % 1) Check DB connection
            %--------------------------
            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    'DB is not connected. Please connect to DB first.', ...
                    'DB not connected');
                return;
            end

            %---------------------------------------------------
            % 2) Read ID from GUI and validate numeric value
            %---------------------------------------------------
            idRawValue = app.IDrowtoaddKPIstoEditField.Value;  % could be numeric/text

            % Convert to numeric ID (double)
            if ischar(idRawValue) || isstring(idRawValue)
                idToWrite = str2double(string(idRawValue));
            else
                idToWrite = idRawValue;
            end

            if isnan(idToWrite)
                uialert(app.UIFigure, ...
                    'New KPIs ID to write is not a valid numeric value.', ...
                    'Invalid ID');
                return;
            end

            %---------------------------------------------------
            % 3) Verify that Metadata contains this ID
            %---------------------------------------------------
            try
                metadataTable = readtable(app.DB_FilePath, ...
                    'Sheet', 'Metadata', ...
                    'ReadVariableNames', true, ...
                    'VariableNamingRule', 'modify');   % valid MATLAB names
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "Metadata":\n%s', readError.message), ...
                    'Metadata read error');
                return;
            end

            idColumnName = 'ID';  % must match column name in MetadataTable

            if ~ismember(idColumnName, metadataTable.Properties.VariableNames)
                uialert(app.UIFigure, ...
                    sprintf('Column "%s" not found in Metadata sheet.', idColumnName), ...
                    'Metadata schema error');
                return;
            end

            idColumnData = metadataTable.(idColumnName);

            if isnumeric(idColumnData)
                metadataMask = (idColumnData == idToWrite);
            else
                metadataMask = strcmp(string(idColumnData), string(idToWrite));
            end

            if ~any(metadataMask)
                % No metadata row for this ID -> do NOT write KPIs
                uialert(app.UIFigure, ...
                    sprintf('No Metadata row found for ID = %g. KPIs will not be written.', idToWrite), ...
                    'ID not found in Metadata');
                return;
            end

            %---------------------------------------------------
            % 4) Check that KPIsToAddTable contains something
            %---------------------------------------------------
            if isempty(app.KPIsToAddTable) || width(app.KPIsToAddTable) == 0
                uialert(app.UIFigure, ...
                    ['No KPIs have been added to the temporary table.' newline ...
                    'Use "Add KPI Value" before writing KPIs.'], ...
                    'No KPIs to write');
                return;
            end

            %---------------------------------------------------
            % 5) Write KPIs to Excel table "TableKPI" for this ID
            %---------------------------------------------------
            try
                app.writeKPIsToExcelTableForID(idToWrite);
            catch ME
                % If user intentionally aborted, you may want a softer message.
                if strcmp(ME.identifier, 'WriteKPIs:UserAborted')
                    % Optional: no dialog, or just an info
                    % uialert(app.UIFigure, 'KPI writing aborted by user.', 'Write aborted');
                    return;
                end

                uialert(app.UIFigure, ...
                    sprintf('Error while writing KPIs to DB:\n%s', ME.message), ...
                    'KPI write error');
                return;
            end

            % Optional: notify user of success
            uialert(app.UIFigure, ...
                sprintf('KPIs written successfully for ID = %g.', idToWrite), ...
                'KPIs write complete', 'Icon', 'success');

        end

        % Button pushed function: ResetKPIstableButton
        function ResetKPIstableButtonPushed(app, event)

            Reset the temporary KPIs table used to accumulate KPI values before
            % writing them to the Excel DB.
            %
            % Behavior:
            %   - app.KPIsToAddTable becomes an empty table (0×0).
            %   - Any function that relies on this table will see that no KPIs
            %     are currently staged for writing.
            %
            % Equivalent patterns in other languages:
            %   - Python/Pandas: df = DataFrame()
            %   - C#/DataTable:  table.Clear(); table.Columns.Clear();
            %----------------------------------------------------------------------

            % Clear the local KPI accumulation table
            app.KPIsToAddTable = table();

            % Optional: clear input fields to avoid visual confusion
            % app.InsertKPIvalueEditField.Value = '';
            % app.SelectKPItobeAddedDropDown.Value = app.SelectKPItobeAddedDropDown.Items{1};

            % Optional: feedback to the user
            % uialert(app.UIFigure, 'KPI table has been reset.', 'Reset complete');
        end

        % Value changed function: IDtoloadEditField
        function IDtoloadEditFieldValueChanged(app, event)
            value = app.IDtoloadEditField.Value;
            app.IDtomodifyIDtoloadEditField.Value = value;
        end

        % Button pushed function: OverwriteMetadatatoIDloadedButton
        function OverwriteMetadatatoIDloadedButtonPushed(app, event)

            %----------------------------------------------------------------------
            % Let the user review and confirm overwriting existing Metadata row
            % in Excel for the currently loaded ID.
            %
            % Steps:
            %   1) Check DB connection.
            %   2) Read Metadata table from Excel.
            %   3) Get ID from GUI (loaded ID) and find corresponding row in DB.
            %   4) Build NEW row from GUI using GeneralMetadataMap and ICMetadataMap.
            %   5) Build a 2-row comparison table:
            %        - Row 1: ORIGINAL (from DB)
            %        - Row 2: NEW (from GUI)
            %   6) Show comparison in a uifigure with 2 buttons:
            %        - "Confirm overwrite" -> actually write to Excel
            %        - "Cancel"            -> do nothing
            %----------------------------------------------------------------------

            %-------------------------
            % 1) Check DB connection
            %-------------------------
            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    'DB is not connected. Please connect to DB first.', ...
                    'DB not connected');
                return;
            end

            %-----------------------------------------
            % 2) Read Metadata table from Excel
            %-----------------------------------------
            try
                metadataTable = readtable(app.DB_FilePath, ...
                    'Sheet', 'Metadata', ...
                    'ReadVariableNames', true, ...
                    'VariableNamingRule', 'modify');  % valid MATLAB identifiers
            catch readError
                uialert(app.UIFigure, ...
                    sprintf('Error reading sheet "Metadata":\n%s', readError.message), ...
                    'Read error');
                return;
            end

            if isempty(metadataTable)
                uialert(app.UIFigure, ...
                    'Metadata sheet is empty. Nothing to overwrite.', ...
                    'Empty Metadata sheet');
                return;
            end

            idColumnName = 'ID';
            if ~ismember(idColumnName, metadataTable.Properties.VariableNames)
                uialert(app.UIFigure, ...
                    sprintf('Column "%s" not found in Metadata sheet.', idColumnName), ...
                    'Schema error');
                return;
            end

            %-------------------------------------------------------
            % 3) Get ID to overwrite from GUI (currently loaded ID)
            %    Here we assume app.IDEditField contains the loaded ID.
            %    Adapt this line if your "loaded ID" is stored elsewhere.
            %-------------------------------------------------------
            rawIdValue = app.IDEditField.Value;  % NumericEditField or text

            if ischar(rawIdValue) || isstring(rawIdValue)
                idToOverwrite = str2double(string(rawIdValue));
            else
                idToOverwrite = rawIdValue;
            end

            if isnan(idToOverwrite)
                uialert(app.UIFigure, ...
                    'Loaded ID is not a valid numeric value.', ...
                    'Invalid ID');
                return;
            end

            % Find row in Metadata table for this ID
            idColumnData = metadataTable.(idColumnName);
            if isnumeric(idColumnData)
                matchMask = (idColumnData == idToOverwrite);
            else
                matchMask = strcmp(string(idColumnData), string(idToOverwrite));
            end

            matchIndices = find(matchMask);
            if isempty(matchIndices)
                uialert(app.UIFigure, ...
                    sprintf('No Metadata row found for ID = %g.', idToOverwrite), ...
                    'ID not found');
                return;
            elseif numel(matchIndices) > 1
                % For simplicity, we take the first; could be extended to handle duplicates.
                uialert(app.UIFigure, ...
                    sprintf(['Multiple Metadata rows found for ID = %g. ' ...
                    'The first matching row will be considered.'], idToOverwrite), ...
                    'Multiple rows found');
            end

            rowIndexToOverwrite = matchIndices(1);
            originalRowTable    = metadataTable(rowIndexToOverwrite, :);  % 1-row table

            %-------------------------------------------------------
            % 4) Build NEW row from GUI (mapping -> struct -> table)
            %-------------------------------------------------------
            % Build struct from GUI for General + IC metadata
            generalRowStruct = app.buildRowFromMapping(app.GeneralMetadataMap);
            icRowStruct      = app.buildRowFromMapping(app.ICMetadataMap);

            % Merge both into one struct
            newCombinedStruct = generalRowStruct;
            icFields = fieldnames(icRowStruct);
            for k = 1:numel(icFields)
                fieldName = icFields{k};
                newCombinedStruct.(fieldName) = icRowStruct.(fieldName);
            end

            % Force ID field to the existing ID (no change to ID itself)
            newCombinedStruct.(idColumnName) = idToOverwrite;

            % Create NEW row table based on ORIGINAL row, so variable names/order match
            newRowTable = originalRowTable;   % copy structure and original values

            % Overwrite only fields that exist in the struct and in the table
            newFieldNames = fieldnames(newCombinedStruct);
            for k = 1:numel(newFieldNames)
                fieldName = newFieldNames{k};
                if ismember(fieldName, newRowTable.Properties.VariableNames)

                    valueToWrite = newCombinedStruct.(fieldName);

                    % Wrap any type into a single cell → table accepts it always
                    newRowTable{1, fieldName} = {valueToWrite};
                end
            end

            %-------------------------------------------------------
            % 5) Build comparison table: row1=Original, row2=New
            %-------------------------------------------------------
            comparisonTable = [originalRowTable; newRowTable];
            comparisonTable.Properties.RowNames = {'Original', 'New'};

            %-------------------------------------------------------
            % 6) Show comparison in a new uifigure with 2 buttons
            %-------------------------------------------------------
            compareFig = uifigure( ...
                'Name', sprintf('Overwrite Metadata for ID = %g', idToOverwrite), ...
                'Position', [100 100 1000 400]);

            % UITable to show original vs new values
            compareUITable = uitable(compareFig, ...
                'Data', comparisonTable, ...
                'Position', [20 70 960 300]);

            compareUITable.ColumnName = comparisonTable.Properties.VariableNames;
            compareUITable.ColumnWidth = 'auto';

            % Show row names ("Original", "New") in the GUI table
            compareUITable.RowName = comparisonTable.Properties.RowNames;

            % Store data needed by confirm/cancel callbacks in figure UserData
            compareFig.UserData = struct( ...
                'IdToOverwrite', idToOverwrite, ...
                'NewRowTable',   newRowTable);

            % Confirm button
            confirmButton = uibutton(compareFig, 'push', ...
                'Text', 'Confirm overwrite', ...
                'Position', [300 20 150 30], ...
                'ButtonPushedFcn', @(src, evt)app.confirmOverwriteMetadataButtonPushed(src, evt, compareFig));

            % Cancel button
            cancelButton = uibutton(compareFig, 'push', ...
                'Text', 'Cancel', ...
                'Position', [500 20 150 30], ...
                'ButtonPushedFcn', @(src, evt)app.cancelOverwriteMetadataButtonPushed(src, evt, compareFig));
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)

            %--------------------------------------------------------------
            % Wrapper specifico per il bottone "Cluster":
            %   - legge la tabella "ClusterT" dal foglio "Support"
            %   - usa la colonna "Cluster"
            %   - scrive il valore scelto in app.ClusterTextArea
            %--------------------------------------------------------------
            app.openSupportSelectionDialog("Cluster", "Cluster", app.ClusterEditField, "Select from DB or add to DB Cluster");

        end

        % Button pushed function: SubClusterButton
        function SubClusterButtonPushed(app, event)
            app.openSupportSelectionDialog("SubCluster", "SubCluster", app.SubClusterEditField, "Select from DB or add to DB SubCluster");
        end

        % Button pushed function: BrandButton
        function BrandButtonPushed(app, event)
            app.openSupportSelectionDialog("Brand", "Brand", app.BrandEditField, "Select from DB or add to DB Brand");
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
            app.openSupportSelectionDialog("Model", "Model", app.ModelEditField, "Select from DB or add to DB Model");
        end

        % Button pushed function: ManeuvreButton
        function ManeuvreButtonPushed(app, event)
            app.openSupportSelectionDialog("Maneuvre", "Maneuvre", app.ManeuvreNameEditField, "Select from DB or add to DB Maneuvre");
        end

        % Button pushed function: ConditionButton
        function ConditionButtonPushed(app, event)
            app.openSupportSelectionDialog("Condition", "Condition", app.ConditionEditField, "Select from DB or add to DB Condition");
        end

        % Button pushed function: RoadSurfaceButton
        function RoadSurfaceButtonPushed(app, event)
            app.openSupportSelectionDialog("Surface", "Surface", app.RoadSurfaceEditField, "Select from DB or add to DB Surface");
        end

        % Button pushed function: TireFrontButton
        function TireFrontButtonPushed(app, event)
            app.openSupportSelectionDialog("Tire", "Tire", app.TireFrontTypeEditField, "Select from DB or add to DB Tire");
        end

        % Button pushed function: TireRearButton
        function TireRearButtonPushed(app, event)
            app.openSupportSelectionDialog("Tire", "Tire", app.TireRearTypeEditField, "Select from DB or add to DB Tire");
        end

        % Button pushed function: LoadMetadatainGUIfromtemplateButton
        function LoadMetadatainGUIfromtemplateButtonPushed(app, event)

            initializeDbMappings(app);

            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, 'DB not connected.', 'Error');
                return;
            end

            try
                templateTable = readtable(app.DB_FilePath, ...
                    "Sheet", "Metadata_Templates", ...
                    "ReadVariableNames", true, ...
                    "VariableNamingRule","modify");
            catch ME
                uialert(app.UIFigure, ...
                    sprintf("Error reading Metadata_Templates:\n%s", ME.message), ...
                    "Read error");
                return;
            end

            if isempty(templateTable)
                uialert(app.UIFigure, "Metadata_Templates is empty.", "Empty");
                return;
            end

            % Dialog identical to previous selection UIs
            fig = uifigure("Name","Select Metadata Template", "Position",[300 300 850 430]);

            uit = uitable(fig, ...
                "Data", templateTable, ...
                "Position",[20 70 810 320], ...
                "ColumnName", templateTable.Properties.VariableNames, ...
                "ColumnWidth", "auto");

            fig.UserData = struct( ...
                "TemplateTable", templateTable, ...
                "SelectedRow", [] );

            uit.CellSelectionCallback = @(src,evt) app.onTemplateSelection(src,evt,fig);

            uibutton(fig,"Text","Load", ...
                "Position",[250 20 120 35], ...
                "ButtonPushedFcn", @(src,evt) app.loadTemplateIntoGUI(src,evt,fig));

            uibutton(fig,"Text","Cancel", ...
                "Position",[400 20 120 35], ...
                "ButtonPushedFcn", @(src,evt) close(fig));

        end

        % Button pushed function: ImportKPIsfromexceltableButton
        function ImportKPIsfromexceltableButtonPushed(app, event)

        end

        % Button pushed function: ExporttemplatetoexcelButton
        function ExporttemplatetoexcelButtonPushed(app, event)

            %--------------------------------------------------------------
            % Lets user pick a row from Metadata_Templates and export it
            % as a one-row Excel file with column headers.
            %--------------------------------------------------------------

            if ~app.DB_Connected || isempty(app.DB_FilePath)
                uialert(app.UIFigure, ...
                    "DB is not connected. Connect first.", ...
                    "DB not connected");
                return;
            end

            % Read Metadata_Templates
            try
                templateTable = readtable(app.DB_FilePath, ...
                    "Sheet","Metadata_Templates", ...
                    "ReadVariableNames",true, ...
                    "VariableNamingRule","preserve");   % preserve original DB column names
            catch ME
                uialert(app.UIFigure, ...
                    sprintf("Error reading Metadata_Templates:\n%s",ME.message), ...
                    "Read error");
                return;
            end

            if isempty(templateTable)
                uialert(app.UIFigure,"Metadata_Templates sheet is empty.","Empty");
                return;
            end

            % Create selection dialog
            fig = uifigure("Name","Select Template Row to Export", ...
                "Position",[300 300 850 430]);

            uit = uitable(fig, ...
                "Data",templateTable, ...
                "Position",[20 70 810 320], ...
                "ColumnName",templateTable.Properties.VariableNames, ...
                "ColumnWidth","auto");

            fig.UserData = struct( ...
                "TemplateTable", templateTable, ...
                "SelectedRow", [] );

            uit.CellSelectionCallback = @(src,evt) app.onTemplateSelection(src,evt,fig);

            % Buttons
            uibutton(fig,"Text","Export", ...
                "Position",[250 20 120 35], ...
                "ButtonPushedFcn", @(src,evt) app.exportSelectedTemplateRow(src,evt,fig));

            uibutton(fig,"Text","Cancel", ...
                "Position",[410 20 120 35], ...
                "ButtonPushedFcn", @(src,evt) close(fig));
        end

        % Button pushed function: ImportMetadatafromexceltableButton
        function ImportMetadatafromexceltableButtonPushed(app, event)

            %--------------------------------------------------------------
            % Lets the user select an Excel file containing ONE metadata row
            % with DB column headers (same format used in export function).
            %
            % Reads it and populates GUI fields according to:
            %   - app.GeneralMetadataMap
            %   - app.ICMetadataMap
            %--------------------------------------------------------------

            % Ensure DB is connected (optional but consistent with your design)
            if ~app.DB_Connected
                uialert(app.UIFigure, ...
                    "Connect to the DB before importing metadata.", ...
                    "DB not connected");
                return;
            end

            % Ask user for a file to import
            [fileName, fileFolder] = uigetfile( ...
                {'*.xlsx','Excel files (*.xlsx)'}, ...
                'Select metadata Excel row to import');

            if isequal(fileName,0)
                return;   % user cancelled
            end

            fullPath = fullfile(fileFolder, fileName);

            %--------------------------------------------------------------
            % Read file — must contain exactly 1 row of metadata + headers
            %--------------------------------------------------------------
            try
                oneRowTable = readtable(fullPath, ...
                    "ReadVariableNames", true, ...
                    "VariableNamingRule","preserve");  % keep DB column names exactly
            catch ME
                uialert(app.UIFigure, ...
                    sprintf("Cannot read selected Excel file:\n%s", ME.message), ...
                    "Read error");
                return;
            end

            % Must have exactly one row
            if height(oneRowTable) ~= 1
                uialert(app.UIFigure, ...
                    sprintf("Expected exactly ONE row, but found %d.", height(oneRowTable)), ...
                    "Invalid template");
                return;
            end

            % Convert row to struct for mapping
            rowStruct = table2struct(oneRowTable);

            %--------------------------------------------------------------
            % Write values into GUI (using your robust mapping logic)
            %--------------------------------------------------------------
            app.writeStructToGUI(rowStruct, app.GeneralMetadataMap);
            app.writeStructToGUI(rowStruct, app.ICMetadataMap);

            % update ID
            updateID(app)

            % Optional: notify user
            uialert(app.UIFigure, ...
                sprintf("Metadata imported from:\n%s", fullPath), ...
                "Import complete", 'Icon', 'success');


        end

        % Button pushed function: ImportMetadataKPIsfromexceltableButton
        function ImportMetadataKPIsfromexceltableButtonPushed(app, event)

            %--------------------------------------------------------------
            % Imports metadata + KPIs from an Excel file.
            %
            % Requirements:
            %   - Date-like values must end up as strings with "dd/MM/yyyy"
            %     (e.g. "01/01/2026"), not datetime "01-Jan-2026".
            %   - Columns containing only numeric characters must end up
            %     as double, not as strings.
            %
            % Outputs:
            %   app.MetadataToAddTable : table of metadata columns only
            %   app.KPIsToAddTable     : table of KPI columns only
            %
            % At the end, a preview of the combined table is shown in a uitable.
            %--------------------------------------------------------------

            %-----------------------------
            % 1) Ask user for Excel file
            %-----------------------------
            [fileName, folderPath] = uigetfile( ...
                {'*.xlsx','Excel files (*.xlsx)'}, ...
                'Select Excel file with Metadata + KPIs');

            if isequal(fileName, 0)
                return;  % user cancelled
            end

            fullPath = fullfile(folderPath, fileName);

            importMetadataKPIsFromFile(app, fullPath);


        end

        % Button pushed function: WritemetedataKPIstonewIDsButton
        function WritemetedataKPIstonewIDsButtonPushed(app, event)
            %--------------------------------------------------------------
            % 0) Valida consistenza del DB prima di qualsiasi operazione
            %--------------------------------------------------------------
            [dbValid, dbErrorMsg] = app.validateDBConsistency();
            if ~dbValid
                uialert(app.UIFigure, dbErrorMsg, "DB non consistente");
                return;
            end

            % Button pushed function: WriteImportedMetadataAndKPIsButton
            %--------------------------------------------------------------
            % 1) Validate imported METADATA rows (app.MetadataToAddTable)
            %--------------------------------------------------------------
            [metadataOK, issuesTable] = app.validateImportedMetadata();

            if ~metadataOK
                % Show issues and stop here
                app.showMetadataIssuesDialog(issuesTable);
                return;
            end


            [noDuplicates, duplicatesSummaryTable] = app.checkMetadataDuplicatesAgainstDB();
            if ~noDuplicates
                app.showMetadataDuplicatesDialog(duplicatesSummaryTable);
                % Do NOT proceed to KPIs or DB write
                return;
            end

            % NUOVO: controllo duplicati interni al batch
            [internalDups, internalDupIdx] = app.checkInternalDuplicates();
            if internalDups
                uialert(app.UIFigure, ...
                    sprintf("Righe duplicate nel file importato: %s", mat2str(internalDupIdx)), ...
                    "Duplicati interni");
                return;
            end


            %--------------------------------------------------------------
            % 2) Validate KPI names and let user decide for unknown KPIs
            %--------------------------------------------------------------
            [kpiResolutionOK, kpiResolution] = app.showKPIResolutionDialog();

            if ~kpiResolutionOK
                % User cancelled or KPI decisions incomplete
                return;
            end

            %--------------------------------------------------------------
            % 3) If both metadata and KPI decisions are OK, write to DB
            %--------------------------------------------------------------
            try
                app.applyImportedMetadataAndKPIsToDB(kpiResolution);
            catch ME
                uialert(app.UIFigure, ...
                    sprintf("Error while writing imported data to DB:\n%s", ME.message), ...
                    "DB write error");
                return;
            end

            uialert(app.UIFigure, ...
                "Imported metadata and KPIs have been successfully written to DB.", ...
                "Write complete", 'Icon','success');
        end

        % Button pushed function: CloseconnectedDBsButton
        function CloseconnectedDBsButtonPushed(app, event)
            closeAllExcelButtonPushed(app)
        end

        % Button pushed function: ImportKPIsfromLoLaOutputButton
        function ImportKPIsfromLoLaOutputButtonPushed(app, event)
            load('Mapping_LoLa_DB_mapping.mat');
            app.LoLa_excel_path = process_excel(mapping);

            uialert(app.UIFigure, ...
                'LoLa KPIs Loaded and generated unified excel file. For excel file path check cmd window', ...
                'LoLa KPIs Loaded', 'Icon', 'success');
        end

        % Button pushed function: WriteGUIMetadataToLoLaOutputButton
        function WriteGUIMetadataToLoLaOutputButtonPushed(app, event)
            app.LoLa_excel_path_DB = writeMetadata_ALL_DB_FromGUI(app, app.LoLa_excel_path);
            disp(app.LoLa_excel_path_DB)
            OpenexcelButtonPushed(app, event)

            uialert(app.UIFigure, ...
                'Metadata Written to Excel File that is automatically opened.', ...
                'Metadata Writte', 'Icon', 'success');

        end

        % Button pushed function: BrakeFrontButton
        function BrakeFrontButtonPushed(app, event)
            app.openSupportSelectionDialog("Brake", "BrakeType", app.BrakeFrontTypeEditField, "Select from DB or add to DB Brake");
        end

        % Button pushed function: BrakeRearButton
        function BrakeRearButtonPushed(app, event)
            app.openSupportSelectionDialog("Brake", "BrakeType", app.BrakeRearTypeEditField, "Select from DB or add to DB Brake");
        end

        % Button pushed function: SmartTireSWTireIDButton
        function SmartTireSWTireIDButtonPushed(app, event)
            app.openSupportSelectionDialog("SmT_SW_TireID", "TireID", app.SmartTireSWTireIDEditField, "Select from DB or add to DB SmT Tire ID");
        end

        % Button pushed function: SmartTireSWRoadButton
        function SmartTireSWRoadButtonPushed(app, event)
            app.openSupportSelectionDialog("SmT_SW_Estimate", "Condition", app.SmartTireSWRoadEditField, "Select from DB or add to DB SmT SW Estimate");
        end

        % Button pushed function: SmartTireSWWearButton
        function SmartTireSWWearButtonPushed(app, event)
            app.openSupportSelectionDialog("SmT_SW_Estimate", "Condition", app.SmartTireSWWearEditField, "Select from DB or add to DB SmT SW Estimate");
        end

        % Button pushed function: SmartTireSWMassButton
        function SmartTireSWMassButtonPushed(app, event)
            app.openSupportSelectionDialog("SmT_SW_Estimate", "Condition", app.SmartTireSWMassEditField, "Select from DB or add to DB SmT SW Estimate");
        end

        % Button pushed function: SuspButton
        function SuspButtonPushed(app, event)
            app.openSupportSelectionDialog("Susp", "SuspActiveStatus", app.SuspTypeEditField, "Select from DB or add to DB Susp Type");
        end

        % Button pushed function: SteeringButton
        function SteeringButtonPushed(app, event)
            app.openSupportSelectionDialog("Steer", "SteeringType", app.SteeringTypeEditField, "Select from DB or add to DB Susp Type");
        end

        % Button pushed function: BrakeFrontBLPhaseButton
        function BrakeFrontBLPhaseButtonPushed(app, event)
            app.openSupportSelectionDialog("BL_Phase", "Phase", app.BrakeFrontBLPhaseEditField, "Select from DB or add to DB BL Phase");
        end

        % Button pushed function: BrakeRearBLPhaseButton
        function BrakeRearBLPhaseButtonPushed(app, event)
            app.openSupportSelectionDialog("BL_Phase", "Phase", app.BrakeRearBLPhaseEditField, "Select from DB or add to DB BL Phase");
        end

        % Button pushed function: OpenexcelButton
        function OpenexcelButtonPushed(app, event)
            winopen(app.LoLa_excel_path_DB)
        end

        % Button pushed function: 
        % WritemanuallymodifiedLoLaKPIsmetadataexcelfileButton
        function WritemanuallymodifiedLoLaKPIsmetadataexcelfileButtonPushed(app, event)
            
            fullPath = app.LoLa_excel_path_DB;

            importMetadataKPIsFromFile(app, fullPath);
            
            WritemetedataKPIstonewIDsButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectLoadWriteTab = uitab(app.TabGroup);
            app.ConnectLoadWriteTab.SizeChangedFcn = createCallbackFcn(app, @ConnectLoadWriteTabSizeChanged, true);
            app.ConnectLoadWriteTab.Title = 'Connect/Load/Write';
            app.GridLayout2 = uigridlayout(app.ConnectLoadWriteTab);
            app.ConnecttoDBButton = uibutton(app.GridLayout2, 'push');
            app.ConnecttoDBButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoDBButtonPushed, true);
            app.ConnecttoDBButton.Text = 'Connect to DB';
            app.DBPathTextAreaLabel = uilabel(app.GridLayout2);
            app.DBPathTextAreaLabel.Text = 'DB Path';
            app.DBPathTextArea = uitextarea(app.GridLayout2);
            app.StatusLampLabel = uilabel(app.GridLayout2);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.GridLayout2);
            app.ShowMetadataButton = uibutton(app.GridLayout2, 'push');
            app.ShowMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMetadataButtonPushed, true);
            app.ShowMetadataButton.Text = 'Show Metadata';
            app.ShowKPIsButton = uibutton(app.GridLayout2, 'push');
            app.ShowKPIsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowKPIsButtonPushed, true);
            app.ShowKPIsButton.Text = 'Show KPIs';
            app.ShowMetatadaKPIsDBButton = uibutton(app.GridLayout2, 'push');
            app.ShowMetatadaKPIsDBButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMetatadaKPIsDBButtonPushed, true);
            app.ShowMetatadaKPIsDBButton.Text = 'Show Metatada & KPIs DB';
            app.ImportMetadatasavetemplatetoexcelPanel = uipanel(app.GridLayout2);
            app.ImportMetadatasavetemplatetoexcelPanel.Title = 'Import Metadata & save template to excel';
            app.GridLayout9 = uigridlayout(app.ImportMetadatasavetemplatetoexcelPanel);
            app.LoadMetadatainGUIfromtemplateButton = uibutton(app.GridLayout9, 'push');
            app.LoadMetadatainGUIfromtemplateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMetadatainGUIfromtemplateButtonPushed, true);
            app.LoadMetadatainGUIfromtemplateButton.Text = 'Load Metadata in GUI from template';
            app.ImportMetadatafromexceltableButton = uibutton(app.GridLayout9, 'push');
            app.ImportMetadatafromexceltableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMetadatafromexceltableButtonPushed, true);
            app.ImportMetadatafromexceltableButton.Text = 'Import Metadata from excel table';
            app.ExporttemplatetoexcelButton = uibutton(app.GridLayout9, 'push');
            app.ExporttemplatetoexcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttemplatetoexcelButtonPushed, true);
            app.ExporttemplatetoexcelButton.Text = 'Export template to excel';
            app.LoadMetadatainGUIfromIDRowofMetadataDBButton = uibutton(app.GridLayout9, 'push');
            app.LoadMetadatainGUIfromIDRowofMetadataDBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMetadatainGUIfromIDRowofMetadataDBButtonPushed, true);
            app.LoadMetadatainGUIfromIDRowofMetadataDBButton.Text = 'Load Metadata in GUI from ID Row of Metadata DB';
            app.IDtoloadEditFieldLabel = uilabel(app.GridLayout9);
            app.IDtoloadEditFieldLabel.Text = 'ID to load';
            app.IDtoloadEditField = uieditfield(app.GridLayout9, 'numeric');
            app.IDtoloadEditField.ValueChangedFcn = createCallbackFcn(app, @IDtoloadEditFieldValueChanged, true);
            app.WriteMetadatatoDBPanel = uipanel(app.GridLayout2);
            app.WriteMetadatatoDBPanel.Title = 'Write Metadata to DB';
            app.GridLayout10 = uigridlayout(app.WriteMetadatatoDBPanel);
            app.WriteMetadatatonewIDButton = uibutton(app.GridLayout10, 'push');
            app.WriteMetadatatonewIDButton.ButtonPushedFcn = createCallbackFcn(app, @WriteMetadatatonewIDButtonPushed, true);
            app.WriteMetadatatonewIDButton.Text = 'Write Metadata to new ID';
            app.OverwriteMetadatatoIDloadedButton = uibutton(app.GridLayout10, 'push');
            app.OverwriteMetadatatoIDloadedButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteMetadatatoIDloadedButtonPushed, true);
            app.OverwriteMetadatatoIDloadedButton.Text = 'Overwrite Metadata to ID loaded';
            app.NewIDavailableEditFieldLabel = uilabel(app.GridLayout10);
            app.NewIDavailableEditFieldLabel.Text = 'New ID available';
            app.NewIDavailableEditField = uieditfield(app.GridLayout10, 'numeric');
            app.IDtomodifyIDtoloadEditFieldLabel = uilabel(app.GridLayout10);
            app.IDtomodifyIDtoloadEditFieldLabel.Text = 'ID to modify (-> ID to load) ';
            app.IDtomodifyIDtoloadEditField = uieditfield(app.GridLayout10, 'numeric');
            app.IDtomodifyIDtoloadEditField.Enable = 'off';
            app.ImportKPIsfromLoLaoutputPanel = uipanel(app.GridLayout2);
            app.ImportKPIsfromLoLaoutputPanel.Title = 'Import KPIs from LoLa output';
            app.GridLayout11 = uigridlayout(app.ImportKPIsfromLoLaoutputPanel);
            app.ImportKPIsfromLoLaOutputButton = uibutton(app.GridLayout11, 'push');
            app.ImportKPIsfromLoLaOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ImportKPIsfromLoLaOutputButtonPushed, true);
            app.ImportKPIsfromLoLaOutputButton.Text = 'Import KPIs from LoLa Output';
            app.InferefromFilenameCheckBox = uicheckbox(app.GridLayout11);
            app.InferefromFilenameCheckBox.Text = 'Infere from Filename';
            app.InfereTemplateCheckBox = uicheckbox(app.GridLayout11);
            app.InfereTemplateCheckBox.Text = 'Infere template';
            app.WriteGUIMetadataToLoLaOutputButton = uibutton(app.GridLayout11, 'push');
            app.WriteGUIMetadataToLoLaOutputButton.ButtonPushedFcn = createCallbackFcn(app, @WriteGUIMetadataToLoLaOutputButtonPushed, true);
            app.WriteGUIMetadataToLoLaOutputButton.Text = 'Write GUI Metadata To LoLa Output';
            app.MustbemodifiedmanuallyrowbyrowLabel = uilabel(app.GridLayout11);
            app.MustbemodifiedmanuallyrowbyrowLabel.Text = 'Must be modified manually row by row';
            app.OpenexcelButton = uibutton(app.GridLayout11, 'push');
            app.OpenexcelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenexcelButtonPushed, true);
            app.OpenexcelButton.Text = 'Open excel';
            app.ImportKPIsKPIsMetadatafromexcelPanel = uipanel(app.GridLayout2);
            app.ImportKPIsKPIsMetadatafromexcelPanel.Title = 'Import KPIs / KPIs + Metadata from excel';
            app.GridLayout12 = uigridlayout(app.ImportKPIsKPIsMetadatafromexcelPanel);
            app.ImportKPIsfromexceltableButton = uibutton(app.GridLayout12, 'push');
            app.ImportKPIsfromexceltableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportKPIsfromexceltableButtonPushed, true);
            app.ImportKPIsfromexceltableButton.Text = 'Import KPIs from excel table';
            app.ImportMetadataKPIsfromexceltableButton = uibutton(app.GridLayout12, 'push');
            app.ImportMetadataKPIsfromexceltableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMetadataKPIsfromexceltableButtonPushed, true);
            app.ImportMetadataKPIsfromexceltableButton.Text = 'Import Metadata & KPIs from excel table';
            app.ShownewKPIstableButton = uibutton(app.GridLayout12, 'push');
            app.ShownewKPIstableButton.ButtonPushedFcn = createCallbackFcn(app, @ShowaddedimportedKPIstableButtonPushed, true);
            app.ShownewKPIstableButton.Text = 'Show new KPIs table ';
            app.WritetoDBPanel = uipanel(app.GridLayout2);
            app.WritetoDBPanel.Title = 'Write to DB';
            app.GridLayout13 = uigridlayout(app.WritetoDBPanel);
            app.IDrowtoaddKPIstoEditFieldLabel = uilabel(app.GridLayout13);
            app.IDrowtoaddKPIstoEditFieldLabel.Text = 'ID row to add KPIs to';
            app.IDrowtoaddKPIstoEditField = uieditfield(app.GridLayout13, 'numeric');
            app.WriteKPIstoIDButton = uibutton(app.GridLayout13, 'push');
            app.WriteKPIstoIDButton.ButtonPushedFcn = createCallbackFcn(app, @WriteKPIstoIDButtonPushed, true);
            app.WriteKPIstoIDButton.Text = 'Write KPIs to ID';
            app.WritemetedataKPIstonewIDsButton = uibutton(app.GridLayout13, 'push');
            app.WritemetedataKPIstonewIDsButton.ButtonPushedFcn = createCallbackFcn(app, @WritemetedataKPIstonewIDsButtonPushed, true);
            app.WritemetedataKPIstonewIDsButton.Text = 'Write metedata & KPIs to new IDs';
            app.WritemanuallymodifiedLoLaKPIsmetadataexcelfileButton = uibutton(app.GridLayout13, 'push');
            app.WritemanuallymodifiedLoLaKPIsmetadataexcelfileButton.ButtonPushedFcn = createCallbackFcn(app, @WritemanuallymodifiedLoLaKPIsmetadataexcelfileButtonPushed, true);
            app.WritemanuallymodifiedLoLaKPIsmetadataexcelfileButton.Text = 'Write manually modified LoLa KPIs + metadata excel file';
            app.CloseconnectedDBsButton = uibutton(app.GridLayout2, 'push');
            app.CloseconnectedDBsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseconnectedDBsButtonPushed, true);
            app.CloseconnectedDBsButton.Text = 'Close connected DBs';
            app.WIPfeaturesLabel = uilabel(app.GridLayout2);
            app.WIPfeaturesLabel.Text = 'WIP features';
            app.MainfeaturesLabel = uilabel(app.GridLayout2);
            app.MainfeaturesLabel.Text = 'Main features';
            app.LegendLabel = uilabel(app.GridLayout2);
            app.LegendLabel.Text = 'Legend';
            app.GeneralMetadataTab = uitab(app.TabGroup);
            app.GeneralMetadataTab.Title = 'General Metadata';
            app.GridLayout = uigridlayout(app.GeneralMetadataTab);
            app.IDReferencesPanel_2 = uipanel(app.GridLayout);
            app.IDReferencesPanel_2.Title = 'ID & References';
            app.RefFolderlinkEditFieldLabel = uilabel(app.IDReferencesPanel_2);
            app.RefFolderlinkEditFieldLabel.Text = 'Ref Folder (link)';
            app.RefFolderlinkEditField = uieditfield(app.IDReferencesPanel_2, 'text');
            app.FileNameEditFieldLabel = uilabel(app.IDReferencesPanel_2);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.IDReferencesPanel_2, 'text');
            app.ManeuvreEnvironmentPanel = uipanel(app.GridLayout);
            app.ManeuvreEnvironmentPanel.Title = 'Maneuvre & Environment';
            app.DriverEditFieldLabel = uilabel(app.ManeuvreEnvironmentPanel);
            app.DriverEditFieldLabel.Text = 'Driver';
            app.DriverEditField = uieditfield(app.ManeuvreEnvironmentPanel, 'text');
            app.TiresPanel = uipanel(app.GridLayout);
            app.TiresPanel.Title = 'Tires';
            app.TireRearPressureLabel = uilabel(app.TiresPanel);
            app.TireRearPressureLabel.Text = 'Tire Rear Pressure';
            app.TireRearPressureEditField = uieditfield(app.TiresPanel, 'text');
            app.TireRearThreadDepEditFieldLabel = uilabel(app.TiresPanel);
            app.TireRearThreadDepEditFieldLabel.Text = 'Tire Rear Thread Dep';
            app.TireRearThreadDepEditField = uieditfield(app.TiresPanel, 'text');
            app.VehiclePanel = uipanel(app.GridLayout);
            app.VehiclePanel.Title = 'Vehicle';
            app.MassDOVWPEditFieldLabel = uilabel(app.VehiclePanel);
            app.MassDOVWPEditFieldLabel.Text = 'Mass (DOVW, #P)';
            app.MassDOVWPEditField = uieditfield(app.VehiclePanel, 'text');
            app.IDEditFieldLabel = uilabel(app.GridLayout);
            app.IDEditFieldLabel.Enable = 'off';
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.GridLayout, 'numeric');
            app.IDEditField.Editable = 'off';
            app.IDEditField.Enable = 'off';
            app.DateDatePickerLabel = uilabel(app.GridLayout);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.GridLayout);
            app.DateDatePicker.DisplayFormat = 'dd/MM/yyyy';
            app.BrandEditFieldLabel = uilabel(app.GridLayout);
            app.BrandEditFieldLabel.Text = 'Brand';
            app.BrandEditField = uieditfield(app.GridLayout, 'text');
            app.BrandEditField.Editable = 'off';
            app.BrandEditField.Enable = 'off';
            app.ModelEditFieldLabel = uilabel(app.GridLayout);
            app.ModelEditFieldLabel.Text = 'Model';
            app.ModelEditField = uieditfield(app.GridLayout, 'text');
            app.ModelEditField.Editable = 'off';
            app.ModelEditField.Enable = 'off';
            app.VehiclespecsEditFieldLabel = uilabel(app.GridLayout);
            app.VehiclespecsEditFieldLabel.Text = 'Vehicle specs';
            app.VehiclespecsEditField = uieditfield(app.GridLayout, 'text');
            app.VINEditFieldLabel = uilabel(app.GridLayout);
            app.VINEditFieldLabel.Text = 'VIN';
            app.VINEditField = uieditfield(app.GridLayout, 'text');
            app.ReferenceNameEditFieldLabel = uilabel(app.GridLayout);
            app.ReferenceNameEditFieldLabel.Text = 'Reference Name';
            app.ReferenceNameEditField = uieditfield(app.GridLayout, 'text');
            app.StartSpeedEditFieldLabel = uilabel(app.GridLayout);
            app.StartSpeedEditFieldLabel.Text = 'Start Speed';
            app.StartSpeedEditField = uieditfield(app.GridLayout, 'numeric');
            app.StartSpeedEditField.Limits = [0 300];
            app.StartSpeedEditField.AllowEmpty = 'on';
            app.StartSpeedEditField.Value = [];
            app.EndSpeedEditFieldLabel = uilabel(app.GridLayout);
            app.EndSpeedEditFieldLabel.Text = 'End Speed';
            app.EndSpeedEditField = uieditfield(app.GridLayout, 'numeric');
            app.EndSpeedEditField.Limits = [0 200];
            app.EndSpeedEditField.AllowEmpty = 'on';
            app.EndSpeedEditField.Value = [];
            app.TrackEditField_2Label = uilabel(app.GridLayout);
            app.TrackEditField_2Label.Text = 'Track';
            app.TrackEditField_2 = uieditfield(app.GridLayout, 'text');
            app.RoadmucoeffEditFieldLabel = uilabel(app.GridLayout);
            app.RoadmucoeffEditFieldLabel.Text = 'Road mu coeff';
            app.RoadmucoeffEditField = uieditfield(app.GridLayout, 'numeric');
            app.RoadmucoeffEditField.Limits = [0 300];
            app.RoadmucoeffEditField.AllowEmpty = 'on';
            app.RoadmucoeffEditField.Value = [];
            app.VehiclesettingsPanel = uipanel(app.GridLayout);
            app.VehiclesettingsPanel.Title = 'Vehicle settings';
            app.DrivingModeEditFieldLabel = uilabel(app.GridLayout);
            app.DrivingModeEditFieldLabel.Text = 'Driving Mode';
            app.DrivingModeEditField = uieditfield(app.GridLayout, 'text');
            app.ABSDropDownLabel = uilabel(app.GridLayout);
            app.ABSDropDownLabel.Text = 'ABS';
            app.ABSDropDown = uidropdown(app.GridLayout);
            app.ABSDropDown.Items = {'ON', 'OFF'};
            app.ABSDropDown.Value = 'ON';
            app.ESCDropDownLabel = uilabel(app.GridLayout);
            app.ESCDropDownLabel.Text = 'ESC';
            app.ESCDropDown = uidropdown(app.GridLayout);
            app.ESCDropDown.Items = {'ON', 'OFF'};
            app.ESCDropDown.Value = 'ON';
            app.TCSDropDownLabel = uilabel(app.GridLayout);
            app.TCSDropDownLabel.Text = 'TCS';
            app.TCSDropDown = uidropdown(app.GridLayout);
            app.TCSDropDown.Items = {'ON', 'OFF'};
            app.TCSDropDown.Value = 'ON';
            app.TireRearNameEditFieldLabel = uilabel(app.GridLayout);
            app.TireRearNameEditFieldLabel.Text = 'Tire Rear Name';
            app.TireRearNameEditField = uieditfield(app.GridLayout, 'text');
            app.ClusterButton = uibutton(app.GridLayout, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'DB';
            app.ClusterEditFieldLabel = uilabel(app.GridLayout);
            app.ClusterEditFieldLabel.Text = 'Cluster';
            app.ClusterEditField = uieditfield(app.GridLayout, 'text');
            app.ClusterEditField.Editable = 'off';
            app.ClusterEditField.Enable = 'off';
            app.SubClusterEditFieldLabel = uilabel(app.GridLayout);
            app.SubClusterEditFieldLabel.Text = 'SubCluster';
            app.SubClusterEditField = uieditfield(app.GridLayout, 'text');
            app.SubClusterEditField.Editable = 'off';
            app.SubClusterEditField.Enable = 'off';
            app.SubClusterButton = uibutton(app.GridLayout, 'push');
            app.SubClusterButton.ButtonPushedFcn = createCallbackFcn(app, @SubClusterButtonPushed, true);
            app.SubClusterButton.Text = 'DB';
            app.TireFrontNameEditFieldLabel = uilabel(app.GridLayout);
            app.TireFrontNameEditFieldLabel.Text = 'Tire Front Name';
            app.TireFrontNameEditField = uieditfield(app.GridLayout, 'text');
            app.BrandButton = uibutton(app.GridLayout, 'push');
            app.BrandButton.ButtonPushedFcn = createCallbackFcn(app, @BrandButtonPushed, true);
            app.BrandButton.Text = 'DB';
            app.ModelButton = uibutton(app.GridLayout, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Text = 'DB';
            app.ManeuvreButton = uibutton(app.GridLayout, 'push');
            app.ManeuvreButton.ButtonPushedFcn = createCallbackFcn(app, @ManeuvreButtonPushed, true);
            app.ManeuvreButton.Text = 'DB';
            app.RoadSurfaceEditFieldLabel = uilabel(app.GridLayout);
            app.RoadSurfaceEditFieldLabel.Text = 'Road Surface';
            app.RoadSurfaceEditField = uieditfield(app.GridLayout, 'text');
            app.RoadSurfaceEditField.Editable = 'off';
            app.RoadSurfaceEditField.Enable = 'off';
            app.RoadSurfaceButton = uibutton(app.GridLayout, 'push');
            app.RoadSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @RoadSurfaceButtonPushed, true);
            app.RoadSurfaceButton.Text = 'DB';
            app.ConditiondrywetEditFieldLabel = uilabel(app.GridLayout);
            app.ConditiondrywetEditFieldLabel.Text = 'Condition (dry, wet..)';
            app.ConditionEditField = uieditfield(app.GridLayout, 'text');
            app.ConditionEditField.Editable = 'off';
            app.ConditionEditField.Enable = 'off';
            app.ConditionButton = uibutton(app.GridLayout, 'push');
            app.ConditionButton.ButtonPushedFcn = createCallbackFcn(app, @ConditionButtonPushed, true);
            app.ConditionButton.Text = 'DB';
            app.TireFrontButton = uibutton(app.GridLayout, 'push');
            app.TireFrontButton.ButtonPushedFcn = createCallbackFcn(app, @TireFrontButtonPushed, true);
            app.TireFrontButton.Text = 'DB';
            app.TireRearButton = uibutton(app.GridLayout, 'push');
            app.TireRearButton.ButtonPushedFcn = createCallbackFcn(app, @TireRearButtonPushed, true);
            app.TireRearButton.Text = 'DB';
            app.TireFrontTypeEditFieldLabel = uilabel(app.GridLayout);
            app.TireFrontTypeEditFieldLabel.Text = 'Tire Front Type';
            app.TireFrontTypeEditField = uieditfield(app.GridLayout, 'text');
            app.TireFrontTypeEditField.Editable = 'off';
            app.TireFrontTypeEditField.Enable = 'off';
            app.TireRearTypeEditFieldLabel = uilabel(app.GridLayout);
            app.TireRearTypeEditFieldLabel.Text = 'Tire RearType';
            app.TireRearTypeEditField = uieditfield(app.GridLayout, 'text');
            app.TireRearTypeEditField.Editable = 'off';
            app.TireRearTypeEditField.Enable = 'off';
            app.ManeuvreNameEditFieldLabel = uilabel(app.GridLayout);
            app.ManeuvreNameEditFieldLabel.Text = 'Maneuvre Name';
            app.ManeuvreNameEditField = uieditfield(app.GridLayout, 'text');
            app.ManeuvreNameEditField.Editable = 'off';
            app.ManeuvreNameEditField.Enable = 'off';
            app.RepetitionIDEditFieldLabel = uilabel(app.GridLayout);
            app.RepetitionIDEditFieldLabel.Text = 'Repetition # ID';
            app.RepetitionIDEditField = uieditfield(app.GridLayout, 'numeric');
            app.RepetitionIDEditField.Limits = [0 300];
            app.IDNameEditFieldLabel = uilabel(app.GridLayout);
            app.IDNameEditFieldLabel.Text = 'ID Name';
            app.IDNameEditField = uieditfield(app.GridLayout, 'text');
            app.ResultsCategoryEditFieldLabel = uilabel(app.GridLayout);
            app.ResultsCategoryEditFieldLabel.Text = 'Results Category';
            app.ResultsCategoryEditField = uieditfield(app.GridLayout, 'text');
            app.TireFrontPressureEditFieldLabel = uilabel(app.GridLayout);
            app.TireFrontPressureEditFieldLabel.Text = 'Tire Front Pressure';
            app.TireFrontPressureEditField = uieditfield(app.GridLayout, 'text');
            app.TireFrontThreadDepEditFieldLabel = uilabel(app.GridLayout);
            app.TireFrontThreadDepEditFieldLabel.Text = 'Tire Front Thread Dep';
            app.TireFrontThreadDepEditField = uieditfield(app.GridLayout, 'text');
            app.ICMetadataTab = uitab(app.TabGroup);
            app.ICMetadataTab.Title = 'IC Metadata';
            app.GridLayout_2 = uigridlayout(app.ICMetadataTab);
            app.SuspensionsPanel = uipanel(app.GridLayout_2);
            app.SuspensionsPanel.Title = 'Suspensions';
            app.GridLayout5 = uigridlayout(app.SuspensionsPanel);
            app.SuspSystemEditFieldLabel = uilabel(app.GridLayout5);
            app.SuspSystemEditFieldLabel.Text = 'Susp System';
            app.SuspSystemEditField = uieditfield(app.GridLayout5, 'text');
            app.SuspModeEditFieldLabel = uilabel(app.GridLayout5);
            app.SuspModeEditFieldLabel.Text = 'Susp Mode';
            app.SuspModeEditField = uieditfield(app.GridLayout5, 'text');
            app.SuspBLEditFieldLabel = uilabel(app.GridLayout5);
            app.SuspBLEditFieldLabel.Text = 'Susp BL';
            app.SuspBLEditField = uieditfield(app.GridLayout5, 'text');
            app.SuspBLCalibDateDatePickerLabel = uilabel(app.GridLayout5);
            app.SuspBLCalibDateDatePickerLabel.Text = 'Susp BL Calib Date';
            app.SuspBLCalibDateDatePicker = uidatepicker(app.GridLayout5);
            app.SuspBLCalibDateDatePicker.DisplayFormat = 'dd/MM/yyyy';
            app.SuspTypeEditFieldLabel = uilabel(app.GridLayout5);
            app.SuspTypeEditFieldLabel.Text = 'Susp Type';
            app.SuspTypeEditField = uieditfield(app.GridLayout5, 'text');
            app.SuspTypeEditField.Editable = 'off';
            app.SuspTypeEditField.Enable = 'off';
            app.SuspButton = uibutton(app.GridLayout5, 'push');
            app.SuspButton.ButtonPushedFcn = createCallbackFcn(app, @SuspButtonPushed, true);
            app.SuspButton.Text = 'DB';
            app.CALIDEditFieldLabel_2 = uilabel(app.GridLayout5);
            app.CALIDEditFieldLabel_2.Text = 'CAL ID';
            app.SuspCALIDEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SuspCALIDEditField.AllowEmpty = 'on';
            app.SuspCALIDEditField.Value = [];
            app.SteeringPanel = uipanel(app.GridLayout_2);
            app.SteeringPanel.Title = 'Steering';
            app.GridLayout7 = uigridlayout(app.SteeringPanel);
            app.CALIDEditField_5Label = uilabel(app.GridLayout7);
            app.CALIDEditField_5Label.Text = 'CAL ID';
            app.SteerCALIDEditField = uieditfield(app.GridLayout7, 'numeric');
            app.SteerCALIDEditField.AllowEmpty = 'on';
            app.SteerCALIDEditField.Value = [];
            app.SteeringTypeEditFieldLabel = uilabel(app.GridLayout7);
            app.SteeringTypeEditFieldLabel.Text = 'Steering Type';
            app.SteeringTypeEditField = uieditfield(app.GridLayout7, 'text');
            app.SteeringTypeEditField.Editable = 'off';
            app.SteeringTypeEditField.Enable = 'off';
            app.SteeringButton = uibutton(app.GridLayout7, 'push');
            app.SteeringButton.ButtonPushedFcn = createCallbackFcn(app, @SteeringButtonPushed, true);
            app.SteeringButton.Text = 'DB';
            app.SteeringBLEditFieldLabel = uilabel(app.GridLayout7);
            app.SteeringBLEditFieldLabel.Text = 'Steering BL';
            app.SteeringBLEditField = uieditfield(app.GridLayout7, 'text');
            app.SteeringModeEditFieldLabel = uilabel(app.GridLayout7);
            app.SteeringModeEditFieldLabel.Text = 'Steering Mode';
            app.SteeringModeEditField = uieditfield(app.GridLayout7, 'text');
            app.SteeringBLCalDateDatePickerLabel = uilabel(app.GridLayout7);
            app.SteeringBLCalDateDatePickerLabel.Text = 'Steering BL Cal Date';
            app.SteeringBLCalDateDatePicker = uidatepicker(app.GridLayout7);
            app.SteeringBLCalDateDatePicker.DisplayFormat = 'dd/MM/yyyy';
            app.SmartTirePanel = uipanel(app.GridLayout_2);
            app.SmartTirePanel.Title = 'Smart Tire';
            app.GridLayout6 = uigridlayout(app.SmartTirePanel);
            app.SmartTireSWMassButton = uibutton(app.GridLayout6, 'push');
            app.SmartTireSWMassButton.ButtonPushedFcn = createCallbackFcn(app, @SmartTireSWMassButtonPushed, true);
            app.SmartTireSWMassButton.Text = 'DB';
            app.SmartTireSWWearButton = uibutton(app.GridLayout6, 'push');
            app.SmartTireSWWearButton.ButtonPushedFcn = createCallbackFcn(app, @SmartTireSWWearButtonPushed, true);
            app.SmartTireSWWearButton.Text = 'DB';
            app.SmartTireSWTireIDButton = uibutton(app.GridLayout6, 'push');
            app.SmartTireSWTireIDButton.ButtonPushedFcn = createCallbackFcn(app, @SmartTireSWTireIDButtonPushed, true);
            app.SmartTireSWTireIDButton.Text = 'DB';
            app.SmartTireSWRoadButton = uibutton(app.GridLayout6, 'push');
            app.SmartTireSWRoadButton.ButtonPushedFcn = createCallbackFcn(app, @SmartTireSWRoadButtonPushed, true);
            app.SmartTireSWRoadButton.Text = 'DB';
            app.CALIDEditField_4Label = uilabel(app.GridLayout6);
            app.CALIDEditField_4Label.Text = 'CAL ID';
            app.SmTCALIDEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SmTCALIDEditField.AllowEmpty = 'on';
            app.SmTCALIDEditField.Value = [];
            app.SmartTireSWVerEditFieldLabel = uilabel(app.GridLayout6);
            app.SmartTireSWVerEditFieldLabel.Text = 'Smart Tire SW Ver';
            app.SmartTireSWVerEditField = uieditfield(app.GridLayout6, 'text');
            app.SmartTireSWRoadEditFieldLabel = uilabel(app.GridLayout6);
            app.SmartTireSWRoadEditFieldLabel.Text = 'Smart Tire SW Road';
            app.SmartTireSWRoadEditField = uieditfield(app.GridLayout6, 'text');
            app.SmartTireSWRoadEditField.Editable = 'off';
            app.SmartTireSWRoadEditField.Enable = 'off';
            app.SmartTireSWWearEditFieldLabel = uilabel(app.GridLayout6);
            app.SmartTireSWWearEditFieldLabel.Text = 'Smart Tire SW Wear';
            app.SmartTireSWWearEditField = uieditfield(app.GridLayout6, 'text');
            app.SmartTireSWWearEditField.Editable = 'off';
            app.SmartTireSWWearEditField.Enable = 'off';
            app.SmartTireSWMassEditFieldLabel = uilabel(app.GridLayout6);
            app.SmartTireSWMassEditFieldLabel.Text = 'Smart Tire SW Mass';
            app.SmartTireSWMassEditField = uieditfield(app.GridLayout6, 'text');
            app.SmartTireSWMassEditField.Editable = 'off';
            app.SmartTireSWMassEditField.Enable = 'off';
            app.SmartTireSWTireIDEditFieldLabel = uilabel(app.GridLayout6);
            app.SmartTireSWTireIDEditFieldLabel.Text = 'Smart Tire SW TireID';
            app.SmartTireSWTireIDEditField = uieditfield(app.GridLayout6, 'text');
            app.SmartTireSWTireIDEditField.Editable = 'off';
            app.SmartTireSWTireIDEditField.Enable = 'off';
            app.SmartTireTypeEditFieldLabel = uilabel(app.GridLayout6);
            app.SmartTireTypeEditFieldLabel.Text = 'Smart Tire Type';
            app.SmartTireTypeEditField = uieditfield(app.GridLayout6, 'text');
            app.SmartTireBLEditFieldLabel = uilabel(app.GridLayout6);
            app.SmartTireBLEditFieldLabel.Text = 'Smart Tire BL';
            app.SmartTireBLEditField = uieditfield(app.GridLayout6, 'text');
            app.CoOperationPanel = uipanel(app.GridLayout_2);
            app.CoOperationPanel.Title = 'CoOperation';
            app.GridLayout8 = uigridlayout(app.CoOperationPanel);
            app.CoOperationtypeLabel = uilabel(app.GridLayout8);
            app.CoOperationtypeLabel.Text = 'CoOperation type';
            app.Tree = uitree(app.GridLayout8, 'checkbox');
            app.BrakeNode = uitreenode(app.Tree);
            app.BrakeNode.Text = 'Brake';
            app.SuspNode = uitreenode(app.Tree);
            app.SuspNode.Text = 'Susp';
            app.TireNode = uitreenode(app.Tree);
            app.TireNode.Text = 'Tire';
            app.SteerNode = uitreenode(app.Tree);
            app.SteerNode.Text = 'Steer';
            app.CALIDEditField_6Label = uilabel(app.GridLayout8);
            app.CALIDEditField_6Label.Text = 'CAL ID';
            app.CoOpCALIDEditField = uieditfield(app.GridLayout8, 'numeric');
            app.CoOpCALIDEditField.AllowEmpty = 'on';
            app.CoOpCALIDEditField.Value = [];
            app.CoOperationBLCalDateDatePickerLabel = uilabel(app.GridLayout8);
            app.CoOperationBLCalDateDatePickerLabel.Text = 'CoOperation BL Cal Date';
            app.CoOperationBLCalDateDatePicker = uidatepicker(app.GridLayout8);
            app.CoOperationBLCalDateDatePicker.DisplayFormat = 'dd/MM/yyyy';
            app.CoOperationBLEditFieldLabel = uilabel(app.GridLayout8);
            app.CoOperationBLEditFieldLabel.Text = 'CoOperation BL';
            app.CoOperationBLEditField = uieditfield(app.GridLayout8, 'text');
            app.SmartTireBLCalDateDatePickerLabel = uilabel(app.GridLayout_2);
            app.SmartTireBLCalDateDatePickerLabel.Text = 'Smart Tire BL Cal. Date';
            app.SmartTireBLCalDateDatePicker = uidatepicker(app.GridLayout_2);
            app.SmartTireBLCalDateDatePicker.DisplayFormat = 'dd/MM/yyyy';
            app.BrakeSystemPanel = uipanel(app.GridLayout_2);
            app.BrakeSystemPanel.Title = 'Brake System';
            app.GridLayout4 = uigridlayout(app.BrakeSystemPanel);
            app.BrakeFrontSystemEditFieldLabel = uilabel(app.GridLayout4);
            app.BrakeFrontSystemEditFieldLabel.Text = 'Brake Front System';
            app.BrakeFrontSystemEditField = uieditfield(app.GridLayout4, 'text');
            app.BrakeFrontTypeEditFieldLabel = uilabel(app.GridLayout4);
            app.BrakeFrontTypeEditFieldLabel.Text = 'Brake Front Type';
            app.BrakeFrontTypeEditField = uieditfield(app.GridLayout4, 'text');
            app.BrakeFrontTypeEditField.Editable = 'off';
            app.BrakeFrontTypeEditField.Enable = 'off';
            app.BrakeFrontButton = uibutton(app.GridLayout4, 'push');
            app.BrakeFrontButton.ButtonPushedFcn = createCallbackFcn(app, @BrakeFrontButtonPushed, true);
            app.BrakeFrontButton.Text = 'DB';
            app.BrakeRearSystemEditFieldLabel = uilabel(app.GridLayout4);
            app.BrakeRearSystemEditFieldLabel.Text = 'Brake Rear System';
            app.BrakeRearSystemEditField = uieditfield(app.GridLayout4, 'text');
            app.BrakeRearTypeEditFieldLabel = uilabel(app.GridLayout4);
            app.BrakeRearTypeEditFieldLabel.Text = 'Brake Rear Type';
            app.BrakeRearTypeEditField = uieditfield(app.GridLayout4, 'text');
            app.BrakeRearTypeEditField.Editable = 'off';
            app.BrakeRearTypeEditField.Enable = 'off';
            app.BrakeRearButton = uibutton(app.GridLayout4, 'push');
            app.BrakeRearButton.ButtonPushedFcn = createCallbackFcn(app, @BrakeRearButtonPushed, true);
            app.BrakeRearButton.Text = 'DB';
            app.BLFrontEditFieldLabel = uilabel(app.GridLayout4);
            app.BLFrontEditFieldLabel.Text = 'BL Front';
            app.BLFrontEditField = uieditfield(app.GridLayout4, 'text');
            app.FrontcalibrationdateDatePickerLabel = uilabel(app.GridLayout4);
            app.FrontcalibrationdateDatePickerLabel.Text = 'Front calibration date';
            app.FrontcalibrationdateDatePicker = uidatepicker(app.GridLayout4);
            app.FrontcalibrationdateDatePicker.DisplayFormat = 'dd/MM/yyyy';
            app.BLRearEditFieldLabel = uilabel(app.GridLayout4);
            app.BLRearEditFieldLabel.Text = 'BL Rear';
            app.BLRearEditField = uieditfield(app.GridLayout4, 'text');
            app.RearcalibrationdateDatePickerLabel = uilabel(app.GridLayout4);
            app.RearcalibrationdateDatePickerLabel.Text = 'Rear calibration date';
            app.RearcalibrationdateDatePicker = uidatepicker(app.GridLayout4);
            app.RearcalibrationdateDatePicker.DisplayFormat = 'dd/MM/yyyy';
            app.CALIDEditFieldLabel = uilabel(app.GridLayout4);
            app.CALIDEditFieldLabel.Text = 'CAL ID';
            app.BrakeFrontCALIDEditField = uieditfield(app.GridLayout4, 'numeric');
            app.BrakeFrontCALIDEditField.AllowEmpty = 'on';
            app.BrakeFrontCALIDEditField.Value = [];
            app.CALIDEditField_2Label = uilabel(app.GridLayout4);
            app.CALIDEditField_2Label.Text = 'CAL ID';
            app.BrakeRearCALIDEditField = uieditfield(app.GridLayout4, 'numeric');
            app.BrakeRearCALIDEditField.AllowEmpty = 'on';
            app.BrakeRearCALIDEditField.Value = [];
            app.BrakeFrontBLPhaseEditFieldLabel = uilabel(app.GridLayout4);
            app.BrakeFrontBLPhaseEditFieldLabel.Text = 'Brake Front BL Phase';
            app.BrakeFrontBLPhaseEditField = uieditfield(app.GridLayout4, 'text');
            app.BrakeFrontBLPhaseEditField.Editable = 'off';
            app.BrakeFrontBLPhaseEditField.Enable = 'off';
            app.BrakeFrontBLPhaseButton = uibutton(app.GridLayout4, 'push');
            app.BrakeFrontBLPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @BrakeFrontBLPhaseButtonPushed, true);
            app.BrakeFrontBLPhaseButton.Text = 'DB';
            app.BrakeRearBLPhaseEditFieldLabel = uilabel(app.GridLayout4);
            app.BrakeRearBLPhaseEditFieldLabel.Text = 'Brake Rear BL Phase';
            app.BrakeRearBLPhaseEditField = uieditfield(app.GridLayout4, 'text');
            app.BrakeRearBLPhaseEditField.Editable = 'off';
            app.BrakeRearBLPhaseEditField.Enable = 'off';
            app.BrakeRearBLPhaseButton = uibutton(app.GridLayout4, 'push');
            app.BrakeRearBLPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @BrakeRearBLPhaseButtonPushed, true);
            app.BrakeRearBLPhaseButton.Text = 'DB';
            app.KPIsTab = uitab(app.TabGroup);
            app.KPIsTab.Title = 'KPIs';
            app.GridLayout3 = uigridlayout(app.KPIsTab);
            app.SelectKPItobeAddedDropDownLabel = uilabel(app.GridLayout3);
            app.SelectKPItobeAddedDropDownLabel.Text = 'Select KPI to be Added';
            app.SelectKPItobeAddedDropDown = uidropdown(app.GridLayout3);
            app.SelectKPItobeAddedDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectKPItobeAddedDropDownValueChanged, true);
            app.InsertKPIvalueEditFieldLabel = uilabel(app.GridLayout3);
            app.InsertKPIvalueEditFieldLabel.Text = 'Insert KPI value';
            app.InsertKPIvalueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RefreshlistfromconnectedDBButton = uibutton(app.GridLayout3, 'push');
            app.RefreshlistfromconnectedDBButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshlistfromconnectedDBButtonPushed, true);
            app.RefreshlistfromconnectedDBButton.Text = 'Refresh list from connected DB';
            app.GenerateexceltabletobefilledwithKPIsAllButton = uibutton(app.GridLayout3, 'push');
            app.GenerateexceltabletobefilledwithKPIsAllButton.Text = {'Generate excel table to be '; 'filled with KPIs (All)'};
            app.GenerateexceltabletobefilledwithKPIsSelectedBelowButton = uibutton(app.GridLayout3, 'push');
            app.GenerateexceltabletobefilledwithKPIsSelectedBelowButton.Text = {'Generate excel table to be '; 'filled with KPIs (Selected Below)'};
            app.ShowaddedimportedKPIstableButton = uibutton(app.GridLayout3, 'push');
            app.ShowaddedimportedKPIstableButton.ButtonPushedFcn = createCallbackFcn(app, @ShowaddedimportedKPIstableButtonPushed, true);
            app.ShowaddedimportedKPIstableButton.Text = 'Show added / imported KPIs table';
            app.AddKPIValueButton = uibutton(app.GridLayout3, 'push');
            app.AddKPIValueButton.ButtonPushedFcn = createCallbackFcn(app, @AddKPIValueButtonPushed, true);
            app.AddKPIValueButton.Text = 'Add KPI Value';
            app.ResetKPIstableButton = uibutton(app.GridLayout3, 'push');
            app.ResetKPIstableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetKPIstableButtonPushed, true);
            app.ResetKPIstableButton.Text = 'Reset KPIs table';
            app.ShownewMetadataKPIsButton = uibutton(app.UIFigure, 'push');
            app.ShownewMetadataKPIsButton.Enable = 'off';
            app.ShownewMetadataKPIsButton.Visible = 'off';
            app.ShownewMetadataKPIsButton.Text = 'Show new Metadata & KPIs';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IC_KPIs_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end